classdef detQCalib < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ScatteringProcess              matlab.ui.Figure
        RightPanel                     matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        qCalibrationTab                matlab.ui.container.Tab
        LoadSetupFileButton_3          matlab.ui.control.Button
        R_peakFittingLabel             matlab.ui.control.Label
        fitR                           matlab.ui.control.Spinner
        deltaSDD                       matlab.ui.control.Spinner
        deltaYaw                       matlab.ui.control.Spinner
        deltaBeam_Y                    matlab.ui.control.Spinner
        deltaBeam_X                    matlab.ui.control.Spinner
        refinerangeLabel               matlab.ui.control.Label
        maskFile                       matlab.ui.control.Label
        MaskCheckBox                   matlab.ui.control.CheckBox
        SelectMaskButton               matlab.ui.control.Button
        XRayEnergySpinner              matlab.ui.control.Spinner
        XRayEnergykeVSpinnerLabel      matlab.ui.control.Label
        BandWidthSpinner               matlab.ui.control.Spinner
        BandWidthSpinnerLabel          matlab.ui.control.Label
        ManualUpdateQRingsButton       matlab.ui.control.Button
        StandardDropDown               matlab.ui.control.DropDown
        StandardDropDownLabel          matlab.ui.control.Label
        Q9EditField                    matlab.ui.control.NumericEditField
        Q9EditFieldLabel               matlab.ui.control.Label
        Q8EditField                    matlab.ui.control.NumericEditField
        Q8EditFieldLabel               matlab.ui.control.Label
        Q7EditField                    matlab.ui.control.NumericEditField
        Q7EditFieldLabel               matlab.ui.control.Label
        Q6EditField                    matlab.ui.control.NumericEditField
        Q6EditFieldLabel               matlab.ui.control.Label
        Q5EditField                    matlab.ui.control.NumericEditField
        Q5EditFieldLabel               matlab.ui.control.Label
        Q4EditField                    matlab.ui.control.NumericEditField
        Q4EditFieldLabel               matlab.ui.control.Label
        Q3EditField                    matlab.ui.control.NumericEditField
        Q3EditFieldLabel               matlab.ui.control.Label
        Q2EditField                    matlab.ui.control.NumericEditField
        Q2EditFieldLabel               matlab.ui.control.Label
        Q1EditField                    matlab.ui.control.NumericEditField
        Q1EditFieldLabel               matlab.ui.control.Label
        SDDmmSpinner                   matlab.ui.control.Spinner
        SDDmmSpinnerLabel              matlab.ui.control.Label
        YawdegSpinner                  matlab.ui.control.Spinner
        YawdegSpinnerLabel             matlab.ui.control.Label
        Beam_YSpinner                  matlab.ui.control.Spinner
        Beam_YSpinnerLabel             matlab.ui.control.Label
        Beam_XSpinner                  matlab.ui.control.Spinner
        Beam_XSpinnerLabel             matlab.ui.control.Label
        EreaseAllLineCirclesButton     matlab.ui.control.Button
        RefinePeaksButton              matlab.ui.control.Button
        Q9CheckBox                     matlab.ui.control.CheckBox
        Q8CheckBox                     matlab.ui.control.CheckBox
        Q7CheckBox                     matlab.ui.control.CheckBox
        Q6CheckBox                     matlab.ui.control.CheckBox
        Q5CheckBox                     matlab.ui.control.CheckBox
        Q4CheckBox                     matlab.ui.control.CheckBox
        Q3CheckBox                     matlab.ui.control.CheckBox
        Q2CheckBox                     matlab.ui.control.CheckBox
        QValueIncludeLabel             matlab.ui.control.Label
        Q1CheckBox                     matlab.ui.control.CheckBox
        AutomaticalUpdateCheckBox      matlab.ui.control.CheckBox
        SetupFileTab                   matlab.ui.container.Tab
        Panel_5                        matlab.ui.container.Panel
        BeamlineDropDown               matlab.ui.control.DropDown
        CalibrantDropDownLabel_4       matlab.ui.control.Label
        QCalibrantDropDown             matlab.ui.control.DropDown
        CalibrantDropDownLabel_3       matlab.ui.control.Label
        AbsCalibrantDropDown           matlab.ui.control.DropDown
        CalibrantDropDownLabel_2       matlab.ui.control.Label
        AbsIntCoeffEditField           matlab.ui.control.NumericEditField
        AbsIntCoeffLabel               matlab.ui.control.Label
        CountLimitsHigh                matlab.ui.control.NumericEditField
        CountLimitsLow                 matlab.ui.control.NumericEditField
        CountLimitsEditFieldLabel      matlab.ui.control.Label
        CountOffset                    matlab.ui.control.NumericEditField
        CountOffsetLabel               matlab.ui.control.Label
        XrayEnergyEditField            matlab.ui.control.NumericEditField
        XrayEnergyLabel                matlab.ui.control.Label
        Panel_4                        matlab.ui.container.Panel
        Q2EditField_3                  matlab.ui.control.NumericEditField
        SelectDataandFitButton         matlab.ui.control.Button
        FitQ2EditField                 matlab.ui.control.NumericEditField
        FittedQ1EditField              matlab.ui.control.NumericEditField
        FittedQrangeEditFieldLabel     matlab.ui.control.Label
        CalibrantDropDown              matlab.ui.control.DropDown
        CalibrantDropDownLabel         matlab.ui.control.Label
        AbsIntCalibrationLabel         matlab.ui.control.Label
        ProcessIndividualButton        matlab.ui.control.Button
        ProcessCurrentButton           matlab.ui.control.Button
        ShowAllMapsButton              matlab.ui.control.Button
        LoadSetupFileButton            matlab.ui.control.Button
        SaveSetupFileButton            matlab.ui.control.Button
        ButtonGroup_2                  matlab.ui.container.ButtonGroup
        CalculateQBinningMapButton     matlab.ui.control.Button
        dQ3EditField3                  matlab.ui.control.NumericEditField
        dQ3EditField_2Label            matlab.ui.control.Label
        Q3EditField3                   matlab.ui.control.NumericEditField
        Q3EditField_3Label             matlab.ui.control.Label
        LineardQ2EditField             matlab.ui.control.NumericEditField
        LineardQ2EditFieldLabel        matlab.ui.control.Label
        PtsEditField                   matlab.ui.control.NumericEditField
        PtsEditFieldLabel              matlab.ui.control.Label
        LogQ2EditField                 matlab.ui.control.NumericEditField
        LogQ2EditFieldLabel            matlab.ui.control.Label
        dQ3EditField                   matlab.ui.control.NumericEditField
        dQ3EditFieldLabel              matlab.ui.control.Label
        Q3EditField_2                  matlab.ui.control.NumericEditField
        Q3EditField_2Label             matlab.ui.control.Label
        dQ2EditField                   matlab.ui.control.NumericEditField
        dQ2EditFieldLabel              matlab.ui.control.Label
        Q2EditField_2                  matlab.ui.control.NumericEditField
        Q2EditField_2Label             matlab.ui.control.Label
        dQ1EditField                   matlab.ui.control.NumericEditField
        dQ1EditFieldLabel              matlab.ui.control.Label
        QptsnumuberEditField           matlab.ui.control.NumericEditField
        QptsnumuberEditFieldLabel      matlab.ui.control.Label
        LoadPredefinedQArrayButton     matlab.ui.control.RadioButton
        QArraysetupLabel               matlab.ui.control.Label
        CombinedLogLinearQButton       matlab.ui.control.RadioButton
        LogQArrayButton                matlab.ui.control.RadioButton
        CombinedLinearQArrayButton     matlab.ui.control.RadioButton
        LinearQArrayButton             matlab.ui.control.RadioButton
        PixelBasedButton               matlab.ui.control.RadioButton
        Panel_3                        matlab.ui.container.Panel
        QmaxEditField                  matlab.ui.control.NumericEditField
        QmaxEditFieldLabel             matlab.ui.control.Label
        QminEditField                  matlab.ui.control.NumericEditField
        QminEditFieldLabel             matlab.ui.control.Label
        CalculateQandCorrMapButton     matlab.ui.control.Button
        QrangeLabel                    matlab.ui.control.Label
        Panel_2                        matlab.ui.container.Panel
        LoadMaskButton                 matlab.ui.control.Button
        MakeMaskButton                 matlab.ui.control.Button
        MaskLabel                      matlab.ui.control.Label
        DataprocessingTab              matlab.ui.container.Tab
        Panel_10                       matlab.ui.container.Panel
        ProcessQCutButton              matlab.ui.control.Button
        dQvalueEditField               matlab.ui.control.NumericEditField
        dQvalueEditFieldLabel          matlab.ui.control.Label
        QcircularCutLabel              matlab.ui.control.Label
        QvalueEditField                matlab.ui.control.NumericEditField
        QvalueEditFieldLabel           matlab.ui.control.Label
        LineCutsLabel                  matlab.ui.control.Label
        ProcessCurrentButton_2         matlab.ui.control.Button
        ButtonGroup_3                  matlab.ui.container.ButtonGroup
        AveLabel                       matlab.ui.control.Label
        AverageFolderLabel_2           matlab.ui.control.Label
        toselectButton                 matlab.ui.control.RadioButton
        AveragedButton                 matlab.ui.control.RadioButton
        AveButton                      matlab.ui.control.RadioButton
        ConvertH5toTIFFButton          matlab.ui.control.Button
        Panel_9                        matlab.ui.container.Panel
        YawLabel                       matlab.ui.control.Label
        SDDLabel                       matlab.ui.control.Label
        BeamYLabel                     matlab.ui.control.Label
        BeamXLabel                     matlab.ui.control.Label
        SetupInfoLabel                 matlab.ui.control.Label
        Panel_8                        matlab.ui.container.Panel
        Panel_7                        matlab.ui.container.Panel
        usewithexistingmaskCheckBox    matlab.ui.control.CheckBox
        inverttheselectionCheckBox_2   matlab.ui.control.CheckBox
        PlotButton                     matlab.ui.control.Button
        SectoranglerangedegLabel       matlab.ui.control.Label
        SectorangelrangeEditField_2    matlab.ui.control.NumericEditField
        SectorangelrangeEditField      matlab.ui.control.NumericEditField
        SectorROIMaskLabel             matlab.ui.control.Label
        Panel_6                        matlab.ui.container.Panel
        inverttheselectionCheckBox     matlab.ui.control.CheckBox
        usewithexistingmaskCheckBox_2  matlab.ui.control.CheckBox
        AdditionalarbitraryROIButton   matlab.ui.control.Button
        AdditionalRegionofInterestROIMaskLabel  matlab.ui.control.Label
        ProcessMultipleButton          matlab.ui.control.Button
        LoadSetupFileButton_2          matlab.ui.control.Button
        SaveSetupFilewithCombMaskButton  matlab.ui.control.Button
        LeftPanel                      matlab.ui.container.Panel
        DetectorSelectionLabel         matlab.ui.control.Label
        ButtonGroup                    matlab.ui.container.ButtonGroup
        Pilatus900KButton              matlab.ui.control.RadioButton
        DetColRowLabel                 matlab.ui.control.Label
        DetectorRowEditField           matlab.ui.control.NumericEditField
        DetectorColEditField           matlab.ui.control.NumericEditField
        OtherButton                    matlab.ui.control.RadioButton
        PixelSizemmEditField           matlab.ui.control.NumericEditField
        PixelSizemmEditFieldLabel      matlab.ui.control.Label
        PEXRpadButton                  matlab.ui.control.RadioButton
        Pilatus300KButton              matlab.ui.control.RadioButton
        Pilatus2MButton                matlab.ui.control.RadioButton
        Eiger9MButton                  matlab.ui.control.RadioButton
        CheckDatainUseButton           matlab.ui.control.Button
        ImageInfoLabel                 matlab.ui.control.Label
        Panel2_2                       matlab.ui.container.Panel
        TBDLabel                       matlab.ui.control.Label
        CdadtaSAXSLabel_2              matlab.ui.control.Label
        ImagesizeColRowLabel           matlab.ui.control.Label
        ImageSizeLabel                 matlab.ui.control.Label
        DatainUseLabel                 matlab.ui.control.Label
        Panel2                         matlab.ui.container.Panel
        FileFolderLabel_2              matlab.ui.control.Label
        FileFolderLabel                matlab.ui.control.Label
        FileNameLabel_2                matlab.ui.control.Label
        FileNameLabel                  matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function results = initializeDetector(app, DetParam)
            pixelSize = DetParam(1);
            BeamX = DetParam(2);
            BeamY = DetParam(3);
            Yaw = DetParam(4);
            SDD = DetParam(5);
            col = DetParam(6);
            row = DetParam(7);
            bandwidth = DetParam(8);
            dBeamX = DetParam(9);
            dBeamY = DetParam(10);
            dYaw   = DetParam(11);
            dSDD   = DetParam(12);

            app.PixelSizemmEditField.Value = pixelSize;
            app.Beam_XSpinner.Value = BeamX;
            app.Beam_YSpinner.Value = BeamY;
            app.YawdegSpinner.Value = Yaw;
            app.SDDmmSpinner.Value = SDD;
            app.DetectorColEditField.Value = col;
            app.DetectorRowEditField.Value = row;
            app.BandWidthSpinner.Value = bandwidth;
            app.deltaBeam_X.Value = dBeamX;
            app.deltaBeam_Y.Value = dBeamY;
            app.deltaYaw.Value = dYaw;
            app.deltaSDD.Value = dSDD;
            results = 1;
        end


        function results = deleteLine(app, lineK)
            results = 1;
            try
                figH = evalin('base', 'SAXSimageviewerhandle');
            catch
                fprintf('Start SAXSimageView first...\n');
                return;
            end
            handles = guidata(figH);
            %t = findobj(handles.ImageAxes, 'type', 'line');
            eval(sprintf('t = findobj(handles.ImageAxes, ''type'', ''line'', ''tag'',''line%d'');',lineK));
            if ~isempty(t)
                delete(t);
            end
            eval(sprintf('t = findobj(handles.ImageAxes, ''type'', ''line'', ''tag'',''line%dH'');',lineK));
            if ~isempty(t)
                delete(t);
            end   
            eval(sprintf('t = findobj(handles.ImageAxes, ''type'', ''line'', ''tag'',''line%dL'');',lineK));
            if ~isempty(t)
                delete(t);
            end
            
        end
        
        function results = generateLine(app, lineK)
            results = 1;
            nPts= 360;
            pixelSize = app.PixelSizemmEditField.Value;
            beamX = app.Beam_XSpinner.Value;
            beamY = app.Beam_YSpinner.Value;
            SDD = app.SDDmmSpinner.Value;
            xeng = app.XRayEnergySpinner.Value;
            bandWidth = app.BandWidthSpinner.Value;
            yaw = app.YawdegSpinner.Value;
            col = app.DetectorColEditField.Value;
            row = app.DetectorRowEditField.Value;
            det = [col, row];
            
            try
                figH = evalin('base', 'SAXSimageviewerhandle');
            catch
                fprintf('Start SAXSimageView first...\n');
                return;
            end
            %figH = evalin('base', 'SAXSimageviewerhandle');
            handles = guidata(figH);  
            
            eval( sprintf('qValue = app.Q%dEditField.Value;',lineK)); 
            if yaw<=5.0
                [ring, ringL, ringH] = calculateQrings(qValue, pixelSize, [beamX beamY], SDD, xeng, nPts, bandWidth);
            else
                [ring, ringL, ringH] = calculateQringsYaw(qValue, pixelSize, [beamX beamY], SDD, xeng, nPts, bandWidth, yaw, det);
            end
            
            cmd1=sprintf('line(ring(:,1), ring(:,2),  ''parent'', handles.ImageAxes, ''Color'', ''red'', ''LineStyle'', ''-'', ''Tag'', ''line%d'');',lineK);
            cmd2=sprintf('line(ringL(:,1),ringL(:,2), ''parent'', handles.ImageAxes, ''Color'', ''m'',   ''LineStyle'', ''--'', ''Tag'', ''line%dL'');',lineK);
            cmd3=sprintf('line(ringH(:,1),ringH(:,2), ''parent'', handles.ImageAxes, ''Color'', ''m'',   ''LineStyle'', ''--'',  ''Tag'', ''line%dH'');',lineK);
            eval(cmd1); eval(cmd2); eval(cmd3);
            
        end
        
        function results = deleteAllLines(app)
            try
                figH = evalin('base', 'SAXSimageviewerhandle');
            catch
                fprintf('Start SAXSimageView first...\n');
                return;
            end
            handles = guidata(figH);
            t = findobj(handles.ImageAxes, 'type', 'line');
            delete(t);
        end


        function results=refinePeaks(app, mask)
%             if exist('qCalData', 'var')
%                 qCalData=evalin('base','qCalData');
%             else
%                 qCalData.calibrant=[];
%                 qCalData.scatt = [];
%                 qCalData.userData=[];
%             end
            %userData = qCalData.userData;
            %calibrant = qCalData.calibrant;
            %scatt = qCalData.scatt;
            if nargin < 2
                mask=[];
            end

            results = 1;
            try
                figH = evalin('base', 'SAXSimageviewerhandle');
            catch
                fprintf('Start SAXSimageView first...\n');
                return;
            end
            handles = guidata(figH);
            %saxs = getgihandle;
            
            nPts= 180;
            pixelSize = app.PixelSizemmEditField.Value;
            beamX = app.Beam_XSpinner.Value;
            beamY = app.Beam_YSpinner.Value;
            SDD = app.SDDmmSpinner.Value;
            xeng = app.XRayEnergySpinner.Value;
            bandWidth = app.BandWidthSpinner.Value;
            yaw = app.YawdegSpinner.Value;
            col = app.DetectorColEditField.Value;
            row = app.DetectorRowEditField.Value;
            det = [col, row];
            dBeamX = app.deltaBeam_X.Value;
            dBeamY = app.deltaBeam_Y.Value;
            dSDD = app.deltaSDD.Value;
            dYaw = app.deltaYaw.Value;
            fitR = app.fitR.Value;

            calibrant.xeng = xeng;
            calibrant.wavelength = eng2wlen(xeng);
            calibrant.data=[];
            saxs = getgihandle;
            img = saxs.image;
            imageSize = saxs.imgsize;
            %cdat.q=cq;
            %calibrant.data = [calibrant.data cdat]

            totPeaks=0;
            for kk=1:9
                checked = 0;
                fprintf("ring #: %d is starting...", kk);
                eval( sprintf('checked = app.Q%dCheckBox.Value;',kk));             
                if checked
                    eval( sprintf('qValue = app.Q%dEditField.Value;',kk)); 
                    cdat.q = qValue;
                    %[ring, ringL, ringH] = calculateQrings(qValue, pixelSize, [beamX beamY], SDD, xeng, nPts, bandWidth);
                    if yaw<=5.0
                        [ring, ringL, ringH] = calculateQrings(qValue, pixelSize, [beamX beamY], SDD, xeng, nPts, bandWidth);
                    else
                        [ring, ringL, ringH] = calculateQringsYaw(qValue, pixelSize, [beamX beamY], SDD, xeng, nPts, bandWidth, yaw, det);
                    end   
                    % find L&H pair and fit peak % here here
                    xy = [];
                    for mm=1:nPts
                        found = 0;
                        xL = ringL(mm,1); yL=ringL(mm,2);    
                        xH = ringH(mm,1); yH=ringH(mm,2); 
                        [found, peak] = fitSliceGauss([xL yL], [xH yH], bandWidth, img, imageSize, fitR, mask);
                        if found
                            xy = [xy peak];
                        end
                    end
                    if ~isempty(xy)
                        totPeaks = totPeaks + length(xy);
                        cdat.xy = xy;
                        calibrant.data=[calibrant.data cdat];
                    end     
                end
                fprintf("ring #: %d is done.", kk);
            end
            % display refine the peaks
            fprintf("total %d valid peaks found.", totPeaks);
            figure(2001); clf; hold on;
            for kk=1:length(calibrant.data) 
                plot(calibrant.data(kk).xy(1,:), calibrant.data(kk).xy(2,:), 'or');
            end
            hold off;

            x0 = [beamX beamY SDD/pixelSize yaw];
            lb = [beamX-dBeamX  beamY-dBeamY (SDD-dSDD)/pixelSize yaw-dYaw];
            ub = [beamX+dBeamX  beamY+dBeamY (SDD+dSDD)/pixelSize yaw+dYaw];
            %lb = [beamX-20  beamY-20 SDD/pixelSize*0.8 yaw-2];
            %ub = [beamX+20  beamY+20 SDD/pixelSize*1.2 yaw+2];

            
            if ~isempty(calibrant.data)
                [xval, fval] = fminsearchcon(@(x) tiltcircle2(x,calibrant), x0, lb, ub);
                plotTiltCircle3(xval,calibrant);
                disp(sprintf("Fitting penalty: %.4E", fval));

                beamX = xval(1);
                beamY = xval(2);
                SDD   = xval(3) * pixelSize;
                yaw   = xval(4);

                app.Beam_XSpinner.Value = beamX;
                app.Beam_YSpinner.Value = beamY;
                app.SDDmmSpinner.Value  = SDD;
                app.YawdegSpinner.Value = yaw;


                % update new rings
                deleteAllLines(app);
                for kk=1:9
                    checked = 0;
                    eval( sprintf('checked = app.Q%dCheckBox.Value;',kk));             
                    if checked
                        eval( sprintf('qValue = app.Q%dEditField.Value;',kk)); 
                        [ring, ringL, ringH] = calculateQringsYaw(qValue, pixelSize, [beamX beamY], SDD, xeng, nPts, bandWidth, yaw, det);
                        cmd1=sprintf('line(ring(:,1), ring(:,2),  ''parent'', handles.ImageAxes, ''Color'', ''m'', ''LineStyle'', ''-.'', ''Tag'', ''line%d'', ''linewidth'', 1.5);',kk);
                        %cmd2=sprintf('line(ringL(:,1),ringL(:,2), ''parent'', handles.ImageAxes, ''Color'', ''m'',   ''LineStyle'', ''--'', ''Tag'', ''line%dL'');',kk);
                        %cmd3=sprintf('line(ringH(:,1),ringH(:,2), ''parent'', handles.ImageAxes, ''Color'', ''m'',   ''LineStyle'', ''--'',  ''Tag'', ''line%dH'');',kk);
                        %eval(cmd1); eval(cmd2); eval(cmd3);
                        eval(cmd1);
                    end
                end


            else
                disp('Please pick the peaks first ....')
            end
            
            updateFitResult(app, calibrant);
%             qCalData.calibrant = calibrant;
%             %scatt = qCalData.scatt;
%             qCalData.scatt.SDD = SDD;
%             qCalData.scatt.BeamXY = [beamX beamY];
%             qCalData.scatt.yaw = yaw;
%             assignin('base','qCalData', qCalData);
        end    


        function results = updateQrings(app) % ignore Yaw value (make =0) if yaw <=5
            results = 1;
            try
                figH = evalin('base', 'SAXSimageviewerhandle');
            catch
                fprintf('Start SAXSimageView first...\n');
                return;
            end
            handles = guidata(figH);
            %saxs = getgihandle;
            
            nPts= 360;
            pixelSize = app.PixelSizemmEditField.Value;
            beamX = app.Beam_XSpinner.Value;
            beamY = app.Beam_YSpinner.Value;
            SDD = app.SDDmmSpinner.Value;
            xeng = app.XRayEnergySpinner.Value;
            bandWidth = app.BandWidthSpinner.Value;
            yaw = app.YawdegSpinner.Value;
            col = app.DetectorColEditField.Value;
            row = app.DetectorRowEditField.Value;
            det = [col, row];
            
            for kk=1:9
                checked = 0;
                eval( sprintf('checked = app.Q%dCheckBox.Value;',kk));             
                if checked
                    eval( sprintf('qValue = app.Q%dEditField.Value;',kk));                    
                    %[ring, ringL, ringH] = calculateQrings(qValue, pixelSize, [beamX beamY], SDD, xeng, nPts, bandWidth);
                    if yaw<=5.0
                        [ring, ringL, ringH] = calculateQrings(qValue, pixelSize, [beamX beamY], SDD, xeng, nPts, bandWidth);
                    else
                        [ring, ringL, ringH] = calculateQringsYaw(qValue, pixelSize, [beamX beamY], SDD, xeng, nPts, bandWidth, yaw, det);
                    end                    
                    cmd1=sprintf('line(ring(:,1), ring(:,2),  ''parent'', handles.ImageAxes, ''Color'', ''m'', ''LineStyle'', ''-.'', ''Tag'', ''line%d'', ''linewidth'', 1.5);',kk);
                    cmd2=sprintf('line(ringL(:,1),ringL(:,2), ''parent'', handles.ImageAxes, ''Color'', ''m'',   ''LineStyle'', ''--'', ''Tag'', ''line%dL'');',kk);
                    cmd3=sprintf('line(ringH(:,1),ringH(:,2), ''parent'', handles.ImageAxes, ''Color'', ''m'',   ''LineStyle'', ''--'',  ''Tag'', ''line%dH'');',kk);
                    eval(cmd1); eval(cmd2); eval(cmd3);
                end
            end
            updateFitResult(app, []);
        end

        function results = updateQrings2(app) % with exact Yaw angle value
            results = 1;
            try
                figH = evalin('base', 'SAXSimageviewerhandle');
            catch
                fprintf('Start SAXSimageView first...\n');
                return;
            end
            handles = guidata(figH);
            %saxs = getgihandle;
            
            nPts= 360;
            pixelSize = app.PixelSizemmEditField.Value;
            beamX = app.Beam_XSpinner.Value;
            beamY = app.Beam_YSpinner.Value;
            SDD = app.SDDmmSpinner.Value;
            xeng = app.XRayEnergySpinner.Value;
            bandWidth = app.BandWidthSpinner.Value;
            yaw = app.YawdegSpinner.Value;
            col = app.DetectorColEditField.Value;
            row = app.DetectorRowEditField.Value;
            det = [col, row];
            
            for kk=1:9
                checked = 0;
                eval( sprintf('checked = app.Q%dCheckBox.Value;',kk));             
                if checked
                    eval( sprintf('qValue = app.Q%dEditField.Value;',kk));    

                    [ring, ringL, ringH] = calculateQringsYaw(qValue, pixelSize, [beamX beamY], SDD, xeng, nPts, bandWidth, yaw, det);
%                     if yaw<=5.0
%                         [ring, ringL, ringH] = calculateQrings(qValue, pixelSize, [beamX beamY], SDD, xeng, nPts, bandWidth);
%                     else
%                         [ring, ringL, ringH] = calculateQringsYaw(qValue, pixelSize, [beamX beamY], SDD, xeng, nPts, bandWidth, yaw, det);
%                     end                    
                    cmd1=sprintf('line(ring(:,1), ring(:,2),  ''parent'', handles.ImageAxes, ''Color'', ''m'', ''LineStyle'', ''-.'', ''Tag'', ''line%d'', ''linewidth'', 1.5);',kk);
                    cmd2=sprintf('line(ringL(:,1),ringL(:,2), ''parent'', handles.ImageAxes, ''Color'', ''m'',   ''LineStyle'', ''--'', ''Tag'', ''line%dL'');',kk);
                    cmd3=sprintf('line(ringH(:,1),ringH(:,2), ''parent'', handles.ImageAxes, ''Color'', ''m'',   ''LineStyle'', ''--'',  ''Tag'', ''line%dH'');',kk);
                    eval(cmd1); eval(cmd2); eval(cmd3);
                end
            end
            updateFitResult(app, []);
        end
        

        function results = loadImageFile(app)
            results = 1;
            try
                saxs = getgihandle;
                app.FileNameLabel.Text = saxs.imagename;
                app.FileFolderLabel.Text = saxs.dir;
                
                imgSize = saxs.imgsize;
                app.ImageSizeLabel.Text = sprintf('[%d,  %d]', imgSize(2), imgSize(1)); 
                
                imgSize = saxs.imgsize;                
                imgSize2 = max(imgSize);
                switch(imgSize2)
                    case 619  % Pilatus300
                        app.Pilatus300KButton.Value = 1;
                        detName = 'Pilatus300K';
                        %updateQstandards(app, 3);   % Agbeh_WAXS
                        %ButtonGroupSelectionChanged(app, event);
                    case 1043  % Pilatus900K
                        app.Pilatus900KButton.Value = 1;
                        detName = 'Pilatus900K';
                        %updateQstandards(app, 3);   % Agbeh_WAXS
                        %ButtonGroupSelectionChanged(app, event);                        
                    case 1679 % Pilatus2M
                        app.Pilatus2MButton.Value = 1;
                        detName = 'Pilatus2M';     
                        %updateQstandards(app, 2);   % Agbeh_SAXS
                    case 3262 % Eiger9M
                        app.Eiger9MButton.Value = 1;
                        detName = 'Eiger9M';        % Agbeh_SAXS
                        %updateQstandards(app, 2);
                    case 4320 % PE
                        app.PEXRpadButton.Value = 1;
                        detName = 'PEXRpad';             % Ce)2
                        %updateQstandards(app, 4);
                    otherwise % Mar300? 
                        app.OtherButton.Value = 1;
                        detName = 'Other';                       
                end
                updateDetectorSetting(app, detName);

            catch
                fprintf('Start SAXSimageView and gisaxsleenew...\n');
                app.Eiger9MButton.Value = 1;
                detName = 'Eiger9M';        % Agbeh_SAXS
                updateDetectorSetting(app, detName); 
            end
            
        end

        function results = loadImageFile2(app)
            results = 1;
            try


                imgSize = saxs.imgsize;
                
                imgSize2 = max(imgSize);
                switch(imgSize2)
                    case 619  % Pilatus300
                        app.Pilatus300KButton.Value = 1;
                        detName = 'Pilatus300K';
                        %updateQstandards(app, 3);   % Agbeh_WAXS
                        %ButtonGroupSelectionChanged(app, event);
                    case 1043  % Pilatus900
                        app.Pilatus900KButton.Value = 1;
                        detName = 'Pilatus900K';
                        %updateQstandards(app, 3);   % Agbeh_WAXS
                        %ButtonGroupSelectionChanged(app, event);                        
                    case 1679 % Pilatus2M
                        app.Pilatus2MButton.Value = 1;
                        detName = 'Pilatus2M';     
                        %updateQstandards(app, 2);   % Agbeh_SAXS
                    case 3262 % Eiger9M
                        app.Eiger9MButton.Value = 1;
                        detName = 'Eiger9M';        % Agbeh_SAXS
                        %updateQstandards(app, 2);
                    case 4320 % PE
                        app.PEXRpadButton.Value = 1;
                        detName = 'PEXRpad';             % Ce)2
                        %updateQstandards(app, 4);
                    otherwise % Mar300? 
                        app.OtherButton.Value = 1;
                        detName = 'Other';                       
                end
                updateDetectorSetting(app, detName);
                
            catch
                fprintf('Start SAXSimageView and gisaxsleenew...\n');
                app.Eiger9MButton.Value = 1;
                detName = 'Eiger9M';        % Agbeh_SAXS
                updateDetectorSetting(app, detName);                        
            end
            
        end        
        
        function results = loadStartState(app)
            results = 1;
            loadImageFile(app);
        end
        
        function results = updateDetectorSetting(app, detName)
            results = 1;
            app.StandardDropDown.ItemsData = 1:length(app.StandardDropDown.Items);
            switch(detName)
                case 'Eiger9M'
                    %detParam = [pixelSize  BeamX BeamY Yaw SDD, Col, Row, bandwidth, dBeamX, dBeamY, dYaw, dSDD ];
                    detParam = [0.075 1433 203 0.0 2000 3108 3262 50 50 50 0.0 50];
                    initializeDetector(app, detParam); 
                    app.StandardDropDown.Value = 2; 
                    updateQstandards(app, 2);  %Agbeh_SAXS     
                    app.maskFile.Text = 'Eiger9M_goodpix.bmp';
                    maskFile = 'Eiger9M_goodpix.bmp';
                    %img= imread(maskFile);
                    %figure; imagesc(img);
                case 'Pilatus2M'
                    detParam = [0.172 1330 1600 0.0 7500 1475 1679 30 30 30 0.0 50];
                    initializeDetector(app, detParam); 
                    app.StandardDropDown.Value = 2;
                    updateQstandards(app, 2);  %Agbeh_SAXS
                    app.maskFile.Text = 'Pilatus2M_goodpix.bmp';
                case 'Pilatus300K'
                    detParam = [0.172 236 920 15.0 420 487 619 20 30 30 2.0 50];
                    initializeDetector(app, detParam); 
                    app.StandardDropDown.Value = 3;
                    updateQstandards(app, 3);  %Agbeh_WAXS
                    app.maskFile.Text = 'Pilatus300K_goodpix.bmp';
                case 'Pilatus900K'
                    detParam = [0.172 356 95 0.0 470 981 1043 20 30 30 2.0 50];
                    initializeDetector(app, detParam); 
                    app.StandardDropDown.Value = 6;
                    updateQstandards(app, 6);  %Agbeh_WAXS2
                    app.maskFile.Text = 'Pilatus900K_goodpix.bmp';                    
                case 'PEXRpad'
                    detParam = [0.100 2112 135 0.0 187 4320 4318 50 50 50 0.5 50] ;
                    initializeDetector(app, detParam); 
                    app.StandardDropDown.Value = 4;
                    updateQstandards(app, 4);  %CeO2
                otherwise
                    fprintf('Unknown detector!\n');
            end            
        end
        
        function results = updateQstandards(app, value)
            results = 1;
            % value = index of standard
            if value==1
                kk=1;
            elseif value == 2 % Agbeh_SAXS
                for kk=1:9
                    cmdStr = sprintf('app.Q%dEditField.Value=%.5f;', kk, 0.10763*kk);
                    eval(cmdStr);
                    cmdStr2 = sprintf('app.Q%dCheckBox.Value = 1;',kk);
                    eval(cmdStr2);
                end
                app.Q9CheckBox.Value = 0;
                
            elseif value == 3 % Agbeh_WAXS
                %qwStandard = [0.86104 0.96867 1.07630 1.18393 1.3839 1.6346 1.8119 2.3676];
                %qwStandard = [ 0.86104 0.96867 1.07630 1.18393 1.29156 1.3839 1.6346 1.8119 2.3676];
                qwStandard = [ 0.86104 0.96867 1.07630 1.18393 1.29156 1.38497 1.63480 1.80918 2.37005]; % with re-calibrated values in June 2022
                for kk=1:length(qwStandard)
                    cmdStr = sprintf('app.Q%dEditField.Value=%.5f;', kk, qwStandard(kk));
                    eval(cmdStr);
                    cmdStr2 = sprintf('app.Q%dCheckBox.Value = 1;',kk);
                    eval(cmdStr2);                   
                end
                for kk=length(qwStandard)+1 : 9
                    cmdStr = sprintf('app.Q%dEditField.Value=%.5f;', kk, 0);
                    eval(cmdStr);
                    cmdStr2 = sprintf('app.Q%dCheckBox.Value = 0;',kk);
                    eval(cmdStr2);
                end
                for kk=[6]
                    cmdStr2 = sprintf('app.Q%dCheckBox.Value = 0;',kk);
                    eval(cmdStr2);
                end
                
            elseif value == 4 % CeO2
                qCeO2Standard = [2.0112 2.3224 3.2843 3.8512 4.0225 4.6447 5.0615 5.1930];
                for kk=1:length(qCeO2Standard)
                    cmdStr = sprintf('app.Q%dEditField.Value=%.5f;', kk, qCeO2Standard(kk));
                    eval(cmdStr);
                    cmdStr2 = sprintf('app.Q%dCheckBox.Value = 1;',kk);
                    eval(cmdStr2);                    
                end
                for kk=length(qCeO2Standard)+1 : 9
                    cmdStr = sprintf('app.Q%dEditField.Value=%.5f;', kk, 0);
                    eval(cmdStr);
                    cmdStr2 = sprintf('app.Q%dCheckBox.Value = 0;',kk);
                    eval(cmdStr2);
                end
                                                
            elseif value == 5 % Agbeh_PE
                qCeO2Standard = [0.53815 0.64578 0.75341 0.86104 0.96867 1.0763 1.63480 1.80918 2.37005];
                for kk=1:length(qCeO2Standard)
                    cmdStr = sprintf('app.Q%dEditField.Value=%.5f;', kk, qCeO2Standard(kk));
                    eval(cmdStr);
                    cmdStr2 = sprintf('app.Q%dCheckBox.Value = 1;',kk);
                    eval(cmdStr2);                    
                end
                for kk=length(qCeO2Standard)+1 : 9
                    cmdStr = sprintf('app.Q%dEditField.Value=%.5f;', kk, 0);
                    eval(cmdStr);
                    cmdStr2 = sprintf('app.Q%dCheckBox.Value = 0;',kk);
                    eval(cmdStr2);
                end

            elseif value == 6 % Agbeh_WAXS2
                %qwStandard = [0.86104 0.96867 1.07630 1.18393 1.3839 1.6346 1.8119 2.3676];
                %qwStandard = [ 0.86104 0.96867 1.07630 1.18393 1.29156 1.3839 1.6346 1.8119 2.3676];
                %qwStandard = [ 0.86104 0.96867 1.07630 1.18393 1.29156 1.38497 1.63480 1.80918 2.37005]; % with re-calibrated values in June 2022
                qwStandard = [ 0.43052 0.53815 0.64578 0.75341 0.86104 1.38497 1.63480 1.80918 2.37005]; % with re-calibrated values in June 2022
                for kk=1:length(qwStandard)
                    cmdStr = sprintf('app.Q%dEditField.Value=%.5f;', kk, qwStandard(kk));
                    eval(cmdStr);
                    cmdStr2 = sprintf('app.Q%dCheckBox.Value = 1;',kk);
                    eval(cmdStr2);                   
                end
                for kk=length(qwStandard)+1 : 9
                    cmdStr = sprintf('app.Q%dEditField.Value=%.5f;', kk, 0);
                    eval(cmdStr);
                    cmdStr2 = sprintf('app.Q%dCheckBox.Value = 0;',kk);
                    eval(cmdStr2);
                end
                for kk=[6]
                    cmdStr2 = sprintf('app.Q%dCheckBox.Value = 0;',kk);
                    eval(cmdStr2);
                end
                                
            end
        end
        
        function results = updateFitResult(app, calibrant)   
            results = 1;
            dets = struct("Pilatus2M",1, "Pilatus300K",0, "PEXRpad",2, "Eiger9M",4, "Other",-1, "Pilatus900K", 3);
            if exist('qCalData', 'var')
                qCalData=evalin('base','qCalData');
            else
                qCalData.calibrant=[];
                qCalData.scatt = [];
                qCalData.userData=[];
            end
            if ~isempty(calibrant)
                qCalData.calibrant = calibrant;
            end
            
%             pixelSize = app.PixelSizemmEditField.Value;
            beamX = app.Beam_XSpinner.Value;
            beamY = app.Beam_YSpinner.Value;
            SDD = app.SDDmmSpinner.Value;
            xeng = app.XRayEnergySpinner.Value;
            bandWidth = app.BandWidthSpinner.Value;
            yaw = app.YawdegSpinner.Value;


            qCalData.scatt.SDD = SDD;
            qCalData.scatt.BeamXY = [beamX beamY];
            qCalData.scatt.yaw = yaw;
            qCalData.scatt.eng = xeng;
            qCalData.scatt.calibrantImageFile = app.FileNameLabel.Text;
            qCalData.scatt.calibrantImagePath = app.FileFolderLabel.Text;
            index = app.StandardDropDown.Value;
            qCalData.scatt.calibrantName = app.StandardDropDown.Items{index};
            qValues=[];
            for jj=1:9
                if eval(sprintf('app.Q%dCheckBox.Value', jj))
                    cQvalue = eval(sprintf('app.Q%dEditField.Value',jj));
                    qValues = [qValues cQvalue];
                end
            end
            qCalData.scatt.calibrantQValues = qValues;

            selectedButton = app.ButtonGroup.SelectedObject;
            detName = selectedButton.Text;
            eval(sprintf('qCalData.scatt.isPilatus2m = dets.%s', detName));

            assignin('base','qCalData', qCalData);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            loadStartState(app);
        end

        % Button pushed function: CheckDatainUseButton
        function CheckDatainUseButtonPushed(app, event)
            % add checkdatainuse here zuo
            loadImageFile(app);
        end

        % Value changed function: StandardDropDown
        function StandardDropDownValueChanged(app, event)
            %value = app.StandardDropDown.Value; 
            app.StandardDropDown.ItemsData = 1:length(app.StandardDropDown.Items); 
            value = app.StandardDropDown.Value; % read index
            updateQstandards(app, value);
            
%             if value==1
%                 kk=1;
%             elseif value == 2 % Agbeh_SAXS
%                 for kk=1:9
%                     cmdStr = sprintf('app.Q%dEditField.Value=%.5f', kk, 0.10763*kk);
%                     eval(cmdStr);
%                     cmdStr2 = sprintf('app.Q%dCheckBox.Value = 1',kk);
%                     eval(cmdStr2);
%                 end
%                 app.Q9CheckBox.Value = 0;
%                 
%             elseif value == 3 % Agbeh_WAXS
%                 %qwStandard = [0.86104 0.96867 1.07630 1.18393 1.3839 1.6346 1.8119 2.3676];
%                 qwStandard = [ 0.86104 0.96867 1.07630 1.18393 1.3839 1.6346 1.8119 2.3676];
%                 for kk=1:length(qwStandard)
%                     cmdStr = sprintf('app.Q%dEditField.Value=%.5f', kk, qwStandard(kk));
%                     eval(cmdStr);
%                     cmdStr2 = sprintf('app.Q%dCheckBox.Value = 1',kk);
%                     eval(cmdStr2);                   
%                 end
%                 for kk=length(qwStandard)+1 : 9
%                     cmdStr = sprintf('app.Q%dEditField.Value=%.5f', kk, 0);
%                     eval(cmdStr);
%                     cmdStr2 = sprintf('app.Q%dCheckBox.Value = 0',kk);
%                     eval(cmdStr2);
%                 end
%                 
%             elseif value == 4 % CeO2
%                 qCeO2Standard = [2.0112 2.3224 3.2843 3.8512 4.0225 4.6447 5.0615 5.1930];
%                 for kk=1:length(qCeO2Standard)
%                     cmdStr = sprintf('app.Q%dEditField.Value=%.5f', kk, qCeO2Standard(kk));
%                     eval(cmdStr);
%                     cmdStr2 = sprintf('app.Q%dCheckBox.Value = 1',kk);
%                     eval(cmdStr2);                    
%                 end
%                 for kk=length(qCeO2Standard)+1 : 9
%                     cmdStr = sprintf('app.Q%dEditField.Value=%.5f', kk, 0);
%                     eval(cmdStr);
%                     cmdStr2 = sprintf('app.Q%dCheckBox.Value = 0',kk);
%                     eval(cmdStr2);
%                 end
%                                                 
%             elseif value == 5 % Agbeh_PE
%                 qCeO2Standard = [2.0112 2.3224 3.2843 3.8512 4.0225 4.6447 5.0615 5.1930];
%                 for kk=1:length(qCeO2Standard)
%                     cmdStr = sprintf('app.Q%dEditField.Value=%.5f', kk, qCeO2Standard(kk));
%                     eval(cmdStr);
%                     cmdStr2 = sprintf('app.Q%dCheckBox.Value = 1',kk);
%                     eval(cmdStr2);                    
%                 end
%                 for kk=length(qCeO2Standard)+1 : 9
%                     cmdStr = sprintf('app.Q%dEditField.Value=%.5f', kk, 0);
%                     eval(cmdStr);
%                     cmdStr2 = sprintf('app.Q%dCheckBox.Value = 0',kk);
%                     eval(cmdStr2);
%                 end
%             end
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            detName = selectedButton.Text;
            updateDetectorSetting(app, detName);
%             switch(selectedButton.Text)
%                 case 'Eiger9M'
%                     %detParam = [pixelSize  BeamX BeamY Yaw SDD, Col, Row];
%                     detParam = [0.075 1440 206 0.0 2000 3108 3262];
%                     initializeDetector(app, detParam); 
%                 case 'Pilatus2M'
%                     detParam = [0.172 1440 206 0.0 2000 1475 1679];
%                     initializeDetector(app, detParam); 
%                 case 'Pilatus300'
%                     detParam = [0.172 239 923 15.0 415 487 619];
%                     initializeDetector(app, detParam); 
%                 case 'PE'
%                     detParam = [0.100 2155 147 0.0 180 4320 4318];
%                     initializeDetector(app, detParam); 
%                 otherwise
%                     fprintf('Unknown detector!\n');
%             end
        end

        % Button pushed function: ManualUpdateQRingsButton
        function ManualUpdateQRingsButtonPushed(app, event)
            updateQrings2(app); % maually update with exact yaw value
%             % image panel on SAXSimageviewer
%             figH = evalin('base', 'SAXSimageviewerhandle');
%             handles = guidata(figH);
%             %saxs = getgihandle;
%             
%             nPts= 100;
%             pixelSize = app.PixelSizemmEditField.Value;
%             beamX = app.Beam_XSpinner.Value;
%             beamY = app.Beam_YSpinner.Value;
%             SDD = app.SDDmmSpinner.Value;
%             xeng = app.XRayEnergySpinner.Value;
%             bandWidth = app.BandWidthSpinner.Value;
%             
%             for kk=1:9
%                 checked = 0;
%                 eval( sprintf('checked = app.Q%dCheckBox.Value;',kk));             
%                 if checked
%                     eval( sprintf('qValue = app.Q%dEditField.Value;',kk)); 
%                     [ring, ringL, ringH] = calculateQrings(qValue, pixelSize, [beamX beamY], SDD, xeng, nPts, bandWidth);
%                     
%                     cmd1=sprintf('line(ring(:,1), ring(:,2),  ''parent'', handles.ImageAxes, ''Color'', ''red'', ''LineStyle'', ''-'', ''Tag'', ''line%d'');',kk);
%                     cmd2=sprintf('line(ringL(:,1),ringL(:,2), ''parent'', handles.ImageAxes, ''Color'', ''m'',   ''LineStyle'', ''--'', ''Tag'', ''line%dL'');',kk);
%                     cmd3=sprintf('line(ringH(:,1),ringH(:,2), ''parent'', handles.ImageAxes, ''Color'', ''m'',   ''LineStyle'', ''--'',  ''Tag'', ''line%dH'');',kk);
%                     eval(cmd1); eval(cmd2); eval(cmd3);
%                 end
%             end
            
        end

        % Value changed function: Q1CheckBox
        function Q1CheckBoxValueChanged(app, event)
            value = app.Q1CheckBox.Value;
            if value == 0
                deleteLine(app, 1);
            elseif value == 1
                generateLine(app, 1);
            end
        end

        % Value changed function: Q2CheckBox
        function Q2CheckBoxValueChanged(app, event)
            value = app.Q2CheckBox.Value;
            if value == 0
                deleteLine(app, 2);
            elseif value == 1
                generateLine(app, 2);
            end
        end

        % Value changed function: Q3CheckBox
        function Q3CheckBoxValueChanged(app, event)
            value = app.Q3CheckBox.Value;
            if value == 0
                deleteLine(app, 3);
            elseif value == 1
                generateLine(app, 3);
            end            
        end

        % Value changed function: Q4CheckBox
        function Q4CheckBoxValueChanged(app, event)
            value = app.Q4CheckBox.Value;
            if value == 0
                deleteLine(app, 4);
            elseif value == 1
                generateLine(app, 4);
            end              
        end

        % Value changed function: Q5CheckBox
        function Q5CheckBoxValueChanged(app, event)
            value = app.Q5CheckBox.Value;
            if value == 0
                deleteLine(app, 5);
            elseif value == 1
                generateLine(app, 5);
            end              
        end

        % Value changed function: Q6CheckBox
        function Q6CheckBoxValueChanged(app, event)
            value = app.Q6CheckBox.Value;
            if value == 0
                deleteLine(app, 6);
            elseif value == 1
                generateLine(app, 6);
            end              
        end

        % Value changed function: Q7CheckBox
        function Q7CheckBoxValueChanged(app, event)
            value = app.Q7CheckBox.Value;
            if value == 0
                deleteLine(app, 7);
            elseif value == 1
                generateLine(app, 7);
            end              
        end

        % Value changed function: Q8CheckBox
        function Q8CheckBoxValueChanged(app, event)
            value = app.Q8CheckBox.Value;
            if value == 0
                deleteLine(app, 8);
            elseif value == 1
                generateLine(app, 8);
            end              
        end

        % Value changed function: Q9CheckBox
        function Q9CheckBoxValueChanged(app, event)
            value = app.Q9CheckBox.Value;
            if value == 0
                deleteLine(app, 9);
            elseif value == 1
                generateLine(app, 9);
            end              
        end

        % Button pushed function: EreaseAllLineCirclesButton
        function EreaseAllLineCirclesButtonPushed(app, event)
            deleteAllLines(app);
        end

        % Value changed function: Beam_XSpinner
        function Beam_XSpinnerValueChanged(app, event)
            %value = app.Beam_XSpinner.Value;
            if app.AutomaticalUpdateCheckBox.Value == 1
                deleteAllLines(app);
                updateQrings(app);                
            end
        end

        % Value changed function: Beam_YSpinner
        function Beam_YSpinnerValueChanged(app, event)
            %value = app.Beam_YSpinner.Value;
            if app.AutomaticalUpdateCheckBox.Value == 1
                deleteAllLines(app);
                updateQrings(app);                
            end           
            
        end

        % Value changed function: YawdegSpinner
        function YawdegSpinnerValueChanged(app, event)
            %value = app.YawdegSpinner.Value;
            if app.AutomaticalUpdateCheckBox.Value == 1
                deleteAllLines(app);
                updateQrings(app);                
            end             
        end

        % Value changed function: BandWidthSpinner
        function BandWidthSpinnerValueChanged(app, event)
            %value = app.BandWidthSpinner.Value;
            if app.AutomaticalUpdateCheckBox.Value == 1
                deleteAllLines(app);
                updateQrings(app);                
            end             
        end

        % Value changed function: XRayEnergySpinner
        function XRayEnergySpinnerValueChanged(app, event)
            %value = app.XRayEnergySpinner.Value;
            if app.AutomaticalUpdateCheckBox.Value == 1
                deleteAllLines(app);
                updateQrings(app);                
            end             
        end

        % Value changed function: SDDmmSpinner
        function SDDmmSpinnerValueChanged(app, event)
            %value = app.SDDmmSpinner.Value;
            if app.AutomaticalUpdateCheckBox.Value == 1
                deleteAllLines(app);
                updateQrings(app);                
            end              
        end

        % Button pushed function: RefinePeaksButton
        function RefinePeaksButtonPushed(app, event)
            mask=[];
            if app.MaskCheckBox.Value
                maskFile = app.maskFile.Text;
                try
                    mask= imread(maskFile);
                catch
                    disp(sprintf("Mask file: %s does not exist!", maskFile));
                end
            end  
            detCol = app.DetectorColEditField.Value;
            detRow = app.DetectorRowEditField.Value;
            maskSize = size(mask);
            if maskSize(1)~=detRow || maskSize(2)~= detCol
                disp("Mask size is wrong!")
                mask = [];
            end
            disp("Peak refining starts...");
            refinePeaks(app, mask);
        end

        % Button pushed function: SelectMaskButton
        function SelectMaskButtonPushed(app, event)
            [filename, pathname] = uigetfile( { '*.bmp;*.tif','Mask File (*.*)'}, 'Select a file');
            maskFile = fullfile(pathname, filename);
            app.maskFile.Text = maskFile;
            img= imread(maskFile);
            figure; imagesc(img);
        end

        % Button pushed function: LoadSetupFileButton_3
        function LoadSetupFileButton_3Pushed(app, event)
            [filename, pathname] = uigetfile( { '*.mat','Setup File (*.*)'}, 'Select a file');
            setupFile = fullfile(pathname, filename);
            scatt = load(setupFile);            
            scattName1 = fieldnames(scatt);
            scattName = scattName1{1};

            BeamXY=[ 0 0];
            SDD = 0;
            yaw = 0;
            eng = 0;

            BeamXY = eval(sprintf('scatt.%s.BeamXY', scattName));
            yaw = eval(sprintf('scatt.%s.yaw', scattName));
            SDD = eval(sprintf('scatt.%s.SDD', scattName));
            eng = eval(sprintf('scatt.%s.eng', scattName));

            app.Beam_XSpinner.Value = BeamXY(1);
            app.Beam_YSpinner.Value = BeamXY(2);
            app.YawdegSpinner.Value = yaw;
            app.SDDmmSpinner.Value = SDD;
            app.XRayEnergySpinner.Value = eng;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ScatteringProcess = uifigure('Visible', 'off');
            app.ScatteringProcess.Name = 'Detector Q Calibration (detQCalib)';
            app.LeftPanel = uipanel(app.ScatteringProcess);
            app.Panel2 = uipanel(app.LeftPanel);
            app.FileNameLabel = uilabel(app.Panel2);
            app.FileNameLabel.Text = 'test.h5';
            app.FileNameLabel_2 = uilabel(app.Panel2);
            app.FileNameLabel_2.Text = 'File Name:';
            app.FileFolderLabel = uilabel(app.Panel2);
            app.FileFolderLabel.Text = 'C:\dadta\SAXS';
            app.FileFolderLabel_2 = uilabel(app.Panel2);
            app.FileFolderLabel_2.Text = 'File Folder:';
            app.DatainUseLabel = uilabel(app.LeftPanel);
            app.DatainUseLabel.Text = 'Data in Use';
            app.Panel2_2 = uipanel(app.LeftPanel);
            app.ImageSizeLabel = uilabel(app.Panel2_2);
            app.ImageSizeLabel.Text = '[3108,  3262 ]';
            app.ImagesizeColRowLabel = uilabel(app.Panel2_2);
            app.ImagesizeColRowLabel.Text = 'Image size [Col, Row]:';
            app.CdadtaSAXSLabel_2 = uilabel(app.Panel2_2);
            app.CdadtaSAXSLabel_2.Text = 'C:\dadta\SAXS';
            app.TBDLabel = uilabel(app.Panel2_2);
            app.TBDLabel.Text = 'TBD';
            app.ImageInfoLabel = uilabel(app.LeftPanel);
            app.ImageInfoLabel.Text = 'Image Info';
            app.CheckDatainUseButton = uibutton(app.LeftPanel, 'push');
            app.CheckDatainUseButton.ButtonPushedFcn = createCallbackFcn(app, @CheckDatainUseButtonPushed, true);
            app.CheckDatainUseButton.Text = 'Check Data in Use';
            app.ButtonGroup = uibuttongroup(app.LeftPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.Eiger9MButton = uiradiobutton(app.ButtonGroup);
            app.Eiger9MButton.Text = 'Eiger9M';
            app.Eiger9MButton.Value = true;
            app.Pilatus2MButton = uiradiobutton(app.ButtonGroup);
            app.Pilatus2MButton.Text = 'Pilatus2M';
            app.Pilatus300KButton = uiradiobutton(app.ButtonGroup);
            app.Pilatus300KButton.Text = 'Pilatus300K';
            app.PEXRpadButton = uiradiobutton(app.ButtonGroup);
            app.PEXRpadButton.Text = 'PEXRpad';
            app.PixelSizemmEditFieldLabel = uilabel(app.ButtonGroup);
            app.PixelSizemmEditFieldLabel.Text = 'Pixel Size(mm)';
            app.PixelSizemmEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.PixelSizemmEditField.ValueDisplayFormat = '%8.4g';
            app.PixelSizemmEditField.Value = 0.075;
            app.OtherButton = uiradiobutton(app.ButtonGroup);
            app.OtherButton.Text = 'Other';
            app.DetectorColEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.DetectorColEditField.ValueDisplayFormat = '%8.4g';
            app.DetectorColEditField.Value = 3108;
            app.DetectorRowEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.DetectorRowEditField.ValueDisplayFormat = '%8.4g';
            app.DetectorRowEditField.Value = 3262;
            app.DetColRowLabel = uilabel(app.ButtonGroup);
            app.DetColRowLabel.Text = 'Det Col#, Row#';
            app.Pilatus900KButton = uiradiobutton(app.ButtonGroup);
            app.Pilatus900KButton.Text = 'Pilatus900K';
            app.DetectorSelectionLabel = uilabel(app.LeftPanel);
            app.DetectorSelectionLabel.Text = 'Detector Selection';
            app.RightPanel = uipanel(app.ScatteringProcess);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.qCalibrationTab = uitab(app.TabGroup);
            app.qCalibrationTab.Title = 'qCalibration';
            app.AutomaticalUpdateCheckBox = uicheckbox(app.qCalibrationTab);
            app.AutomaticalUpdateCheckBox.Text = 'Automatical Update';
            app.AutomaticalUpdateCheckBox.Value = true;
            app.Q1CheckBox = uicheckbox(app.qCalibrationTab);
            app.Q1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Q1CheckBoxValueChanged, true);
            app.Q1CheckBox.Text = '';
            app.Q1CheckBox.Value = true;
            app.QValueIncludeLabel = uilabel(app.qCalibrationTab);
            app.QValueIncludeLabel.Text = 'Q Value        Include?';
            app.Q2CheckBox = uicheckbox(app.qCalibrationTab);
            app.Q2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Q2CheckBoxValueChanged, true);
            app.Q2CheckBox.Text = '';
            app.Q2CheckBox.Value = true;
            app.Q3CheckBox = uicheckbox(app.qCalibrationTab);
            app.Q3CheckBox.ValueChangedFcn = createCallbackFcn(app, @Q3CheckBoxValueChanged, true);
            app.Q3CheckBox.Text = '';
            app.Q3CheckBox.Value = true;
            app.Q4CheckBox = uicheckbox(app.qCalibrationTab);
            app.Q4CheckBox.ValueChangedFcn = createCallbackFcn(app, @Q4CheckBoxValueChanged, true);
            app.Q4CheckBox.Text = '';
            app.Q4CheckBox.Value = true;
            app.Q5CheckBox = uicheckbox(app.qCalibrationTab);
            app.Q5CheckBox.ValueChangedFcn = createCallbackFcn(app, @Q5CheckBoxValueChanged, true);
            app.Q5CheckBox.Text = '';
            app.Q5CheckBox.Value = true;
            app.Q6CheckBox = uicheckbox(app.qCalibrationTab);
            app.Q6CheckBox.ValueChangedFcn = createCallbackFcn(app, @Q6CheckBoxValueChanged, true);
            app.Q6CheckBox.Text = '';
            app.Q6CheckBox.Value = true;
            app.Q7CheckBox = uicheckbox(app.qCalibrationTab);
            app.Q7CheckBox.ValueChangedFcn = createCallbackFcn(app, @Q7CheckBoxValueChanged, true);
            app.Q7CheckBox.Text = '';
            app.Q7CheckBox.Value = true;
            app.Q8CheckBox = uicheckbox(app.qCalibrationTab);
            app.Q8CheckBox.ValueChangedFcn = createCallbackFcn(app, @Q8CheckBoxValueChanged, true);
            app.Q8CheckBox.Text = '';
            app.Q8CheckBox.Value = true;
            app.Q9CheckBox = uicheckbox(app.qCalibrationTab);
            app.Q9CheckBox.ValueChangedFcn = createCallbackFcn(app, @Q9CheckBoxValueChanged, true);
            app.Q9CheckBox.Text = '';
            app.Q9CheckBox.Value = true;
            app.RefinePeaksButton = uibutton(app.qCalibrationTab, 'push');
            app.RefinePeaksButton.ButtonPushedFcn = createCallbackFcn(app, @RefinePeaksButtonPushed, true);
            app.RefinePeaksButton.Text = 'Refine Peaks';
            app.EreaseAllLineCirclesButton = uibutton(app.qCalibrationTab, 'push');
            app.EreaseAllLineCirclesButton.ButtonPushedFcn = createCallbackFcn(app, @EreaseAllLineCirclesButtonPushed, true);
            app.EreaseAllLineCirclesButton.Text = 'Erease All Line/Circles';
            app.Beam_XSpinnerLabel = uilabel(app.qCalibrationTab);
            app.Beam_XSpinnerLabel.Text = 'Beam_X';
            app.Beam_XSpinner = uispinner(app.qCalibrationTab);
            app.Beam_XSpinner.Step = 0.5;
            app.Beam_XSpinner.ValueDisplayFormat = '%11.6g';
            app.Beam_XSpinner.ValueChangedFcn = createCallbackFcn(app, @Beam_XSpinnerValueChanged, true);
            app.Beam_XSpinner.Tooltip = {'Beam X-position on Detector'};
            app.Beam_XSpinner.Value = 1440;
            app.Beam_YSpinnerLabel = uilabel(app.qCalibrationTab);
            app.Beam_YSpinnerLabel.Text = 'Beam_Y';
            app.Beam_YSpinner = uispinner(app.qCalibrationTab);
            app.Beam_YSpinner.Step = 0.5;
            app.Beam_YSpinner.ValueDisplayFormat = '%11.6g';
            app.Beam_YSpinner.ValueChangedFcn = createCallbackFcn(app, @Beam_YSpinnerValueChanged, true);
            app.Beam_YSpinner.Tooltip = {'Beam Y-position on Detector'};
            app.Beam_YSpinner.Value = 206;
            app.YawdegSpinnerLabel = uilabel(app.qCalibrationTab);
            app.YawdegSpinnerLabel.Text = 'Yaw(deg)';
            app.YawdegSpinner = uispinner(app.qCalibrationTab);
            app.YawdegSpinner.Step = 0.1;
            app.YawdegSpinner.ValueDisplayFormat = '%11.6g';
            app.YawdegSpinner.ValueChangedFcn = createCallbackFcn(app, @YawdegSpinnerValueChanged, true);
            app.YawdegSpinner.Tooltip = {'Only on when Yaw> 5'};
            app.SDDmmSpinnerLabel = uilabel(app.qCalibrationTab);
            app.SDDmmSpinnerLabel.Text = 'SDD(mm)';
            app.SDDmmSpinner = uispinner(app.qCalibrationTab);
            app.SDDmmSpinner.Step = 0.5;
            app.SDDmmSpinner.ValueDisplayFormat = '%11.6g';
            app.SDDmmSpinner.ValueChangedFcn = createCallbackFcn(app, @SDDmmSpinnerValueChanged, true);
            app.SDDmmSpinner.Tooltip = {'Sample-to-Detector Distance'};
            app.SDDmmSpinner.Value = 2000;
            app.Q1EditFieldLabel = uilabel(app.qCalibrationTab);
            app.Q1EditFieldLabel.Text = 'Q1';
            app.Q1EditField = uieditfield(app.qCalibrationTab, 'numeric');
            app.Q1EditField.ValueDisplayFormat = '%11.5g';
            app.Q2EditFieldLabel = uilabel(app.qCalibrationTab);
            app.Q2EditFieldLabel.Text = 'Q2';
            app.Q2EditField = uieditfield(app.qCalibrationTab, 'numeric');
            app.Q2EditField.ValueDisplayFormat = '%11.5g';
            app.Q3EditFieldLabel = uilabel(app.qCalibrationTab);
            app.Q3EditFieldLabel.Text = 'Q3';
            app.Q3EditField = uieditfield(app.qCalibrationTab, 'numeric');
            app.Q3EditField.ValueDisplayFormat = '%11.5g';
            app.Q4EditFieldLabel = uilabel(app.qCalibrationTab);
            app.Q4EditFieldLabel.Text = 'Q4';
            app.Q4EditField = uieditfield(app.qCalibrationTab, 'numeric');
            app.Q4EditField.ValueDisplayFormat = '%11.5g';
            app.Q5EditFieldLabel = uilabel(app.qCalibrationTab);
            app.Q5EditFieldLabel.Text = 'Q5';
            app.Q5EditField = uieditfield(app.qCalibrationTab, 'numeric');
            app.Q5EditField.ValueDisplayFormat = '%11.5g';
            app.Q6EditFieldLabel = uilabel(app.qCalibrationTab);
            app.Q6EditFieldLabel.Text = 'Q6';
            app.Q6EditField = uieditfield(app.qCalibrationTab, 'numeric');
            app.Q6EditField.ValueDisplayFormat = '%11.5g';
            app.Q7EditFieldLabel = uilabel(app.qCalibrationTab);
            app.Q7EditFieldLabel.Text = 'Q7';
            app.Q7EditField = uieditfield(app.qCalibrationTab, 'numeric');
            app.Q7EditField.ValueDisplayFormat = '%11.5g';
            app.Q8EditFieldLabel = uilabel(app.qCalibrationTab);
            app.Q8EditFieldLabel.Text = 'Q8';
            app.Q8EditField = uieditfield(app.qCalibrationTab, 'numeric');
            app.Q8EditField.ValueDisplayFormat = '%11.5g';
            app.Q9EditFieldLabel = uilabel(app.qCalibrationTab);
            app.Q9EditFieldLabel.Text = 'Q9';
            app.Q9EditField = uieditfield(app.qCalibrationTab, 'numeric');
            app.Q9EditField.ValueDisplayFormat = '%11.5g';
            app.StandardDropDownLabel = uilabel(app.qCalibrationTab);
            app.StandardDropDownLabel.Text = 'Standard';
            app.StandardDropDown = uidropdown(app.qCalibrationTab);
            app.StandardDropDown.Items = {'None', 'Agbeh_SAXS', 'Agbeh_WAXS', 'CeO2', 'Agbeh_PE', 'Agbeh_WAXS2'};
            app.StandardDropDown.ValueChangedFcn = createCallbackFcn(app, @StandardDropDownValueChanged, true);
            app.StandardDropDown.Value = 'Agbeh_SAXS';
            app.ManualUpdateQRingsButton = uibutton(app.qCalibrationTab, 'push');
            app.ManualUpdateQRingsButton.ButtonPushedFcn = createCallbackFcn(app, @ManualUpdateQRingsButtonPushed, true);
            app.ManualUpdateQRingsButton.Text = 'Manual Update Q-Rings';
            app.BandWidthSpinnerLabel = uilabel(app.qCalibrationTab);
            app.BandWidthSpinnerLabel.Text = 'BandWidth';
            app.BandWidthSpinner = uispinner(app.qCalibrationTab);
            app.BandWidthSpinner.ValueDisplayFormat = '%11.6g';
            app.BandWidthSpinner.ValueChangedFcn = createCallbackFcn(app, @BandWidthSpinnerValueChanged, true);
            app.BandWidthSpinner.Tooltip = {'Bandwidth between upper and down bounds '};
            app.BandWidthSpinner.Value = 30;
            app.XRayEnergykeVSpinnerLabel = uilabel(app.qCalibrationTab);
            app.XRayEnergykeVSpinnerLabel.Text = 'X-Ray Energy(keV)';
            app.XRayEnergySpinner = uispinner(app.qCalibrationTab);
            app.XRayEnergySpinner.Step = 0.1;
            app.XRayEnergySpinner.ValueDisplayFormat = '%11.6g';
            app.XRayEnergySpinner.ValueChangedFcn = createCallbackFcn(app, @XRayEnergySpinnerValueChanged, true);
            app.XRayEnergySpinner.Value = 13.3;
            app.SelectMaskButton = uibutton(app.qCalibrationTab, 'push');
            app.SelectMaskButton.ButtonPushedFcn = createCallbackFcn(app, @SelectMaskButtonPushed, true);
            app.SelectMaskButton.Text = 'Select Mask';
            app.MaskCheckBox = uicheckbox(app.qCalibrationTab);
            app.MaskCheckBox.Text = 'Use Mask';
            app.MaskCheckBox.Value = true;
            app.maskFile = uilabel(app.qCalibrationTab);
            app.maskFile.Text = 'C:\dadta\SAXS';
            app.refinerangeLabel = uilabel(app.qCalibrationTab);
            app.refinerangeLabel.Text = 'refine range';
            app.deltaBeam_X = uispinner(app.qCalibrationTab);
            app.deltaBeam_X.ValueDisplayFormat = '%11.6g';
            app.deltaBeam_X.Tooltip = {'Beam X-position on Detector'};
            app.deltaBeam_X.Value = 50;
            app.deltaBeam_Y = uispinner(app.qCalibrationTab);
            app.deltaBeam_Y.ValueDisplayFormat = '%11.6g';
            app.deltaBeam_Y.Tooltip = {'Beam X-position on Detector'};
            app.deltaBeam_Y.Value = 50;
            app.deltaYaw = uispinner(app.qCalibrationTab);
            app.deltaYaw.Step = 0.5;
            app.deltaYaw.ValueDisplayFormat = '%11.6g';
            app.deltaYaw.Tooltip = {'Beam X-position on Detector'};
            app.deltaYaw.Value = 5;
            app.deltaSDD = uispinner(app.qCalibrationTab);
            app.deltaSDD.ValueDisplayFormat = '%11.6g';
            app.deltaSDD.Tooltip = {'Beam X-position on Detector'};
            app.deltaSDD.Value = 50;
            app.fitR = uispinner(app.qCalibrationTab);
            app.fitR.Step = 0.1;
            app.fitR.ValueDisplayFormat = '%11.6g';
            app.fitR.Tooltip = {'Beam X-position on Detector'};
            app.fitR.Value = 0.9;
            app.R_peakFittingLabel = uilabel(app.qCalibrationTab);
            app.R_peakFittingLabel.Text = 'R_peakFitting';
            app.LoadSetupFileButton_3 = uibutton(app.qCalibrationTab, 'push');
            app.LoadSetupFileButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadSetupFileButton_3Pushed, true);
            app.LoadSetupFileButton_3.Text = 'Load Setup File';
            app.SetupFileTab = uitab(app.TabGroup);
            app.SetupFileTab.Title = 'Setup File';
            app.Panel_2 = uipanel(app.SetupFileTab);
            app.MaskLabel = uilabel(app.Panel_2);
            app.MaskLabel.Text = 'Mask';
            app.MakeMaskButton = uibutton(app.Panel_2, 'push');
            app.MakeMaskButton.Text = 'Make Mask';
            app.LoadMaskButton = uibutton(app.Panel_2, 'push');
            app.LoadMaskButton.Text = 'Load Mask';
            app.Panel_3 = uipanel(app.SetupFileTab);
            app.QrangeLabel = uilabel(app.Panel_3);
            app.QrangeLabel.Text = 'Q range';
            app.CalculateQandCorrMapButton = uibutton(app.Panel_3, 'push');
            app.CalculateQandCorrMapButton.Text = 'Calculate Q and Corr Map';
            app.QminEditFieldLabel = uilabel(app.Panel_3);
            app.QminEditFieldLabel.Text = 'Qmin';
            app.QminEditField = uieditfield(app.Panel_3, 'numeric');
            app.QmaxEditFieldLabel = uilabel(app.Panel_3);
            app.QmaxEditFieldLabel.Text = 'Qmax';
            app.QmaxEditField = uieditfield(app.Panel_3, 'numeric');
            app.ButtonGroup_2 = uibuttongroup(app.SetupFileTab);
            app.PixelBasedButton = uiradiobutton(app.ButtonGroup_2);
            app.PixelBasedButton.Text = 'Pixel Based';
            app.PixelBasedButton.Value = true;
            app.LinearQArrayButton = uiradiobutton(app.ButtonGroup_2);
            app.LinearQArrayButton.Text = 'Linear Q Array';
            app.CombinedLinearQArrayButton = uiradiobutton(app.ButtonGroup_2);
            app.CombinedLinearQArrayButton.Text = 'Combined Linear Q Array';
            app.LogQArrayButton = uiradiobutton(app.ButtonGroup_2);
            app.LogQArrayButton.Text = 'Log Q Array';
            app.CombinedLogLinearQButton = uiradiobutton(app.ButtonGroup_2);
            app.CombinedLogLinearQButton.Text = 'Combined Log-Linear Q';
            app.QArraysetupLabel = uilabel(app.ButtonGroup_2);
            app.QArraysetupLabel.Text = 'Q Array setup';
            app.LoadPredefinedQArrayButton = uiradiobutton(app.ButtonGroup_2);
            app.LoadPredefinedQArrayButton.Text = 'Load Pre-defined Q Array';
            app.QptsnumuberEditFieldLabel = uilabel(app.ButtonGroup_2);
            app.QptsnumuberEditFieldLabel.Text = 'Q pts numuber';
            app.QptsnumuberEditField = uieditfield(app.ButtonGroup_2, 'numeric');
            app.dQ1EditFieldLabel = uilabel(app.ButtonGroup_2);
            app.dQ1EditFieldLabel.Text = 'dQ1';
            app.dQ1EditField = uieditfield(app.ButtonGroup_2, 'numeric');
            app.Q2EditField_2Label = uilabel(app.ButtonGroup_2);
            app.Q2EditField_2Label.Text = 'Q2';
            app.Q2EditField_2 = uieditfield(app.ButtonGroup_2, 'numeric');
            app.dQ2EditFieldLabel = uilabel(app.ButtonGroup_2);
            app.dQ2EditFieldLabel.Text = 'dQ2';
            app.dQ2EditField = uieditfield(app.ButtonGroup_2, 'numeric');
            app.Q3EditField_2Label = uilabel(app.ButtonGroup_2);
            app.Q3EditField_2Label.Text = 'Q3';
            app.Q3EditField_2 = uieditfield(app.ButtonGroup_2, 'numeric');
            app.dQ3EditFieldLabel = uilabel(app.ButtonGroup_2);
            app.dQ3EditFieldLabel.Text = 'dQ3';
            app.dQ3EditField = uieditfield(app.ButtonGroup_2, 'numeric');
            app.LogQ2EditFieldLabel = uilabel(app.ButtonGroup_2);
            app.LogQ2EditFieldLabel.Text = 'Log: Q2';
            app.LogQ2EditField = uieditfield(app.ButtonGroup_2, 'numeric');
            app.PtsEditFieldLabel = uilabel(app.ButtonGroup_2);
            app.PtsEditFieldLabel.Text = 'Pts#';
            app.PtsEditField = uieditfield(app.ButtonGroup_2, 'numeric');
            app.LineardQ2EditFieldLabel = uilabel(app.ButtonGroup_2);
            app.LineardQ2EditFieldLabel.Text = 'Linear: dQ2';
            app.LineardQ2EditField = uieditfield(app.ButtonGroup_2, 'numeric');
            app.Q3EditField_3Label = uilabel(app.ButtonGroup_2);
            app.Q3EditField_3Label.Text = 'Q3';
            app.Q3EditField3 = uieditfield(app.ButtonGroup_2, 'numeric');
            app.dQ3EditField_2Label = uilabel(app.ButtonGroup_2);
            app.dQ3EditField_2Label.Text = 'dQ3';
            app.dQ3EditField3 = uieditfield(app.ButtonGroup_2, 'numeric');
            app.CalculateQBinningMapButton = uibutton(app.ButtonGroup_2, 'push');
            app.CalculateQBinningMapButton.Text = 'Calculate Q Binning Map';
            app.SaveSetupFileButton = uibutton(app.SetupFileTab, 'push');
            app.SaveSetupFileButton.Text = 'Save Setup File';
            app.LoadSetupFileButton = uibutton(app.SetupFileTab, 'push');
            app.LoadSetupFileButton.Text = 'Load Setup File';
            app.ShowAllMapsButton = uibutton(app.SetupFileTab, 'push');
            app.ShowAllMapsButton.Text = 'Show All Maps';
            app.ProcessCurrentButton = uibutton(app.SetupFileTab, 'push');
            app.ProcessCurrentButton.Text = 'Process Current';
            app.ProcessIndividualButton = uibutton(app.SetupFileTab, 'push');
            app.ProcessIndividualButton.Text = 'Process Individual';
            app.Panel_4 = uipanel(app.SetupFileTab);
            app.AbsIntCalibrationLabel = uilabel(app.Panel_4);
            app.AbsIntCalibrationLabel.Text = 'Abs.Int. Calibration';
            app.CalibrantDropDownLabel = uilabel(app.Panel_4);
            app.CalibrantDropDownLabel.Text = 'Calibrant';
            app.CalibrantDropDown = uidropdown(app.Panel_4);
            app.CalibrantDropDown.Items = {'None', 'Water', 'Glassy Carbon'};
            app.CalibrantDropDown.Value = 'None';
            app.FittedQrangeEditFieldLabel = uilabel(app.Panel_4);
            app.FittedQrangeEditFieldLabel.Text = 'Fitted Q range';
            app.FittedQ1EditField = uieditfield(app.Panel_4, 'numeric');
            app.FitQ2EditField = uieditfield(app.Panel_4, 'numeric');
            app.SelectDataandFitButton = uibutton(app.Panel_4, 'push');
            app.SelectDataandFitButton.Text = 'Select Data and Fit';
            app.Q2EditField_3 = uieditfield(app.Panel_4, 'numeric');
            app.Panel_5 = uipanel(app.SetupFileTab);
            app.XrayEnergyLabel = uilabel(app.Panel_5);
            app.XrayEnergyLabel.Text = {'X-ray'; 'Energy'};
            app.XrayEnergyEditField = uieditfield(app.Panel_5, 'numeric');
            app.XrayEnergyEditField.Value = 13.3;
            app.CountOffsetLabel = uilabel(app.Panel_5);
            app.CountOffsetLabel.Text = {'Count '; 'Offset'};
            app.CountOffset = uieditfield(app.Panel_5, 'numeric');
            app.CountLimitsEditFieldLabel = uilabel(app.Panel_5);
            app.CountLimitsEditFieldLabel.Text = 'Count Limits ';
            app.CountLimitsLow = uieditfield(app.Panel_5, 'numeric');
            app.CountLimitsLow.Value = -1;
            app.CountLimitsHigh = uieditfield(app.Panel_5, 'numeric');
            app.CountLimitsHigh.Value = 1000000;
            app.AbsIntCoeffLabel = uilabel(app.Panel_5);
            app.AbsIntCoeffLabel.Text = {'Abs.Int. '; 'Coeff'};
            app.AbsIntCoeffEditField = uieditfield(app.Panel_5, 'numeric');
            app.AbsIntCoeffEditField.Value = 1;
            app.CalibrantDropDownLabel_2 = uilabel(app.Panel_5);
            app.CalibrantDropDownLabel_2.Text = {'AbsInt'; 'Calibrant'};
            app.AbsCalibrantDropDown = uidropdown(app.Panel_5);
            app.AbsCalibrantDropDown.Items = {'None', 'Water', 'Glassy Carbon'};
            app.AbsCalibrantDropDown.Value = 'None';
            app.CalibrantDropDownLabel_3 = uilabel(app.Panel_5);
            app.CalibrantDropDownLabel_3.Text = 'QCalibrant';
            app.QCalibrantDropDown = uidropdown(app.Panel_5);
            app.QCalibrantDropDown.Items = {'None', 'Silver Behenate', 'CeO2'};
            app.QCalibrantDropDown.Value = 'None';
            app.CalibrantDropDownLabel_4 = uilabel(app.Panel_5);
            app.CalibrantDropDownLabel_4.Text = 'Beamline';
            app.BeamlineDropDown = uidropdown(app.Panel_5);
            app.BeamlineDropDown.Items = {'12-ID-B,APS', '12-ID-C,APS'};
            app.BeamlineDropDown.Value = '12-ID-B,APS';
            app.DataprocessingTab = uitab(app.TabGroup);
            app.DataprocessingTab.Title = 'Data processing';
            app.SaveSetupFilewithCombMaskButton = uibutton(app.DataprocessingTab, 'push');
            app.SaveSetupFilewithCombMaskButton.Text = 'Save Setup File with Comb Mask';
            app.LoadSetupFileButton_2 = uibutton(app.DataprocessingTab, 'push');
            app.LoadSetupFileButton_2.Text = 'Load Setup File';
            app.ProcessMultipleButton = uibutton(app.DataprocessingTab, 'push');
            app.ProcessMultipleButton.Text = 'Process Multiple';
            app.Panel_8 = uipanel(app.DataprocessingTab);
            app.AdditionalRegionofInterestROIMaskLabel = uilabel(app.Panel_8);
            app.AdditionalRegionofInterestROIMaskLabel.Text = 'Additional Region of Interest(ROI) / Mask';
            app.Panel_6 = uipanel(app.Panel_8);
            app.AdditionalarbitraryROIButton = uibutton(app.Panel_6, 'push');
            app.AdditionalarbitraryROIButton.Text = {'Additional'; 'arbitrary ROI'};
            app.usewithexistingmaskCheckBox_2 = uicheckbox(app.Panel_6);
            app.usewithexistingmaskCheckBox_2.Text = 'use with existing mask';
            app.inverttheselectionCheckBox = uicheckbox(app.Panel_6);
            app.inverttheselectionCheckBox.Text = 'invert the selection';
            app.Panel_7 = uipanel(app.Panel_8);
            app.SectorROIMaskLabel = uilabel(app.Panel_7);
            app.SectorROIMaskLabel.Text = 'Sector ROI/Mask';
            app.SectorangelrangeEditField = uieditfield(app.Panel_7, 'numeric');
            app.SectorangelrangeEditField_2 = uieditfield(app.Panel_7, 'numeric');
            app.SectoranglerangedegLabel = uilabel(app.Panel_7);
            app.SectoranglerangedegLabel.Text = 'Sector angle range(deg):';
            app.PlotButton = uibutton(app.Panel_7, 'push');
            app.PlotButton.Text = 'Plot';
            app.inverttheselectionCheckBox_2 = uicheckbox(app.Panel_7);
            app.inverttheselectionCheckBox_2.Text = 'invert the selection';
            app.usewithexistingmaskCheckBox = uicheckbox(app.Panel_7);
            app.usewithexistingmaskCheckBox.Text = 'use with existing mask';
            app.Panel_9 = uipanel(app.DataprocessingTab);
            app.SetupInfoLabel = uilabel(app.Panel_9);
            app.SetupInfoLabel.Text = 'Setup Info:';
            app.BeamXLabel = uilabel(app.Panel_9);
            app.BeamXLabel.Text = 'BeamX';
            app.BeamYLabel = uilabel(app.Panel_9);
            app.BeamYLabel.Text = 'BeamY';
            app.SDDLabel = uilabel(app.Panel_9);
            app.SDDLabel.Text = 'SDD';
            app.YawLabel = uilabel(app.Panel_9);
            app.YawLabel.Text = 'Yaw';
            app.ConvertH5toTIFFButton = uibutton(app.DataprocessingTab, 'push');
            app.ConvertH5toTIFFButton.Text = 'Convert H5 to TIFF';
            app.ButtonGroup_3 = uibuttongroup(app.DataprocessingTab);
            app.AveButton = uiradiobutton(app.ButtonGroup_3);
            app.AveButton.Text = 'Ave';
            app.AveButton.Value = true;
            app.AveragedButton = uiradiobutton(app.ButtonGroup_3);
            app.AveragedButton.Text = 'Averaged';
            app.toselectButton = uiradiobutton(app.ButtonGroup_3);
            app.toselectButton.Text = 'to select';
            app.AverageFolderLabel_2 = uilabel(app.ButtonGroup_3);
            app.AverageFolderLabel_2.Text = 'Average Folder';
            app.AveLabel = uilabel(app.ButtonGroup_3);
            app.AveLabel.Text = 'Ave';
            app.ProcessCurrentButton_2 = uibutton(app.DataprocessingTab, 'push');
            app.ProcessCurrentButton_2.Text = 'Process Current';
            app.Panel_10 = uipanel(app.DataprocessingTab);
            app.LineCutsLabel = uilabel(app.Panel_10);
            app.LineCutsLabel.Text = 'Line Cuts:';
            app.QvalueEditFieldLabel = uilabel(app.Panel_10);
            app.QvalueEditFieldLabel.Text = 'Q value';
            app.QvalueEditField = uieditfield(app.Panel_10, 'numeric');
            app.QcircularCutLabel = uilabel(app.Panel_10);
            app.QcircularCutLabel.Text = 'Q circular Cut:';
            app.dQvalueEditFieldLabel = uilabel(app.Panel_10);
            app.dQvalueEditFieldLabel.Text = 'dQ value';
            app.dQvalueEditField = uieditfield(app.Panel_10, 'numeric');
            app.ProcessQCutButton = uibutton(app.Panel_10, 'push');
            app.ProcessQCutButton.Text = 'Process Q Cut';
            % Show the figure after all components are created
            app.ScatteringProcess.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = detQCalib

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ScatteringProcess)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ScatteringProcess)
        end
    end
end