classdef maskmaker_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                       matlab.ui.Figure
        uipanel6                      matlab.ui.container.Panel
        pb_loadcoordinates            matlab.ui.control.Button
        pb_loadPilatusMask            matlab.ui.control.Button
        pb_loadCenterBS               matlab.ui.control.Button
        pb_loadSAXSBS                 matlab.ui.control.Button
        pb_load12IDBframe             matlab.ui.control.Button
        uipanel1                      matlab.ui.container.Panel
        pbsmooth                      matlab.ui.control.Button
        ed_thresh                     matlab.ui.control.EditField
        pbsetthresh                   matlab.ui.control.Button
        pbhisto                       matlab.ui.control.Button
        uipanel5                      matlab.ui.container.Panel
        pbloadimage                   matlab.ui.control.Button
        edhighlimit                   matlab.ui.control.EditField
        pbloglin                      matlab.ui.control.Button
        pbsetscale                    matlab.ui.control.Button
        edlowlimit                    matlab.ui.control.EditField
        uipanel4                      matlab.ui.container.Panel
        pbdrawcircle                  matlab.ui.control.Button
        pbdrawpolygon                 matlab.ui.control.Button
        pbinvertselection             matlab.ui.control.Button
        pbremovefrommask              matlab.ui.control.Button
        pbadd2mask                    matlab.ui.control.Button
        uipanel2                      matlab.ui.container.Panel
        SavewithOriginalFileCheckBox  matlab.ui.control.CheckBox
        pbinit                        matlab.ui.control.Button
        edmaskname                    matlab.ui.control.EditField
        pbshowmask                    matlab.ui.control.Button
        pbloadmask                    matlab.ui.control.Button
        pbsavemask                    matlab.ui.control.Button
    end

    properties (Access = private)
        %Property % Description
        imageData = struct('fileName', 'test.h5', 'fileDir', '.', 'trainingFolder', 'trainingSets', ... 
            'jsonFile', 'mask_calibration_metadata.json'); 
    end

    methods (Access = private)
    
        %------------------------------------------------------------------

        %------------------------------------------------------------------


        function addmask(app, varargin)
                    tmpmask = evalin('base', 'tmpmask');
                    mask = evalin('base', 'mask');
                    %mask = mask.*uint8(tmpmask);
                    mask = maskand(app, mask, tmpmask);
                    assignin('base', 'mask', mask);
        end
        
        function drawc(app, varargin)
                    f = findobj('tag', 'Figure_mask');
                    ax = findall(f, 'type', 'axes');
            
                    figure(f);
                    ctn = ginput(1);
                    setappdata(f, 'selectedpnt', ctn);
                    set(f, 'windowbuttonmotionfcn', @app.wdbmf_circle);
                    set(ax, 'userdata', []);
                    set(f, 'windowbuttondownfcn', @app.wdbdf_circle);
        end
        
        function drawcir(app, x, y, r, ns)
                    f = findobj('tag', 'Figure_mask');
                    ax = findall(f, 'type', 'axes');
                    theta = linspace(0, 2*pi, ns);
                    pline_x = r * cos(theta) + x;
                    pline_y = r * sin(theta) + y;
                    k = findall(ax, 'type', 'line');
                    delete(k)
                    line(pline_x, pline_y);
        end
        
        function drawpolygon(app, varargin)
                    f = findobj('tag', 'Figure_mask');
                    ax = findall(f, 'type', 'axes');
                    btdfcn = get(f, 'windowbuttondownfcn');
                    setappdata(f, 'oldwindowbuttondownfcn', btdfcn);
                    set(f, 'windowbuttondownfcn', @app.gtrack_on);
                    set(ax, 'userdata', []);
        end
        
        function edsmoothstep_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edsmoothstep (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function gtrack_off(app)
                    f = findobj('tag', 'Figure_mask');
                    ax = findall(f, 'type', 'axes');
                    lh = getappdata(f, 'linehandle');
                    if ~isempty(lh)
                        delete(lh)
                        lh = [];
                    end
                    btdfcn = getappdata(f, 'oldwindowbuttondownfcn');
                    set(f, 'windowbuttondownfcn', btdfcn);
                        t = get(ax, 'userdata');
                        t = [t; [t(1,1), t(1,2)]];
            %            set(ax, 'userdata', t);
                        if numel(t(:,1)) > 1
                            lh = line(t(:,1), t(:,2));
                        end
                        setappdata(f, 'linehandle', lh);
                    makemask(app, f, ax, t)
                    k = findall(f, 'type', 'line');
                    delete(k);
        end
        
        function gtrack_on(app, src, event)
                    f = findobj('tag', 'Figure_mask');
                    ax = findall(f, 'type', 'axes');
                    switch get(f, 'selectiontype')
                    case 'normal'
                        pt = get(ax, 'CurrentPoint');
                        xInd = pt(1, 1);
                        yInd = pt(1, 2);
                        t = get(ax, 'userdata');
                        t = [t; [xInd, yInd]];
                        set(ax, 'userdata', t);
                        if numel(t(:,1)) > 1
                            lh = line(t(:,1), t(:,2));
                            setappdata(f, 'linehandle', lh);
                        end
                    case 'extend'
                        gtrack_off(app)
                    case 'alt'
                        gtrack_off(app)
            %            winBtnDownFcn(handles.ImageAxes);
                    otherwise
                    end
        end
        
        function initiate(app, varargin)
            %setappdata(f, 'logdata', log10(img));
            %setappdata('base', 'mask', mask);
            
                    f = findobj('tag', 'Figure_mask');
                    img = getappdata(f, 'image');
                    sizea = size(img);
                    mask = uint8(ones(sizea));
                    assignin('base', 'mask', mask);
                    assignin('base', 'tmpmask', mask);
                    x = 1:sizea(1);
                    y = 1:sizea(2);
                    [X,Y] = meshgrid(y,x);
                    setappdata(f, 'X', X);
                    setappdata(f, 'Y', Y);
        end
        
        function varargout = invert(app, varargin)
                    if numel(varargin) == 1
                        tmpmask = abs(varargin{1} -1);
                        varargout{1} = tmpmask;
                    else
                        tmpmask = evalin('base', 'tmpmask');
                        tmpmask = abs(tmpmask -1);
                        showm(app, tmpmask);
                    end
                    assignin('base', 'tmpmask', tmpmask);
        end
        
        function loadCoordinates(app, varargin)
                    [filename, pathname] = uigetfile('*.txt', 'Pick a mask file');
                    if isequal(filename,0) || isequal(pathname,0)
                        disp('User pressed cancel')
                        return
                    else
                        fn = fullfile(pathname, filename);
                        disp(['User selected ', fn])
                    end
                    fid = fopen(fn);
                    xy = textscan(fid, '%d %d');
                    fclose(fid);
                    m = evalin('base', 'mask');
                    if isempty(m)
                        error('Use this function after a mask is built.')
                    end
                    if numel(xy)>0
                        tmpmsk = zeros(size(m));
                        t = sub2ind(size(aa), xy{1}, xy{2});
                        tmpmsk(t) = 1;
                        assignin('base', 'tmpmask', tmpmsk);
                    else
                        error('Blank file is loaded.')
                    end
        end
        
        function loadPilatusMask(app, varargin)
                    f = findobj('tag', 'Figure_mask');
                    img = getappdata(f, 'image');
                    msk = [];
                    if size(img,1) == 1679
                        try
                            [~, filepath] = APS_ch2currentfolder;
                        catch
                            filepath = pwd;
                            isSAXS = strfind(filepath, [filesep, 'SAXS']);
                            filepath(isSAXS:end) = [];
                        end
                        msk = imread(fullfile(filepath, filesep, 'SAXS', filesep, 'goodpix_mask.tif'));
                        msk2 = Pilatus2mBad;
                        msk = msk & msk2;
                    end
            
                    if size(img,1) == 619
                        try
                            [~, filepath] = APS_ch2currentfolder;
                        catch
                            filepath = pwd;
                            isSAXS = strfind(filepath, [filesep, 'WAXS']);
                            filepath(isSAXS:end) = [];
                        end
                        msk = imread(fullfile(filepath, filesep, 'WAXS', filesep, 'goodpix_mask.tif'));
                        msk2 = Pilatus300Bad;
                        msk = msk & msk2;
                    end
                    if ~isempty(msk)
                        assignin('base', 'tmpmask', msk);
                        showm(app, msk);
                    end
        end
        
        function loadSAXSBSmask(app, varargin)
                    load SAXSBSmask.mat;
                    oldmask = SAXSBSmask.mask;
                    oldcen = SAXSBSmask.center;
                    saxs = getgihandle;
                    newcen = saxs.center;
                    dcx = newcen(1)-oldcen(1);
                    dcy = newcen(2)-oldcen(2);
                    [X, Y] = meshgrid(1:1475, 1:1679);
                    nM = interp2(X+dcx, Y+dcy, double(oldmask), X, Y);
                    nM(isnan(nM)) = 1;
                    assignin('base', 'tmpmask', nM);
                    showm(app, nM);
        end
        
        function loadSAXSCNTBSmask(app, varargin)
                    load SAXSCNTBSmask.mat;
                    oldmask = SAXSCNTBSmask.mask;
                    oldcen = SAXSCNTBSmask.center;
                    saxs = getgihandle;
                    newcen = saxs.center;
                    dcx = newcen(1)-oldcen(1);
                    dcy = newcen(2)-oldcen(2);
                    [X, Y] = meshgrid(1:1475, 1:1679);
                    nM = interp2(X+dcx, Y+dcy, double(oldmask), X, Y);
                    nM(isnan(nM)) = 1;
                    assignin('base', 'tmpmask', nM);
                    showm(app, nM);
        end
        
        function loadSAXSframemask(app, varargin)
                    is2meter = 0;
                    try
                        s = getgihandle;
                        if (abs(s.SDD-2000) < 1000)
                            is2meter = 1;
                        end
                    catch
                        is2meter = 1;
                    end
                    switch is2meter
                        case 0
                            load SAXSframemask.mat;
                        case 1
                            load SAXSframemask2m.mat;
                    end
                    assignin('base', 'tmpmask', mask);
                    showm(app, mask);
        end
        
        function loadmask(app, varargin)
                    [filename, pathname] = uigetfile('*.*', 'Pick a mask file');
                    if isequal(filename,0) || isequal(pathname,0)
                        disp('User pressed cancel')
                        return
                    else
                        disp(['User selected ', fullfile(pathname, filename)])
                    end
                    msk = imread(fullfile(pathname, filename));
                    if isempty(evalin('base', 'mask'))
                        assignin('base', 'mask', msk);
                        return
                    else
                        m = evalin('base', 'mask');
                        if sum(m(:))==0
                            assignin('base', 'mask', msk);
                        end
                    end
                    assignin('base', 'tmpmask', msk);
        end
        
        function makemask(app, varargin)
                    f = varargin{1};
                    ax = varargin{2};
                    t = varargin{3};
                    X = getappdata(f, 'X');
                    Y = getappdata(f, 'Y');
                    t = get(ax, 'userdata');
                    tmpmask = inpolygon(X(:), Y(:), t(:,1), t(:,2));
                    tmpmask = double(tmpmask);
                    tmpmask(tmpmask == 0) = 2;
                    tmpmask = reshape(tmpmask, size(X));
                    tmpmask = tmpmask - 1;
                    showm(app, tmpmask);
                    assignin('base', 'tmpmask', tmpmask);
        end
        
        function rtn = maskand(app, a, b)
                    a = uint8(a);
                    b = uint8(b);
                    rtn = a & b;
        end
        
        function rtn = maskor(app, a, b)
                    a = uint8(a);
                    b = uint8(b);
                    rtn = a | b;
        end
        
        function pbsmooth_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in pbsmooth.
            
            % hObject    handle to pbsmooth (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %gc = evalin('base', 'gc');
             gc = [0.0063   41.2739
                0.0072   39.4490
                0.0081   38.6674
                0.0090   38.2356
                0.0099   37.9955
                0.0108   37.6382
                0.0117   36.9394
                0.0126   36.5603
                0.0135   36.6277
                0.0144   36.5361
                0.0153   35.7443
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                0.4479    0.6636];


            saxs = getgihandle;
            %mask = zeros(size(saxs.image));
            [x, y] = size(saxs.image);
            [Y, X] = meshgrid(1:y, 1:x);
            q = saxs.pxQ*sqrt((X-saxs.center(2)).^2 + (Y-saxs.center(1)).^2);
            
            q2 = 0:0.00001:(gc(1,1)-diff(gc(1:2), 1));
            x2 = log10(gc(:,1));
            y2 = log10(gc(:,2));
            dt = polyfit(x2(1:2), y2(1:2), 1);
            gc_qmin_fit = 10.^(log10(q2)*dt(1)+dt(2));
            gc = [q2(:), gc_qmin_fit(:); gc];
            
            Z = interp1(gc(:,1), gc(:,2), q(:));
            Z = reshape(Z, size(saxs.image));
            mask = saxs.image./Z;
            
            % making a mask
            mask(isnan(mask)) = 0;
            mask_mean = mean(mean(mask))/2;
            
            masked = mask;
            ind = find(masked<mask_mean);
            [maskrow, maskcol] = ind2sub(size(masked), ind);
            newr = maskrow;
            newc = maskcol;
            
            mask(mask < mask_mean) = 0;
            mask(mask >= mask_mean) = 1;
            masksize = size(mask);
            fprintf('Preliminary mask computed from the glass carbon is ready\n');
            %% find egde
            edgern = newr;
            edgecn = newc;
            tmask = masked;
            tmask((mask == 0)) = [];
            sig = std(tmask(:))*2;
            nb = 1;
            while ~isempty(edgern)
                switch nb
                    case 1
                        strn = '1st';
                    case 2
                        strn = '2nd';
                    case 3
                        strn = '3rd';
                    otherwise
                        strn = [num2str(nb), 'th'];
                end
                fprintf('Checking %s neigbhors.\n', strn)
                [edgern, edgecn] = maskedgefinder(edgern, edgecn, masked, sig);
                newr = [newr; edgern(:)];
                newc = [newc; edgecn(:)];
                indn = sub2ind(masksize, edgern(:), edgecn(:));
                mask(indn) = 0;
                nb = nb + 1;
            end
            %mask = ones(size(mask));
            %[maskrow, maskcol] = sub2ind(size(masked), ind);
            %mask(newr, newc) = 0;
            assignin('base', 'mask', mask);
            fprintf('Done.. A mask ready.\n')
        end
        
        function rmmask(app, varargin)
                    tmpmask = evalin('base', 'tmpmask');
                    mask = evalin('base', 'mask');
                    %mask = mask - uint8(tmpmask);
                    tm = invert(app, tmpmask);
                    mask = maskor(app, mask, tm);
                    assignin('base', 'mask', mask);
        end
        
        function savemask(app, event)
                    %handles = varargin{1};
                    mask = evalin('base', 'mask');
                    %filename = get(handles.edmaskname, 'string');
                    filename = app.edmaskname.Value;                    

                    %[filename, pathname] = uiputfile('*.bmp', 'Save as');
                    %if isequal(filename,0) || isequal(pathname,0)
                    %    disp('User pressed cancel')
                    %    return
                    %else
                        %imwrite(mask, fullfile(pathname, filename));
                    %pathname1 = pwd;
                    metainfo = sprintf('origin: %s', fullfile(app.imageData.fileDir, app.imageData.fileName));
                    pathname= uigetdir(pwd, 'Select folder to save file');
                    imwrite(mask, fullfile(pathname, filename), 'tif', 'Description',metainfo);                     
                    fprintf(['Mask saved as %s\n.', fullfile(pathname, filename)]);

                    if app.SavewithOriginalFileCheckBox.Value == 1
                        %folder = 'trainingSets_CURRENT_FOLDER_NAME';
                        [~, cfolder] = fileparts(regexprep(pathname,'[\\/]+$',''));  
                        fullfld = fullfile(pathname, sprintf('%s_%s', app.imageData.trainingFolder, cfolder));
                        calibSet=[]; % not calibration sets

                        maskSet.maskFileName = filename;
                        maskSet.maskOriginalLocation = pathname;
                        maskSet.motherFileName = app.imageData.fileName;
                        maskSet.motherOriginalLocation = app.imageData.fileDir;

                        maskSet.type='SAXS';
                        if contains(filename, 'WAXS', 'IgnoreCase', true)
                            maskSet.type='WAXS';
                        elseif contains(filename, 'PE', 'IgnoreCase', true)
                            maskSet.type='PE';
                        end

                        % updateMaskCalibrationDataset(maskSets, calibSets, destFolder)
                        updateMaskCalibrationDataset(maskSet, calibSet, fullfld);


                        % if ~isfolder(fullfld)
                        %     mkdir(fullfld);
                        %     % metaJsonData.mask.maskFileName = filename;
                        %     % metaJsonData.mask.maskFilePath = pathname;
                        %     % metaJsonData.mask.OriginFileName = app.imageData.fileName;
                        %     % metaJsonData.mask.OriginFilePath = app.imageData.fileDir;
                        %     % 
                        %     % jsonText = jsonencode(metaJsonData,'PrettyPrint',true);
                        %     % 
                        %     % %fid = fopen(fullfile(fullfld, 'mask_calibration_metadata.json'),'w');
                        %     % fid = fopen(fullfile(fullfld, app.imageData.jsonFile),'w');
                        %     % fprintf(fid,'%s',jsonText);
                        %     % fclose(fid);
                        % else
                        %     jsonText = fileread(fullfile(fullfld, app.imageData.jsonFile));
                        %     metaJsonData = jsondecode(jsonText);
                        % end
                        % 
                        % metaJsonData.mask.maskFileName = filename;
                        % metaJsonData.mask.maskFilePath = pathname;
                        % metaJsonData.mask.OriginFileName = app.imageData.fileName;
                        % metaJsonData.mask.OriginFilePath = app.imageData.fileDir;
                        % 
                        % jsonText = jsonencode(metaJsonData,'PrettyPrint',true);
                        % 
                        % %fid = fopen(fullfile(fullfld, 'mask_calibration_metadata.json'),'w');
                        % fid = fopen(fullfile(fullfld, app.imageData.jsonFile),'w');
                        % fprintf(fid,'%s',jsonText);
                        % fclose(fid);
                        % 
                        % copyfile(fullfile(app.imageData.fileDir, app.imageData.fileName), fullfld);
                        % copyfile(fullfile(pathname, filename), fullfld);
                        
                    end
                    % reading out metainfo:
                    % info = iminfo('mask.tif');
                    % description = info.ImageDescription;
        end
        
        function showimage(app, event)  % showimage(app, varargin)
                    f = findobj('tag', 'Figure_mask');
                    img = getappdata(f, 'image');
                    img = double(img);
                    %ax = findobj(f, 'type', 'axes');
                    ax = findall(f, 'type', 'image');
                    imscale = getappdata(f, 'imagescale');
                    if imscale == 1
                        set(ax, 'cdata', log10(abs(img)));
                        %setappdata(f, 'imagescale', 1);
                    else
                        set(ax, 'cdata', img);
                        %setappdata(f, 'imagescale', 0);
                    end
                    %pbsetscale_Callback([],[],varargin{3})
                    pbsetscale_Callback(app,event)
            
                    %set(ax, 'cdata', img);
                    E = ones(size(img));
                    [ver, dat] = version();
                    t = findstr(ver, '.');ver = str2double(ver(1:t(2)-1));
                    if ver >= 8.5
                        set(ax, 'alphadata', E, 'AlphaDataMapping', 'none');
                    else
                        set(ax, 'alphadata', E);
                    end
        end
        
        function showm(app, msk)
                    f = findobj('tag', 'Figure_mask');
                    try
                    if ~isempty(maskhandle)
                        delete(maskhandle)
                        maskhandle = [];
                    end
                    catch
                        maskhandle = [];
                    end
                    %hold on;
                    %maskhandle = image(E);
                    %hold off;
                    %E = abs(msk-1)*0.5; %E = E*0.5;
                    %E = uint8(abs(msk));
                    E = abs(double(msk));
                    E(E<=0)=0.3;
                    h = findall(f, 'type', 'image');
                    [ver, dat] = version();
                    t = strfind(ver, '.');ver = str2double(ver(1:t(2)-1));
                    if ver >= 8.5
                        %set(h, 'alphadata', E);
                        alpha(h, E);
                        %set(h, 'alphadata', E, 'AlphaDataMapping', 'scaled');
                    else
                        set(h, 'alphadata', E);
                    end
                    set(f, 'windowbuttonmotionfcn', '');
                    set(f, 'windowbuttondownfcn', '');
        end
        
        function showmask(app, varargin)
                    mask = evalin('base', 'mask');
                    showm(app, mask);
        end
        
        function wdbdf_circle(app, varargin)
                    f = findobj('tag', 'Figure_mask');
                    ax = findall(f, 'type', 'axes');
                    X = getappdata(f, 'X');
                    Y = getappdata(f, 'Y');
                    ctn = getappdata(f, 'selectedpnt');
                    r = getappdata(f, 'r');
                    m = sqrt(abs(X-ctn(1)).^2+abs(Y-ctn(2)).^2)-r;
                    indx = m > 0;
                    %indy = m <= 0;
                    tmpmask = zeros(size(X));
                    tmpmask(indx) = 1;
                    tmpmask = reshape(tmpmask, size(X));
                    k = findall(ax, 'type', 'line');
                    delete(k)
                    showm(app, tmpmask);
                    assignin('base', 'tmpmask', tmpmask);
        end
        
        function wdbmf_circle(app, varargin)
                    f = findobj('tag', 'Figure_mask');
                    ax = findall(f, 'type', 'axes');
            
                    pt = get(ax, 'CurrentPoint');
                    ctn = getappdata(f, 'selectedpnt');
                    r = sqrt(abs(ctn(1)-pt(1,1)).^2 + abs(ctn(2)-pt(1,2)).^2);
                    setappdata(f, 'r', r);
                    drawcir(app, ctn(1), ctn(2), r, 100)
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function maskmaker_OpeningFcn(app, varargin)
            % --- Executes just before maskmaker is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to maskmaker (see VARARGIN)
            
            % Choose default command line output for maskmaker
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: edhighlimit
        function edhighlimit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edhighlimit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edhighlimit as text
            %        str2double(get(hObject,'String')) returns contents of edhighlimit as a double
            
            %pbsetscale_Callback(hObject, eventdata, handles)
            pbsetscale_Callback(app, event)
        end

        % Value changed function: edlowlimit
        function edlowlimit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edlowlimit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edlowlimit as text
            %        str2double(get(hObject,'String')) returns contents of edlowlimit as a double
            
            %pbsetscale_Callback(hObject, eventdata, handles)
            pbsetscale_Callback(app, event)

        end

        % Button pushed function: pbsmooth
        function pb_gcmasking_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                    % assuming there are azimavgmap available.
                    azimavgmap = evalin('base', 'azimavgmap');
                    f = findobj('tag', 'Figure_mask');
                    img = getappdata(f, 'image');
                    load L17_12IDB.mat;
                    ref = L17_12IDB;
                    gc = interp1(ref(:,1), ref(:,2), azimavgmap.qmap);
                    gc = reshape(gc, size(img));
                    gc = gc/mean(gc(:));
                    img = img/mean(img(:));
                    m = gc./img;
                    mask = ones(size(m));
                    mask(m>2) = 0;
                    mask(isinf(m)) = 0;
                    mask(m<=0) = 0;
                    t = find(mask == 0);
                    img2 = img;img2(t) = [];
                    mean_goodpix = mean(img2);
                    img = img/mean_goodpix;
                    img2=img2/mean_goodpix;
                    gc2=gc;gc2(t) = [];
                    mean_goodpix_gc = mean(gc2);
                    gc2 = gc2/mean_goodpix_gc;
                    gc = gc/mean_goodpix_gc;
                    img = img./gc;
                    setappdata(f, 'sensitivity_image', img);
                    showm(app, mask);
                    assignin('base', 'tmpmask', mask);
        end

        % Button pushed function: pb_load12IDBframe
        function pb_load12IDBframe_Callback(app, event)
            % --- Executes on button press in pb_load12IDBframe.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pb_load12IDBframe (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            loadSAXSframemask(app)
        end

        % Button pushed function: pb_loadCenterBS
        function pb_loadCenterBS_Callback(app, event)
            % --- Executes on button press in pb_loadCenterBS.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pb_loadCenterBS (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            loadSAXSCNTBSmask(app)
        end

        % Button pushed function: pb_loadPilatusMask
        function pb_loadPilatusMask_Callback(app, event)
            % --- Executes on button press in pb_loadPilatusMask.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pb_loadPilatusMask (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            loadPilatusMask(app)
        end

        % Button pushed function: pb_loadSAXSBS
        function pb_loadSAXSBS_Callback(app, event)
            % --- Executes on button press in pb_loadSAXSBS.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pb_loadSAXSBS (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            loadSAXSBSmask(app)
        end

        % Button pushed function: pb_loadcoordinates
        function pb_loadcoordinates_Callback(app, event)
            % --- Executes on button press in pb_loadcoordinates.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pb_loadcoordinates (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            loadCoordinates(app)
        end

        % Button pushed function: pbadd2mask
        function pbadd2mask_Callback(app, event)
            % --- Executes on button press in pbadd2mask.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbadd2mask (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            addmask(app)
        end

        % Button pushed function: pbdrawcircle
        function pbdrawcircle_Callback(app, event)
            % --- Executes on button press in pbdrawcircle.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbdrawcircle (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            drawc(app)
        end

        % Button pushed function: pbdrawpolygon
        function pbdrawpolygon_Callback(app, event)
            % --- Executes on button press in pbdrawpolygon.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbdrawpolygon (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            drawpolygon(app)
        end

        % Button pushed function: pbhisto
        function pbhisto_Callback(app, event)
            % --- Executes on button press in pbhisto.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbhisto (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            f = findobj('tag', 'Figure_mask');
            %ax = findall(f, 'type', 'image');
            img = getappdata(f, 'sensitivity_image');
            [n,x] = hist(img(:), 0:0.02:5);
            
            f = ezfit(x, n, 'gauss');
            figure;
            plot(x, n, 'o');showfit(f)
        end

        % Button pushed function: pbinit
        function pbinit_Callback(app, event)
            % --- Executes on button press in pbinit.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbinit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            initiate(app)
        end

        % Button pushed function: pbinvertselection
        function pbinvertselection_Callback(app, event)
            % --- Executes on button press in pbinvertselection.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbinvertselection (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            invert(app)
        end

        % Button pushed function: pbloadimage
        function pbloadimage_Callback(app, event)
            % --- Executes on button press in pbloadimage.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbloadimage (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            saxs = getgihandle;
            app.imageData.fileName = saxs.imagename;
            app.imageData.fileDir  = saxs.dir;
            if isempty(saxs)
                img = get(findall(0, 'type', 'image'), 'CData');
            else
                if isfield(saxs, 'image')
                    if ndims(saxs.image)==3
                        img = saxs.image(:, :, saxs.frame);
                    else
                        img = saxs.image;
                    end
                else
                    [filename, pathname] = uigetfile('*.*', 'Pick a mask file');
                    if isequal(filename,0) || isequal(pathname,0)
                        disp('User pressed cancel')
                        return
                    else
                        disp(['User selected ', fullfile(pathname, filename)])
                    end
                    img = imread(fullfile(pathname, filename));
                end
            end
            f = findobj('tag', 'Figure_mask');
            if isempty(f)
                f = figure;
                set(f, 'tag', 'Figure_mask');
            end
            ax = findall(f, 'type', 'axes');
            %image(img, 'parent', ax);
            figure(f);
            imagesc(img);
            set(findobj(f, 'type', 'axes'), 'cLimmode', 'manual')
            setappdata(f, 'image', img);
            setappdata(f, 'imagescale', 0);
            initiate(app)
        end

        % Button pushed function: pbloadmask
        function pbloadmask_Callback(app, event)
            % --- Executes on button press in pbloadmask.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbloadmask (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            loadmask(app)
        end

        % Button pushed function: pbloglin
        function pbloglin_Callback(app, event)
            % --- Executes on button press in pbloglin.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbloglin (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            f = findobj('tag', 'Figure_mask');
            ax = findall(f, 'type', 'image');
            img = double(getappdata(f, 'image'));
            imscale = getappdata(f, 'imagescale');
            if imscale == 0
                set(ax, 'cdata', log10(abs(img)));
                setappdata(f, 'imagescale', 1);
                app.edlowlimit.Value = '0';
                app.edhighlimit.Value = '5';
            else
                set(ax, 'cdata', img);
                setappdata(f, 'imagescale', 0);
                app.edlowlimit.Value = '0';
                app.edhighlimit.Value = '50';
            end
            %pbsetscale_Callback(hObject, eventdata, handles)
            pbsetscale_Callback(app, event)
        end

        % Button pushed function: pbremovefrommask
        function pbremovefrommask_Callback(app, event)
            % --- Executes on button press in pbremovefrommask.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbremovefrommask (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            rmmask(app)
        end

        % Button pushed function: pbsavemask
        function pbsavemask_Callback(app, event)
            % --- Executes on button press in pbsavemask.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbsavemask (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %savemask(app, handles)
            savemask(app, event)
        end

        % Button pushed function: pbsetscale
        function pbsetscale_Callback(app, event)
            % --- Executes on button press in pbsetscale.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbsetscale (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            f = findobj('tag', 'Figure_mask');
            %if isempty(f)
            %    f = figure;
            %    set(f, 'tag', 'Figure_mask');
            %end
            %img = getappdata(f, 'image');
            figure(f)
            ax = findall(f, 'type', 'axes');
            %set(ax, 'cLimmode', climmode);
            ll = str2double(get(handles.edlowlimit, 'string'));
            hl = str2double(get(handles.edhighlimit, 'string'));
            set(ax, 'cLim', [ll,hl]);
        end

        % Button pushed function: pbsetthresh
        function pbsetthresh_Callback(app, event)
            % --- Executes on button press in pbsetthresh.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbsetthresh (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            showimage(app, [],[], handles)
            f = findobj('tag', 'Figure_mask');
            %ax = findall(f, 'type', 'image');
            img = getappdata(f, 'sensitivity_image');
            if isempty(img)
                img = double(getappdata(f, 'image'));
            end
            lim = eval(get(handles.ed_thresh, 'string'));
            ll = lim(1); ul = lim(2);
            mask = ones(size(img));
            mask(img<ll) = 0;
            mask(img>ul) = 0;
            
            showm(app, mask);
            assignin('base', 'tmpmask', mask);
        end

        % Button pushed function: pbshowmask
        function pbshowmask_Callback(app, event)
            % --- Executes on button press in pbshowmask.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbshowmask (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            switch get(hObject, 'string')
                case 'Show mask'
                    showmask(app, hObject, eventdata, handles)
                    set(hObject, 'string', 'Show image');
                case 'Show image'
                    %showimage(app, hObject, eventdata, handles)
                    showimage(app, event)
                    set(hObject, 'string', 'Show mask');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'maskmaker';
            app.figure1.Tag = 'figure1';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'File handling';
            app.uipanel2.Tag = 'uipanel2';
            app.pbsavemask = uibutton(app.uipanel2, 'push');
            app.pbsavemask.ButtonPushedFcn = createCallbackFcn(app, @pbsavemask_Callback, true);
            app.pbsavemask.Tag = 'pbsavemask';
            app.pbsavemask.Text = 'Save mask';
            app.pbloadmask = uibutton(app.uipanel2, 'push');
            app.pbloadmask.ButtonPushedFcn = createCallbackFcn(app, @pbloadmask_Callback, true);
            app.pbloadmask.Tag = 'pbloadmask';
            app.pbloadmask.Text = 'Load mask';
            app.pbshowmask = uibutton(app.uipanel2, 'push');
            app.pbshowmask.ButtonPushedFcn = createCallbackFcn(app, @pbshowmask_Callback, true);
            app.pbshowmask.Tag = 'pbshowmask';
            app.pbshowmask.Text = 'Show mask';
            app.edmaskname = uieditfield(app.uipanel2, 'text');
            app.edmaskname.Tag = 'edmaskname';
            app.edmaskname.Value = 'mask.tif';
            app.pbinit = uibutton(app.uipanel2, 'push');
            app.pbinit.ButtonPushedFcn = createCallbackFcn(app, @pbinit_Callback, true);
            app.pbinit.Tag = 'pbinit';
            app.pbinit.Text = 'Initialize';
            app.SavewithOriginalFileCheckBox = uicheckbox(app.uipanel2);
            app.SavewithOriginalFileCheckBox.Text = 'Save with Original File';
            app.SavewithOriginalFileCheckBox.Value = true;
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Title = 'Arithmetics';
            app.uipanel4.Tag = 'uipanel4';
            app.pbadd2mask = uibutton(app.uipanel4, 'push');
            app.pbadd2mask.ButtonPushedFcn = createCallbackFcn(app, @pbadd2mask_Callback, true);
            app.pbadd2mask.Tag = 'pbadd2mask';
            app.pbadd2mask.Text = 'Add to mask';
            app.pbremovefrommask = uibutton(app.uipanel4, 'push');
            app.pbremovefrommask.ButtonPushedFcn = createCallbackFcn(app, @pbremovefrommask_Callback, true);
            app.pbremovefrommask.Tag = 'pbremovefrommask';
            app.pbremovefrommask.Text = 'Remove from mask';
            app.pbinvertselection = uibutton(app.uipanel4, 'push');
            app.pbinvertselection.ButtonPushedFcn = createCallbackFcn(app, @pbinvertselection_Callback, true);
            app.pbinvertselection.Tag = 'pbinvertselection';
            app.pbinvertselection.Text = 'Invert the selection';
            app.pbdrawpolygon = uibutton(app.uipanel4, 'push');
            app.pbdrawpolygon.ButtonPushedFcn = createCallbackFcn(app, @pbdrawpolygon_Callback, true);
            app.pbdrawpolygon.Tag = 'pbdrawpolygon';
            app.pbdrawpolygon.Text = 'Draw Polygon';
            app.pbdrawcircle = uibutton(app.uipanel4, 'push');
            app.pbdrawcircle.ButtonPushedFcn = createCallbackFcn(app, @pbdrawcircle_Callback, true);
            app.pbdrawcircle.Tag = 'pbdrawcircle';
            app.pbdrawcircle.Text = 'Draw Circle';
            app.uipanel5 = uipanel(app.figure1);
            app.uipanel5.Title = 'Image loading';
            app.uipanel5.Tag = 'uipanel5';
            app.edlowlimit = uieditfield(app.uipanel5, 'text');
            app.edlowlimit.ValueChangedFcn = createCallbackFcn(app, @edlowlimit_Callback, true);
            app.edlowlimit.Tag = 'edlowlimit';
            app.edlowlimit.Value = '0';
            app.pbsetscale = uibutton(app.uipanel5, 'push');
            app.pbsetscale.ButtonPushedFcn = createCallbackFcn(app, @pbsetscale_Callback, true);
            app.pbsetscale.Tag = 'pbsetscale';
            app.pbsetscale.Text = 'Set scale';
            app.pbloglin = uibutton(app.uipanel5, 'push');
            app.pbloglin.ButtonPushedFcn = createCallbackFcn(app, @pbloglin_Callback, true);
            app.pbloglin.Tag = 'pbloglin';
            app.pbloglin.Text = 'Log/Linear';
            app.edhighlimit = uieditfield(app.uipanel5, 'text');
            app.edhighlimit.ValueChangedFcn = createCallbackFcn(app, @edhighlimit_Callback, true);
            app.edhighlimit.Tag = 'edhighlimit';
            app.edhighlimit.Value = '200';
            app.pbloadimage = uibutton(app.uipanel5, 'push');
            app.pbloadimage.ButtonPushedFcn = createCallbackFcn(app, @pbloadimage_Callback, true);
            app.pbloadimage.Tag = 'pbloadimage';
            app.pbloadimage.Text = 'Load image';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Quick make';
            app.uipanel1.Tag = 'uipanel1';
            app.pbhisto = uibutton(app.uipanel1, 'push');
            app.pbhisto.ButtonPushedFcn = createCallbackFcn(app, @pbhisto_Callback, true);
            app.pbhisto.Tag = 'pbhisto';
            app.pbhisto.Text = 'Show histogram';
            app.pbsetthresh = uibutton(app.uipanel1, 'push');
            app.pbsetthresh.ButtonPushedFcn = createCallbackFcn(app, @pbsetthresh_Callback, true);
            app.pbsetthresh.Tag = 'pbsetthresh';
            app.pbsetthresh.Text = 'Set thresh';
            app.ed_thresh = uieditfield(app.uipanel1, 'text');
            app.ed_thresh.Tag = 'ed_thresh';
            app.ed_thresh.Value = '[0.2, 1.8]';
            app.pbsmooth = uibutton(app.uipanel1, 'push');
            app.pbsmooth.ButtonPushedFcn = createCallbackFcn(app, @pb_gcmasking_Callback, true);
            app.pbsmooth.Tag = 'pbsmooth';
            app.pbsmooth.Text = 'using Glass Carbon';
            app.uipanel6 = uipanel(app.figure1);
            app.uipanel6.Title = 'Premade Mask';
            app.uipanel6.Tag = 'uipanel6';
            app.pb_load12IDBframe = uibutton(app.uipanel6, 'push');
            app.pb_load12IDBframe.ButtonPushedFcn = createCallbackFcn(app, @pb_load12IDBframe_Callback, true);
            app.pb_load12IDBframe.Tag = 'pb_load12IDBframe';
            app.pb_load12IDBframe.Text = '12IDBSAXSframe';
            app.pb_loadSAXSBS = uibutton(app.uipanel6, 'push');
            app.pb_loadSAXSBS.ButtonPushedFcn = createCallbackFcn(app, @pb_loadSAXSBS_Callback, true);
            app.pb_loadSAXSBS.Tag = 'pb_loadSAXSBS';
            app.pb_loadSAXSBS.Text = 'SAXSBS';
            app.pb_loadCenterBS = uibutton(app.uipanel6, 'push');
            app.pb_loadCenterBS.ButtonPushedFcn = createCallbackFcn(app, @pb_loadCenterBS_Callback, true);
            app.pb_loadCenterBS.Tag = 'pb_loadCenterBS';
            app.pb_loadCenterBS.Text = 'SAXSCNTBS';
            app.pb_loadPilatusMask = uibutton(app.uipanel6, 'push');
            app.pb_loadPilatusMask.ButtonPushedFcn = createCallbackFcn(app, @pb_loadPilatusMask_Callback, true);
            app.pb_loadPilatusMask.Tag = 'pb_loadPilatusMask';
            app.pb_loadPilatusMask.Text = 'Pilatus';
            app.pb_loadcoordinates = uibutton(app.uipanel6, 'push');
            app.pb_loadcoordinates.ButtonPushedFcn = createCallbackFcn(app, @pb_loadcoordinates_Callback, true);
            app.pb_loadcoordinates.Tag = 'pb_loadcoordinates';
            app.pb_loadcoordinates.Text = 'Load Coordinates';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = maskmaker_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)maskmaker_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end