classdef qCalibration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                matlab.ui.Figure
        uipanel17              matlab.ui.container.Panel
        makeMask               matlab.ui.control.Button
        pbDetQCalib            matlab.ui.control.Button
        processCurrentImg      matlab.ui.control.Button
        processMultipleImages  matlab.ui.control.Button
        processSingleImage     matlab.ui.control.Button
        pushbutton16           matlab.ui.control.Button
        pbSaveSetup            matlab.ui.control.Button
        pbLoadSetup            matlab.ui.control.Button
        uipanel14              matlab.ui.container.Panel
        absIntCoefftext        matlab.ui.control.Label
        absIntSelectData       matlab.ui.control.Button
        ed_absIntQmin          matlab.ui.control.EditField
        text91                 matlab.ui.control.Label
        ed_absIntQmax          matlab.ui.control.EditField
        AbsIntlistbox          matlab.ui.control.ListBox
        text90                 matlab.ui.control.Label
        uipanel10              matlab.ui.container.Panel
        SaveCalibDataCheckBox  matlab.ui.control.CheckBox
        ConvH5toTiff           matlab.ui.control.Button
        uibuttongroup1         matlab.ui.container.ButtonGroup
        folderStr              matlab.ui.control.Label
        rdAveraged             matlab.ui.control.RadioButton
        rdSelectAvgFolder      matlab.ui.control.RadioButton
        rdAve                  matlab.ui.control.RadioButton
        uipanel15              matlab.ui.container.Panel
        excludeSectorMask      matlab.ui.control.CheckBox
        useSectorMask          matlab.ui.control.CheckBox
        arbitraryMaskExclude   matlab.ui.control.CheckBox
        arbitraryMaskOn        matlab.ui.control.CheckBox
        text95                 matlab.ui.control.Label
        plotSector             matlab.ui.control.Button
        sectorA2               matlab.ui.control.EditField
        sectorA1               matlab.ui.control.EditField
        text93                 matlab.ui.control.Label
        combineMask            matlab.ui.control.Button
        text41                 matlab.ui.control.Label
        pbLoadMaskFile         matlab.ui.control.Button
        txQmax                 matlab.ui.control.Label
        text39                 matlab.ui.control.Label
        txQmin                 matlab.ui.control.Label
        text37                 matlab.ui.control.Label
        text36                 matlab.ui.control.Label
        edQMax                 matlab.ui.control.EditField
        edQMin                 matlab.ui.control.EditField
        pbCalQCorrMaps         matlab.ui.control.Button
        uipanel13              matlab.ui.container.ButtonGroup
        text62                 matlab.ui.control.Label
        text61                 matlab.ui.control.Label
        text60                 matlab.ui.control.Label
        cLogDQ3                matlab.ui.control.EditField
        cLogQ3                 matlab.ui.control.EditField
        cLogDQ2                matlab.ui.control.EditField
        cLogPts                matlab.ui.control.EditField
        cLogQ2                 matlab.ui.control.EditField
        combLogLinear          matlab.ui.control.RadioButton
        text58                 matlab.ui.control.Label
        pbCalExistQIndxMap     matlab.ui.control.Button
        text53                 matlab.ui.control.Label
        edDQ3                  matlab.ui.control.EditField
        text52                 matlab.ui.control.Label
        edQ3                   matlab.ui.control.EditField
        text51                 matlab.ui.control.Label
        edDQ2                  matlab.ui.control.EditField
        text50                 matlab.ui.control.Label
        edQ2                   matlab.ui.control.EditField
        text49                 matlab.ui.control.Label
        edDQ1                  matlab.ui.control.EditField
        rbCombLinearQ          matlab.ui.control.RadioButton
        text33                 matlab.ui.control.Label
        pbCalQIndxMap          matlab.ui.control.Button
        edPtsNum               matlab.ui.control.EditField
        rbLogQ                 matlab.ui.control.RadioButton
        rbLinearQ              matlab.ui.control.RadioButton
        rbPixelBasedQ          matlab.ui.control.RadioButton
        uipanel9               matlab.ui.container.Panel
        CalibrantdataLabel     matlab.ui.control.Label
        CopyFromDetQCalib      matlab.ui.control.Button
        text29                 matlab.ui.control.Label
        edYaw                  matlab.ui.control.EditField
        text28                 matlab.ui.control.Label
        edSDD                  matlab.ui.control.EditField
        text27                 matlab.ui.control.Label
        edBeamY                matlab.ui.control.EditField
        text26                 matlab.ui.control.Label
        edBeamX                matlab.ui.control.EditField
        qStandard              matlab.ui.control.ListBox
        text87                 matlab.ui.control.Label
        absIntStandard         matlab.ui.control.ListBox
        text86                 matlab.ui.control.Label
        text85                 matlab.ui.control.Label
        edAbsCoeff             matlab.ui.control.EditField
        edLimitLb              matlab.ui.control.EditField
        text35                 matlab.ui.control.Label
        edLimitUb              matlab.ui.control.EditField
        text34                 matlab.ui.control.Label
        edOffset               matlab.ui.control.EditField
        text16                 matlab.ui.control.Label
        edEnergy               matlab.ui.control.EditField
        uipanel7               matlab.ui.container.Panel
        edPixelsize            matlab.ui.control.EditField
        Beamline               matlab.ui.control.ListBox
        text88                 matlab.ui.control.Label
        txtPixelsize           matlab.ui.control.Label
        bgDetector             matlab.ui.container.ButtonGroup
        rbEiger9m              matlab.ui.control.RadioButton
        rbOther                matlab.ui.control.RadioButton
        rbPilatus900k          matlab.ui.control.RadioButton
        rbPE                   matlab.ui.control.RadioButton
        text56                 matlab.ui.control.Label
        edCol                  matlab.ui.control.EditField
        text550                matlab.ui.control.Label
        edRow                  matlab.ui.control.EditField
        rbPilatus300           matlab.ui.control.RadioButton
        rbPilatus2m            matlab.ui.control.RadioButton
    end

    properties (Access = private)
        %Property % Description
        imageData = struct('fileName', 'test.h5', 'fileDir', '.', 'trainingFolder', 'trainingSets', ... 
            'jsonFile', 'mask_calibration_metadata.json'); 
    end


    methods (Access = private)
        function isReady = checkReadiness(app)
            isReady = 1;
            [qCalData, userData, scatt, calibrant]= getData(app);
            
            if ~isfield(scatt, 'mask') || isempty(scatt.mask)
                disp('Mask is not ready! ...')
                isReady = 0;
                return;
            end
            
            if ~isfield(scatt, 'qCMap') || isempty(scatt.qCMap)
                isReady = 0;
                disp('qCMap is not ready! ...')
                return;
            end
            
            if ~isfield(scatt, 'qRMap') || isempty(scatt.qRMap)
                isReady = 0;
                disp('qRMap is not ready! ...')
                return;
            end
            
            if ~isfield(scatt, 'qArray') || isempty(scatt.qArray)
                isReady = 0;
                disp('qArray is not ready! ...')
                return;
            end
            
            if ~isfield(scatt, 'offset') || isempty(scatt.offset)
                isReady = 0
                disp('offset is not available! ...')
                return;
            end
            
            if ~isfield(scatt, 'limits') || isempty(scatt.limits)
                isReady = 0;
                disp('limits is not available! ...')
                return;
            end
        end
        
        function edAend_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edAend (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edAstart_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edAstart (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edCX_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edCX (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edCY_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edCY (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edDA_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edDA (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edDPixel_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edDPixel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edDist_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edDist (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edQValue_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edQValue (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit50_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit50 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit51_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit51 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit52_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit52 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit72_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit72 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit73_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit73 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit74_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit74 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit75_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit75 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit76_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit76 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit77_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit77 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit78_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit78 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit79_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit79 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function fbPilatus2MCalib_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in fbPilatus2MCalib.
            
            % hObject    handle to fbPilatus2MCalib (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % This function find multiple pnts on a diffraction ring and stack them in
            % the
            % updateQarray(handles)
             [qCalData, userData, scatt, calibrant]= getData(app);
            
            cX = str2double(get(handles.edCX, 'string'));
            cY = str2double(get(handles.edCY, 'string'));
            dist = str2double(get(handles.edDist, 'string'));
            qValue = str2double(get(handles.edQValue, 'string'));
            Astart = str2double(get(handles.edAsart, 'string'));
            Aend = str2double(get(handles.edAend, 'string'));
            dA = str2double(get(handles.edDA, 'string'));
            dPixel = str2double(get(handles.edDPixel, 'string'));
            angles = Astart:dA:Aend;
            aLen = length(angles);
            
            try
                mousePts = evalin('base', 'mousepnt');
                %cImgName = evalin('base', 'cImgName');
                %cImg = imgUpsideDn(double(imread(cImgName)));
                cImgName2 = getgihandle;
                cImg = cImgName2.image;
                [row, col] = size(cImg);
                bnds =[col row];
                halfW = 30;
                pks=[];
                rsqVal = 0.95;
                for kk = 1:length(mousePts.x)
            
                    x=round(mousePts.x(kk));
                    y=round(mousePts.y(kk));
                    cpt = [x y];
                    pkV = findVertPeak(cpt, cImg, halfW, bnds, rsqVal);
                    pkH = findHorzPeak(cpt, cImg, halfW, bnds, rsqVal);
                    newPKs = [pkV pkH];
                    if isempty(newPKs)
                        newPKs = [x;y];
                    end
                    pks = [pks newPKs];
                end
            
                cdat.xy = sortrows(pks')';
            
                %cdat.xy = [mousePts.x; mousePts.y];
            
                cq = input('Please enter the q-value for the selected ring:');
                cdat.q=cq;
            
                calibrant.data = [calibrant.data cdat]
                qCalData.calibrant = calibrant;
                assignin('base','qCalData', qCalData);
            catch
                disp('Pick calibrant pixels in SAXSimageviewer window ....');
                calibrant.data = [];
                calibrant.wavelength = 0.;
                qCalData.calibrant = qCalData.userData.wavelength;
                assignin('base','qCalData', qCalData);
            end


            % if isfield(scatt,'qMap') & isfield(scatt, 'qArray') & ~isempty(scatt.qMap) & ~isempty(scatt.qArray)
            %     scatt.qRMap = calQRMap(scatt.qMap, scatt.qArray);
            %     disp('Q index map done!')
            % else
            %     disp('Not Ready! Calculate Q and Corr Maps first ....')
            % end
            setData(app, qCalData, userData, scatt, calibrant);
        end
        
        function voxels = genPolygonVoxels(app, A1, A2, vx1, vx2)
            ang1 = vx1(:,1);
            ang2 = vx2(:,1);
            AA1 = vx1(3,1);
            AA2 = vx2(3,1);
            if A1 >= A2
                kk= find(ang1==AA2);
                voxels = [vx1(1:kk-1,2:3); vx2(2,2:3) ];
            else
                kk= find(ang2==AA1);
                voxels = [vx2(1:kk-1,2:3); vx1(2,2:3) ];
            end
        end
        
        function [xy, vx] = genSectorLine(app, beamxy, imgsize, angle, nPts)
                %vx = []; vx polygon voxels in anti-clock directions,
                col=imgsize(2); row = imgsize(1);
                theta1=getSectorAngle(app, beamxy,[col row]);
                theta2=getSectorAngle(app, beamxy,[1 row]);
                theta3=getSectorAngle(app, beamxy,[1 1]);
                theta4=getSectorAngle(app, beamxy,[col 1]);
                %vx = [theta1 col row; theta2 1 row; theta3 1 1; theta4 col 1 ];
            
                if angle <= theta1
                    x=col; y = ceil(beamxy(2) + (x-beamxy(1))*tand(angle));
                    vx = [-1 beamxy(1) beamxy(2); angle x y;  theta1 col row; theta2 1 row; theta3 1 1; theta4 col 1 ];
                elseif angle <= theta2
                    if angle == 90
                        y = row; x=beamxy(1);
                    else
                        y = row; x = round(beamxy(1) + (y-beamxy(2))/tand(angle));
                    end
                    vx = [-1 beamxy(1) beamxy(2); angle x y;   theta2 1 row; theta3 1 1; theta4 col 1; theta1 col row];
                elseif angle <= theta3
                    x = 1; y = ceil(beamxy(2) + (x-beamxy(1))*tand(angle));
                    vx = [-1 beamxy(1) beamxy(2); angle x y;   theta3 1 1; theta4 col 1; theta1 col row; theta2 1 row];
                elseif angle <= theta4
                    if angle == 270
                        y = 1; x=beamxy(1);
                    else
                        y = 1; x = round(beamxy(1) + (y-beamxy(2))/tand(angle));
                    end
                    vx = [-1 beamxy(1) beamxy(2); angle x y;  theta4 col 1; theta1 col row; theta2 1 row;  theta3 1 1];
                else
                    x=col; y = ceil(beamxy(2) + (x-beamxy(1))*tand(angle));
                    vx = [-1 beamxy(1) beamxy(2); angle x y;  theta1 col row; theta2 1 row;  theta3 1 1; theta4 col 1];
                end
            
                xx=linspace(beamxy(1), x, nPts);
                yy=linspace(beamxy(2), y, nPts);
                xy = [xx' yy'];
        end
        
        function genSectorMask(app, event)
            % generate Sector mask
            
            [qCalData, userData, scatt, calibrant]= getData(app);
            tic
            sectorMaskOn = scatt.sectorMaskOn;
            if ~sectorMaskOn
                scatt.sectorMask = [];
            else
                try
                    figH = evalin('base', 'SAXSimageviewerhandle');
                catch
                    fprintf('Start SAXSimageView first...\n');
                    return;
                end
                %h2 = guidata(figH);
                % ImageView handle" h2.ImageAxes
                imgH = getgihandle;
                imgHrow = imgH.imgsize(1); imgHcol = imgH.imgsize(2);
                row = scatt.row; col = scatt.col;
                if (row ~= imgHrow || col ~= imgHcol)
                    disp('image size not match! use qCalibration2 parameters!');
                end
            
                beamXY = scatt.BeamXY;
                nPts = 100;
            
                [~, vx1]=genSectorLine(app, beamXY, imgH.imgsize, scatt.sectorA1, nPts);
                [~, vx2]=genSectorLine(app, beamXY, imgH.imgsize, scatt.sectorA2, nPts);
            
                voxels = genPolygonVoxels(app, scatt.sectorA1, scatt.sectorA2, vx1, vx2 );
                tic
                imgx = [];
                imgy = [];
                xv = voxels(:,1); yv =voxels(:,2);
                col = scatt.col; row = scatt.row;
                for kk=1:col
                    imgx = [imgx ones(1,row)*kk];
                    imgy = [imgy 1:row];
                end
                toc
                in = inpolygon(imgx,imgy,xv,yv);
                mask2 = reshape(in,[row, col]);
            
            % if scatt.sectorMaskOn
                if scatt.sectorMaskExclude
                    scatt.sectorMask = 1-mask2;
                    figure(3001); imagesc(scatt.sectorMask); set(gca, 'YDir','normal');
                else
                    scatt.sectorMask = mask2;
                    figure(3002); imagesc(scatt.sectorMask); set(gca, 'YDir','normal');
                end
            % else
            %     scatt.sectorMask = [];
            end
            toc
            setData(app, qCalData, userData, scatt, calibrant);
        end
        
        function [qCalData, userData, scatt, calibrant] = getData(app)
            qCalData = evalin('base', 'qCalData');
            userData = qCalData.userData;
            scatt = qCalData.scatt;
            calibrant = qCalData.calibrant;
        end
        
        function qChoice = getQChoice(app, event)
            qChoice = 0;
            if app.rbPixelBasedQ.Value
                qChoice = 1;
            elseif app.rbLinearQ.Value
                qChoice = 2;
            elseif app.rbCombLinearQ.Value
                %get(handles.rbLogQ, 'Value')
                qChoice = 3;
            
            elseif app.rbLogQ.Value
                qChoice = 4;
            
            elseif app.combLogLinear.Value
                %get(handles.rbLogQ, 'Value')
                qChoice = 5;
            
            end
        end
        
        function angdeg = getSectorAngle(app, beamxy, pnt)
            % calculate sector angle for a certain point(x1,y1)
            
                angdeg = 0;
                x0 = beamxy(1); y0 = beamxy(2);
                x1 = pnt(1);    y1 = pnt(2);
                if x1 == x0
                    if y1 > y0
                        angdeg = 90;
                    else
                        angdeg = 270;
                    end
                else
                    if (x1-x0)>0 && (y1-y0)>=0
                        angdeg = atand((y1-y0)/(x1-x0));
                    elseif (x1-x0)<0 && (y1-y0)>=0
                        angdeg = atand((y1-y0)/(x1-x0)) + 180;
                    elseif (x1-x0)<0 && (y1-y0)<=0
                        angdeg = atand((y1-y0)/(x1-x0)) + 180;
                    elseif (x1-x0)>0 && (y1-y0)<=0
                        angdeg = atand((y1-y0)/(x1-x0)) + 360;
                    end
                end
        end
        
        function pbCalMaskQrange_Callback(app, event)
            % --- Executes on button press in pbCalMaskQrange.
            
            % hObject    handle to pbCalMaskQrange (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [qCalData, userData, scatt, calibrant]= getData(app);
            
            setData(app, qCalData, userData, scatt, calibrant);
        end
        
        function setData(app, qCalData, userData, scatt, calibrant)
            qCalData.userData = userData;
            qCalData.scatt = scatt;
            qCalData.calibrant = calibrant;
            assignin('base', 'qCalData', qCalData);
        end
        
        function setDetectorInitials(app, event, isPilatus2m)
            if isPilatus2m == 1
                setPilatus2mInitials(app, event);
            elseif isPilatus2m == 0
                setPilatus300Initials(app, event);
            elseif isPilatus2m == 2
                setPEInitials(app, event);
            elseif isPilatus2m == 3
                %setMar300Initials(handles);
                setPilatus900kInitials(app, event);
            elseif isPilatus2m == 4
                setEiger9mInitials(app, event);
            elseif isPilatus2m == -1
                setOtherDetectorInitials(app, event);
            end
        end
        
        function setDetectorSelection(app, event, isPilatus2m)
            if isPilatus2m == 1
                %set(handles.rbPilatus2m, 'Value', 1);
                app.rbPilatus2m.Value = 1;
            elseif isPilatus2m == 0
                app.rbPilatus300.Value = 1;
            elseif isPilatus2m == 2
                app.rbPE.Value = 1;
            elseif isPilatus2m == 3
                app.rbPilatus900k.Value = 1; 
            elseif isPilatus2m == 4
                app.rbEiger9m.Value = 1;
            elseif isPilatus2m == -1
                app.rbOther.Value = 1;
            end
        end
        
        function setEiger9mInitials(app, event)
            app.edBeamX.Value = '1440.0';
            app.edBeamY.Value = '206.0';
            app.edSDD.Value = '2000.0';
            app.edYaw.Value = '0.0';

            app.edPixelsize.Value = '0.075';
            app.edRow.Value = '3262';
            app.edCol.Value = '3108';

            app.edOffset.Value = '0';
            app.edLimitLb.Value = '-1';
            app.edLimitUb.Value = '2000000';

            % set(handles.edBeamX,   'String', 1440.0);
            % set(handles.edBeamxLb, 'String', 500.0);
            % set(handles.edBeamxUb, 'String', 2000.0);
            % 
            % set(handles.edBeamY,   'String', 206.0);
            % set(handles.edBeamyLb, 'String', 90.0);
            % set(handles.edBeamyUb, 'String', 300.0);
            % 
            % set(handles.edSDD,   'String', 2000.0);
            % set(handles.edSDDLb, 'String', 800.0);
            % set(handles.edSDDUb, 'String', 4000.0);
            % 
            % set(handles.edYaw,   'String', 0.0);
            % set(handles.edYawLb, 'String', 0.0);
            % set(handles.edYawUb, 'String', 0.0);
            % 
            % set(handles.edPixelsize, 'String', 0.075);
            % set(handles.edRow, 'String', 3262);
            % set(handles.edCol, 'String', 3108);
            % 
            % set(handles.edOffset,   'String', 0);
            % set(handles.edLimitLb,   'String', -1);
            % set(handles.edLimitUb,   'String', 2000000);
        end
        
        function setMar300Initials(app, event)
            app.edBeamX.Value = '821.0';
            app.edBeamY.Value = '1682.0';
            app.edSDD.Value = '2200.0';
            app.edYaw.Value = '0.0';

            app.edPixelsize.Value = '0.1465';
            app.edRow.Value = '2048';
            app.edCol.Value = '2048';

            app.edOffset.Value = '100';
            app.edLimitLb.Value = '-1';
            app.edLimitUb.Value = '100000';


            % set(handles.edBeamX,   'String', 821.0);
            % set(handles.edBeamxLb, 'String', 200.0);
            % set(handles.edBeamxUb, 'String', 1200.0);
            % 
            % set(handles.edBeamY,   'String', 1682.0);
            % set(handles.edBeamyLb, 'String', 900.0);
            % set(handles.edBeamyUb, 'String', 2000.0);
            % 
            % set(handles.edSDD,   'String', 2200.0);
            % set(handles.edSDDLb, 'String', 800.0);
            % set(handles.edSDDUb, 'String', 3000.0);
            % 
            % set(handles.edYaw,   'String', 0.5);
            % set(handles.edYawLb, 'String', -2.0);
            % set(handles.edYawUb, 'String', 2.0);
            % 
            % set(handles.edPixelsize, 'String', 0.1465);
            % set(handles.edRow, 'String', 2048);
            % set(handles.edCol, 'String', 2048);
            % 
            % set(handles.edOffset,   'String', 100);
            % set(handles.edLimitLb,   'String', -1);
            % set(handles.edLimitUb,   'String', 100000);
        end
        
        function setOtherDetectorInitials(app, event)
            app.edBeamX.Value = '500.0';
            app.edBeamY.Value = '500.0';
            app.edSDD.Value = '2000.0';
            app.edYaw.Value = '0.0';

            app.edPixelsize.Value = '0.100';
            app.edRow.Value = '1024';
            app.edCol.Value = '1024';

            app.edOffset.Value = '0';
            app.edLimitLb.Value = '-1';
            app.edLimitUb.Value = '1000000';


            % set(handles.edBeamX,   'String', 500.0);
            % set(handles.edBeamxLb, 'String', 100.0);
            % set(handles.edBeamxUb, 'String', 800.0);
            % 
            % set(handles.edBeamY,   'String', 500.0);
            % set(handles.edBeamyLb, 'String', 100.0);
            % set(handles.edBeamyUb, 'String', 800.0);
            % 
            % set(handles.edSDD,   'String', 2000.0);
            % set(handles.edSDDLb, 'String', 200.0);
            % set(handles.edSDDUb, 'String', 3000.0);
            % 
            % set(handles.edYaw,   'String', 0.0);
            % set(handles.edYawLb, 'String', 0.0);
            % set(handles.edYawUb, 'String', 0.0);
            % 
            % set(handles.edPixelsize, 'String', 0.100);
            % set(handles.edRow, 'String', 1024);
            % set(handles.edCol, 'String', 1024);
            % 
            % set(handles.edOffset,   'String', 0);
            % set(handles.edLimitLb,   'String', -1);
            % set(handles.edLimitUb,   'String', 1000000);
        end
        
        function setPEInitials(app, event)
            app.edBeamX.Value = '2150.0';
            app.edBeamY.Value = '130.0';
            app.edSDD.Value = '175.0';
            app.edYaw.Value = '0.0';

            app.edPixelsize.Value = '0.100';
            app.edRow.Value = '4318';
            app.edCol.Value = '4320';

            app.edOffset.Value = '100';
            app.edLimitLb.Value = '-1';
            app.edLimitUb.Value = '1000000';


            % set(handles.edBeamX,   'String', 2150.0);
            % set(handles.edBeamxLb, 'String', 2000.0);
            % set(handles.edBeamxUb, 'String', 2500.0);
            % 
            % set(handles.edBeamY,   'String', 130.0);
            % set(handles.edBeamyLb, 'String', 90.0);
            % set(handles.edBeamyUb, 'String', 300.0);
            % 
            % set(handles.edSDD,   'String', 150.0);
            % set(handles.edSDDLb, 'String', 80.0);
            % set(handles.edSDDUb, 'String', 400.0);
            % 
            % set(handles.edYaw,   'String', 0.5);
            % set(handles.edYawLb, 'String', -5.0);
            % set(handles.edYawUb, 'String', 5.0);
            % 
            % set(handles.edPixelsize, 'String', 0.100);
            % set(handles.edRow, 'String', 4318);
            % set(handles.edCol, 'String', 4320);
            % 
            % set(handles.edOffset,   'String', 100);
            % set(handles.edLimitLb,   'String', -1);
            % set(handles.edLimitUb,   'String', 100000);
        end
        
        function setPilatus2mInitials(app, event)
            app.edBeamX.Value = '750.0';
            app.edBeamY.Value = '180.0';
            app.edSDD.Value = '2000.0';
            app.edYaw.Value = '0.0';

            app.edPixelsize.Value = '0.172';
            app.edRow.Value = '1679';
            app.edCol.Value = '1475';

            app.edOffset.Value = '0';
            app.edLimitLb.Value = '-1';
            app.edLimitUb.Value = '1000000';

            % set(handles.edBeamX,   'String', 750.0);
            % set(handles.edBeamxLb, 'String', 500.0);
            % set(handles.edBeamxUb, 'String', 2000.0);
            % 
            % set(handles.edBeamY,   'String', 180.0);
            % set(handles.edBeamyLb, 'String', 90.0);
            % set(handles.edBeamyUb, 'String', 200.0);
            % 
            % set(handles.edSDD,   'String', 2000.0);
            % set(handles.edSDDLb, 'String', 800.0);
            % set(handles.edSDDUb, 'String', 4000.0);
            % 
            % set(handles.edYaw,   'String', 0.0);
            % set(handles.edYawLb, 'String', 0.0);
            % set(handles.edYawUb, 'String', 0.0);
            % 
            % set(handles.edPixelsize, 'String', 0.172);
            % set(handles.edRow, 'String', 1679);
            % set(handles.edCol, 'String', 1475);
            % 
            % set(handles.edOffset,   'String', 0);
            % set(handles.edLimitLb,   'String', -1);
            % set(handles.edLimitUb,   'String', 1000000);
        end
        
        function setPilatus300Initials(app, event)
            app.edBeamX.Value = '200.0';
            app.edBeamY.Value = '900.0';
            app.edSDD.Value = '450.0';
            app.edYaw.Value = '15.0';

            app.edPixelsize.Value = '0.172';
            app.edRow.Value = '619';
            app.edCol.Value = '487';

            app.edOffset.Value = '0';
            app.edLimitLb.Value = '-1';
            app.edLimitUb.Value = '1000000';


            % set(handles.edBeamX,   'String', 200.0);
            % set(handles.edBeamxLb, 'String', 50.0);
            % set(handles.edBeamxUb, 'String', 400.0);
            % 
            % set(handles.edBeamY,   'String', 900.0);
            % set(handles.edBeamyLb, 'String', 600.0);
            % set(handles.edBeamyUb, 'String', 2000.0);
            % 
            % set(handles.edSDD,   'String', 500.0);
            % set(handles.edSDDLb, 'String', 300.0);
            % set(handles.edSDDUb, 'String', 600.0);
            % 
            % set(handles.edYaw,   'String', 16.0);
            % set(handles.edYawLb, 'String', 14.0);
            % set(handles.edYawUb, 'String', 17.0);
            % 
            % set(handles.edPixelsize, 'String', 0.172);
            % set(handles.edRow, 'String', 619);
            % set(handles.edCol, 'String', 487);
            % 
            % set(handles.edOffset,   'String', 0);
            % set(handles.edLimitLb,   'String', -1);
            % set(handles.edLimitUb,   'String', 1000000);
        end
        
        function setPilatus900kInitials(app, event)
            app.edBeamX.Value = '821.0';
            app.edBeamY.Value = '1682.0';
            app.edSDD.Value = '2000.0';
            app.edYaw.Value = '0.0';

            app.edPixelsize.Value = '0.172';
            app.edRow.Value = '1043';
            app.edCol.Value = '981';

            app.edOffset.Value = '0';
            app.edLimitLb.Value = '-1';
            app.edLimitUb.Value = '1000000';


            % set(handles.edBeamX,   'String', 821.0);
            % set(handles.edBeamxLb, 'String', 200.0);
            % set(handles.edBeamxUb, 'String', 1200.0);
            % 
            % set(handles.edBeamY,   'String', 1682.0);
            % set(handles.edBeamyLb, 'String', 900.0);
            % set(handles.edBeamyUb, 'String', 2000.0);
            % 
            % set(handles.edSDD,   'String', 2200.0);
            % set(handles.edSDDLb, 'String', 800.0);
            % set(handles.edSDDUb, 'String', 3000.0);
            % 
            % set(handles.edYaw,   'String', 0.5);
            % set(handles.edYawLb, 'String', -2.0);
            % set(handles.edYawUb, 'String', 2.0);
            % 
            % set(handles.edPixelsize, 'String', 0.172);
            % set(handles.edRow, 'String', 1043);
            % set(handles.edCol, 'String', 981);
            % 
            % set(handles.edOffset,   'String', 0);
            % set(handles.edLimitLb,   'String', -1);
            % set(handles.edLimitUb,   'String', 100000);
        end
        
        function syncScatt(app, handles, cScatt)
            %
            [qCalData, userData, scatt, calibrant]= getData(app);
            scatt=cScatt;
            
            setDetectorInitials(app, handles, scatt.isPilatus2m);
            
            % label for Pilatus 2M, 300, or PE
            if scatt.isPilatus2m ==1        % Pilatus2m
                set(handles.rbPilatus2m, 'Value', 1);
                scatt.detector = 'Pilatus2M';
            elseif scatt.isPilatus2m ==0    % Pilatus300
                set(handles.rbPilatus300, 'Value', 1);
                scatt.detector = 'Pilatus300K';
            elseif scatt.isPilatus2m ==2    % PE
                set(handles.rbPE, 'Value', 1);
                scatt.detector = 'PEXRpad';
            elseif scatt.isPilatus2m ==3    % Pilatus900K
                set(handles.rbPilatus900k, 'Value', 1);
                %scatt.detector = 'Mar300';
                scatt.detector = 'Pilatus900K';
            elseif scatt.isPilatus2m ==4    % Eiger9M
                set(handles.rbEiger9m, 'Value', 1);
                scatt.detector = 'Eiger9M';
            elseif scatt.isPilatus2m ==-1
                set(handles.rbOther, 'Value', 1);
                set(handles.edRow, 'String', scatt.row);
                set(handles.edCol, 'String', scatt.col);
                scatt.detector = 'Undefined';
            else
                disp('should not be here, in syncScatt()!')
            end
            
            %setDetectorInitials(handles, scatt.isPilatus2m)
            
            set(handles.edEnergy, 'String', scatt.eng);
            set(handles.edPixelsize, 'String', scatt.pSize);
            set(handles.edBeamX, 'String', scatt.BeamXY(1));
            set(handles.edBeamY, 'String', scatt.BeamXY(2));
            set(handles.edSDD, 'String', scatt.SDD);
            set(handles.edYaw, 'String', scatt.yaw);
            set(handles.edOffset, 'String', scatt.offset);
            set(handles.edLimitLb, 'String', scatt.limits(1));
            set(handles.edLimitUb, 'String', scatt.limits(2));
            %if exist(handles.edAbsCoeff) & isfield(scatt, 'absIntCoeff')
            if isfield(scatt, 'absIntCoeff')
                set(handles.edAbsCoeff, 'String', scatt.absIntCoeff);
            end
            set(handles.edPtsNum, 'String', scatt.qNum);
            set(handles.edQMin, 'String', scatt.qMin);
            set(handles.edQMax, 'String', scatt.qMax);
            if isfield(scatt, 'qMode')
                switch(scatt.qMode)
                    case 'Linear'
                        set(handles.rbLinearQ, 'Value', 1);
                    case 'LogQ'
                        set(handles.rbLogQ, 'Value', 1);
                    case 'Pixel'
                        set(handles.rbPixelBasedQ, 'Value', 1);
                    case 'CombLinear'
                        set(handles.rbCombLinearQ, 'Value', 1);
                        dq1= 0;
                        q2 = 0.;
                        dq2 =0.;
                        q3 = 0.;
                        dq3 = 0.;
                        if isfield(scatt,'combQs')
                            dq1 = scatt.combQs(1);
                            q2  = scatt.combQs(2);
                            dq2 = scatt.combQs(3);
                            q3  = scatt.combQs(4);
                            dq3 = scatt.combQs(5);
                        end
                        set(handles.edDQ1, 'String', num2str(dq1));
                        set(handles.edQ2, 'String', num2str(q2));
                        set(handles.edDQ2, 'String', num2str(dq2));
                        set(handles.edQ3, 'String', num2str(q3));
                        set(handles.edDQ3, 'String', num2str(dq3));
                    case 'CombLogLinear'
                        set(handles.combLogLinear, 'Value', 1);
                        logQ2= 0;
                        logPts = 0.;
                        logDQ2 =0.;
                        logQ3 = 0.;
                        logDQ3 = 0.;
                        if isfield(scatt,'combQs')
                            logQ2 = scatt.combQs(1);
                            logPts  = scatt.combQs(2);
                            logDQ2 = scatt.combQs(3);
                            logQ3  = scatt.combQs(4);
                            logDQ3 = scatt.combQs(5);
                        end
                        set(handles.cLogQ2,  'String', num2str(logQ2));
                        set(handles.cLogPts, 'String', num2str(logPts));
                        set(handles.cLogDQ2, 'String', num2str(logDQ2));
                        set(handles.cLogQ3,  'String', num2str(logQ3));
                        set(handles.cLogDQ3, 'String', num2str(logDQ3));
                end
            end
            if isfield(scatt, 'absIntStandardIndex')
                set(handles.absIntStandard, 'Value', scatt.absIntStandardIndex);
            else
                set(handles.absIntStandard, 'Value', 1);
                scatt.absIntStandardIndex = 1;
                scatt.absIntStandard = 'None';
                userData.absIntCalibrantIndex = 1;
                userData.absIntCalibrant = 'None';
            end
            
            if isfield(scatt, 'qStandardIndex')
                set(handles.qStandard, 'Value', scatt.qStandardIndex);
            else
                set(handles.qStandard, 'Value', 1);
                scatt.qStandardIndex = 1;
                scatt.qStandard = 'None';
            end
            
            if isfield(scatt, 'BeamlineIndex')
                set(handles.Beamline, 'Value', scatt.BeamlineIndex);
            else
                set(handles.Beamline, 'Value', 1);
                scatt.BeamlineIndex = 1;
                scatt.Beamline = '12-ID-B, APS';
            end
            setData(app, qCalData, userData, scatt, calibrant);
            updateUserdata(app, handles);
        end
        
        function updateAll(app, event)
            updateUserdata(app, event);
            updateScatt(app, event);
        end
        
        function qArray = updateComLinearQ(app, event)
            % --- update combined linear q array ---
            
            [qCalData, userData, scatt, calibrant]= getData(app);
            
            qMin = userData.qMin;
            qMax = userData.qMax;
            
            % if isfield(scatt, 'qArray')
            %     scatt.qArray = [];
            % end
            
            dq1 = userData.combQs(1);
            q2 = userData.combQs(2);
            dq2 = userData.combQs(3);
            q3 = userData.combQs(4);
            dq3 = userData.combQs(5);
            qA1=[];
            qA2=[];
            qA3=[];
            qArray = [];
            if dq1>0
                if q2 < 0
                    qArray = qMin:dq1:qMax;
                    qNum = length(qArray);
                    app.edPtsNum.Value = num2str(qNum);
                elseif q2 > qMin
                    qA1=qMin:dq1:q2;
            
                    if dq2 > 0
                        if q3 < 0
                           qA2=q2+dq2:dq2:qMax;
                           qArray = [qA1 qA2];
                           qNum = length(qArray);
                           app.edPtsNum.Value = num2str(qNum);
                        elseif q3 > q2
                            qA2=q2+dq2:dq2:q3;
            
                            if dq3 > 0
                                if q3 < qMax
                                    qA3 = q3+dq3 : dq3:qMax;
                                    qArray = [qA1 qA2 qA3];
                                    qNum = length(qArray);
                                    app.edPtsNum.Value = num2str(qNum);
                                else
                                    disp('Setting of Q3 is wrong! Please check!')
                                end
                            else
                                disp('Setting of dQ3 is wrong! Please check!')
                            end
                        else
                            disp('Setting of Q3 is wrong! Please check!')
                        end
                    else
                        disp('Setting of dQ2 is wrong! Please check!')
                    end
                else
                    disp('Setting of Q2 is wrong! Please check!')
                end
            else
                disp('Setting of dQ1 is wrong! Please check!')
            end
        end
        
        function qArray = updateComLogLinearQ(app, event)
            [qCalData, userData, scatt, calibrant]= getData(app);
            
            qMin = userData.qMin;
            qMax = userData.qMax;
            
            
            cLogQ2  = str2num(app.cLogQ2.Value);
            cLogPts = str2num(app.cLogPts.Value);
            cLogDQ2 = str2num(app.cLogDQ2.Value);
            cLogQ3  = str2num(app.cLogQ3.Value);
            cLogDQ3 = str2num(app.cLogDQ3.Value);
            

            q2 = cLogQ2;
            logPts = cLogPts;
            
            dq2 = cLogDQ2;
            q3= cLogQ3;
            dq3= cLogDQ3;
            
            qA1=[];
            qA2=[];
            qA3=[];
            qArray = [];
            if q2> qMin & q2 < qMax
                if logPts >= 1
                    if qMin == 0
                        disp('qMin Cannot be Zero for Log scale! Please check!');
                        return;
                    else
                    qA1 = logspace(log10(qMin), log10(q2), round(logPts));
                    end
                else
                    disp('Setting of Log Pts is wrong! Please check!');
                    return;
                end
            
            else
                disp('Setting of Log Q2 is wrong! Please check!');
                return;
            end
            
            if dq2 > 0
                if q3 > q2
                    if q3 > qMax
                        q3 = qMax;
                        qA2 = q2+dq2:dq2:q3;
                    else
                        if dq3 > 0
                            qA2 = q2+dq2:dq2:q3;
                            qA3 = q3+dq3:dq3:qMax;
                            qA2 = [qA2 qA3];
                        else
                            disp('Setting of Linear dQ3 is wrong! Please check!');
                            return;
                        end
                    end
                else
                    disp('Setting of Linear Q3 is wrong! Please check!');
                    return;
                end
            else
                disp('Setting of Linear dQ2 is wrong! Please check!');
                return;
            end
            qArray = [qA1 qA2];
            qNum = length(qArray);
            if app.combLogLinear.Value
                app.edPtsNum.Value = num2str(qNum);
            end
        end
        
        function updateQarray(app, handles)
            [qCalData, userData, scatt, calibrant]= getData(app);
            
            qMin = userData.qMin;
            qMax = userData.qMax;
            qNum = userData.qNum;
            isLog = 0;
            
            row = scatt.row;
            col = scatt.col;
            SDDrel = scatt.SDDrel;
            waveln = scatt.wavelength;
            
            qChoice=userData.qChoice;
            
            if qChoice == 1
                qNum = pixelBaseQ(row, col, scatt.BeamXY);
                app.edPtsNum.Value = num2str(qNum);
                isLog = 0;
                qMin = 0;
            %     scatt.qArray = genQArray(qMin, qMax, qNum, isLog );
                scatt.qArray = linspace(qMin, qMax, qNum);
                scatt.qMode = 'Pixel';
            
            elseif qChoice == 2
                isLog = 0;
            %     scatt.qArray = genQArray(qMin, qMax, qNum, isLog );
                scatt.qArray = linspace(qMin, qMax, qNum);
                scatt.qMode = 'Linear';
            
            elseif qChoice == 3
                isLog = 0;
                %scatt.qArray = genQArray(qMin, qMax, qNum, isLog );
            
                %already bult in
                % function uipanel13_SelectionChangeFcn(hObject, eventdata, handles)
                scatt.qArray = updateComLinearQ(app, handles);
                scatt.qNum = length(scatt.qArray);
                app.edPtsNum.Value = num2str(qNum);
                scatt.qMode = 'CombLinear';
            
            elseif qChoice == 4
                isLog = 1;
                if qMin <= 0
                    qMin = 0.001;
                    app.edQMin.Value = num2str(qMin);
                    disp('Setting of qMin may be wrong! Please check!')
                end
                %scatt.qArray = genQArray(qMin, qMax, qNum, isLog );
            
                scatt.qArray = logspace(log10(qMin), log10(qMax), qNum);
                scatt.qMode = 'Log';
            
            elseif qChoice == 5
                isLog = 1;
                %scatt.qArray = genQArray(qMin, qMax, qNum, isLog );
            
                %already bult in
                % function uipanel13_SelectionChangeFcn(hObject, eventdata, handles)
                scatt.qArray = updateComLogLinearQ(app, handles);
                scatt.qNum = length(scatt.qArray);
                app.edPtsNum.Value = num2str(scatt.qNum);
                scatt.qMode = 'CombLogLinear';
            
            else
            
                disp('Wrong! should not be here ....')
            end
            setData(app, qCalData, userData, scatt, calibrant);
        end
        
        function updateScatt(app, event)
            %qCalData=evalin('base','qCalData');
            %userData=qCalData.userData;
            %scatt = qCalData.scatt;
            [qCalData, userData, scatt, calibrant]= getData(app);
            
            scatt.limits=userData.limits;
            scatt.isPilatus2m = userData.isPilatus2m;
            scatt.detector =userData.detector;
            scatt.row = userData.detRow;
            scatt.col = userData.detCol;
            scatt.offset = userData.offset;
            scatt.absIntCoeff = userData.absIntCoeff;
            scatt.eng = userData.xeng;
            scatt.wavelength = userData.wavelength;
            scatt.pSize = userData.pSize;
            scatt.qNum = userData.qNum;
            scatt.qMin = userData.qMin;
            scatt.qMax = userData.qMax;
            scatt.combQs = userData.combQs;
            scatt.qChoice = userData.qChoice;
            scatt.SDD = userData.SDD;
            scatt.SDDrel = userData.SDD / userData.pSize;
            scatt.BeamXY = [userData.beamx userData.beamy];
            scatt.yaw = userData.yaw;
            
            scatt.absIntStandardIndex = userData.absIntStandardIndex;
            scatt.absIntStandard = userData.absIntStandard;
            scatt.qStandardIndex = userData.qStandardIndex;
            scatt.qStandard = userData.qStandard;
            scatt.BeamlineIndex = userData.BeamlineIndex;
            scatt.Beamline = userData.Beamline;
            
            scatt.sectorA1 = userData.sectorA1;
            scatt.sectorA2 = userData.sectorA2;
            scatt.sectorMaskOn = userData.sectorMaskOn;
            scatt.sectorMaskExclude = userData.sectorMaskExclude;
            scatt.sectorMask = [];
            
            scatt.arbitraryMaskOn = userData.arbitraryMaskOn;
            scatt.arbitraryMask = [];
            scatt.arbitraryMaskExclude = userData.arbitraryMaskExclude;
            
            %scatt.calibrantImageFile = 'c:\';
            
            setData(app, qCalData, userData, scatt, calibrant);
        end
        
        function updateUserdata(app, event)
            %update parameter inputs
            qCalData=evalin('base','qCalData');
            userData=qCalData.userData;
            
            userData.xeng = str2double(app.edEnergy.Value);
            userData.wavelength = 12.398418746/userData.xeng;
            
            userData.pSize = str2double(app.edPixelsize.Value);
            
            userData.beamx  = str2double(app.edBeamX.Value);
            userData.beamy  = str2double(app.edBeamY.Value);
            userData.SDD    = str2double(app.edSDD.Value);
            userData.yaw = str2double(app.edYaw.Value);
            
            % userData.SDDLb = str2double(app.edSDDLb.Value);
            % userData.beamxLb = str2double(app.edBeamXLb.Value);
            % userData.beamyLb = str2double(app.edBeamYLb.Value);
            % userData.yawLb = str2double(app.edYawLb.Value);
            % 
            % userData.beamxUb = str2double(app.edBeamXUb.Value);
            % userData.beamyUb = str2double(app.edBeamYUb.Value);
            % userData.SDDUb = str2double(app.edSDDUb.Value);
            % userData.yawUb = str2double(app.edYawUb.Value);
            
            userData.offset = str2double(app.edOffset.Value);
            limitUb = str2double(app.edLimitUb.Value);
            limitLb = str2double(app.edLimitLb.Value);
            userData.limits=[limitLb limitUb];
            userData.absIntCoeff = str2double(app.edAbsCoeff.Value);
            
            if app.rbPilatus2m.Value
                userData.detector = 'Pilatus2M';
                userData.isPilatus2m = 1;
                userData.detRow=1679;
                userData.detCol=1475;
                userData.pSize = 0.172;
            elseif app.rbPilatus300.Value
                userData.detector = 'Pilatus300K';
                userData.isPilatus2m = 0;
                userData.detRow=619;
                userData.detCol=487;
                userData.pSize = 0.172;
            elseif app.rbPE.Value
                userData.detector = 'PEXRpad';
                userData.isPilatus2m = 2;
                userData.detRow=4318;
                userData.detCol=4320;
                userData.pSize = 0.100;
            elseif app.rbPilatus900k.Value
                %userData.detector = 'Mar300';
                %userData.detRow=2048;
                %userData.detCol=2048;
                %userData.pSize = 0.1465;
                userData.detector = 'Pilatus900K';
                userData.isPilatus2m = 3;
                userData.detCol=981;
                userData.detRow=1043;
                userData.pSize = 0.172;
            elseif app.rbEiger9m.Value
                userData.detector = 'Eiger9M';
                userData.isPilatus2m = 4;
                userData.detRow=3262;
                userData.detCol=3108;
                userData.pSize = 0.075;
            elseif app.rbOther.Value
                userData.detector = 'Undefined';
                userData.isPilatus2m = -1;
                userData.detRow = str2num(app.edRow.Value);
                userData.detCol = str2num(app.edCol.Value);
            end
            
            app.edPixelsize.Value = num2str(userData.pSize );
            app.edRow.Value = num2str(userData.detRow);
            app.edCol.Value = num2str(userData.detCol);
            
            % userData.isPilatus2m =  get(handles.rbPilatus2m,'Value');
            % if userData.isPilatus2m > 0
            %     userData.detRow=1679;
            %     userData.detCol=1475;
            % else
            %     userData.detRow=619;
            %     userData.detCol=487;
            % end
            
            userData.qNum = str2double(app.edPtsNum.Value);
            userData.qMin = str2double(app.edQMin.Value);
            userData.qMax = str2double(app.edQMax.Value);
            userData.qMax = str2double(app.edQMax.Value);
            
            if app.combLogLinear.Value
                userData.dq1 = str2double(app.cLogQ2.Value);
                userData.q2  = str2double(app.cLogPts.Value);
                userData.dq2 = str2double(app.cLogDQ2.Value);
                userData.q3  = str2double(app.cLogQ3.Value);
                userData.dq3 = str2double(app.cLogDQ3.Value);
            else
                userData.dq1 = str2double(app.edDQ1.Value);
                userData.q2  = str2double(app.edQ2.Value);
                userData.dq2 = str2double(app.edDQ2.Value);
                userData.q3  = str2double(app.edQ3.Value);
                userData.dq3 = str2double(app.edDQ3.Value);
            end
            userData.combQs=[userData.dq1 userData.q2 userData.dq2 userData.q3 userData.dq3];  % combined for linear & Log-linear
            userData.qChoice = getQChoice(app, event);
            
            % index_selected = get(hObject,'Value');
            % list = get(hObject,'String');
            % item_selected = list{index_selected};
            %
            % scatt.absIntStandardIndex =  index_selected;
            % scatt.absIntStandard = item_selected;
            
            userData.BeamlineIndex = app.Beamline.ValueIndex;
            % list = app.Beamline.Value;
            % userData.Beamline = list{userData.BeamlineIndex};
            userData.Beamline = app.Beamline.Value;
            
            userData.absIntStandardIndex = app.absIntStandard.ValueIndex;
            userData.absIntStandard = app.absIntStandard.Value;
            
            userData.qStandardIndex = app.qStandard.ValueIndex;
            userData.qStandard = app.qStandard.Value;
            
            %average folder
            % set(handles.rdAve, 'Value', 1);
            % userData.avgFolderChoice = 1;
            % userData.avgFolder='Ave';
            app.rdAveraged.Value = 1;
            userData.avgFolderChoice = 2;
            userData.avgFolder='Averaged';
            
            userData.sectorA1 = str2num(app.sectorA1.Value);
            userData.sectorA2 = str2num(app.sectorA2.Value);
            userData.sectorMaskOn = app.useSectorMask.Value;
            userData.sectorMaskExclude = app.excludeSectorMask.Value;
            
            userData.arbitraryMaskOn = app.arbitraryMaskOn.Value ;
            userData.arbitraryMaskExclude = app.arbitraryMaskExclude.Value;
            
            
            qCalData.userData = userData;
            assignin('base','qCalData', qCalData);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function qCalibration2_OpeningFcn(app, varargin)
            % --- Executes just before qCalibration2 is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to qCalibration2 (see VARARGIN)
            
            % Choose default command line output for qCalibration2
            handles.output = hObject;
            
            
            setenv('HDF5_PLUGIN_PATH', 'C:\Users\zuox\Box\work\matlab_codes\matSAXS\SAXSimagviewer\plugin');
            setenv('HDF5_DIR', 'C:\Users\zuox\Box\work\matlab_codes\matSAXS\SAXSimagviewer\plugin');
            setenv('LD_LIBRARY_PATH', 'C:\Users\zuox\Box\work\matlab_codes\matSAXS\SAXSimagviewer\plugin');
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: AbsIntlistbox
        function AbsIntlistbox_Callback(app, event)
            % --- Executes on selection change in AbsIntlistbox.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to AbsIntlistbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns AbsIntlistbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from AbsIntlistbox
            [qCalData, userData, scatt, calibrant]= getData(app);
            
            index_selected = get(hObject,'Value');
            list = get(hObject,'String');
            item_selected = list{index_selected};
            
            userData.absIntCalibrantIndex =  index_selected;
            userData.absIntCalibrant = item_selected;
            
            switch lower(userData.absIntCalibrant)
                case 'water'
                    set(handles.ed_absIntQmin, 'String', 0.01);
                    set(handles.ed_absIntQmax, 'String', 0.8);
                case 'glassy carbon'
                    set(handles.ed_absIntQmin, 'String', 0.02);
                    set(handles.ed_absIntQmax, 'String', 0.25);
            end
            
            setData(app, qCalData, userData, scatt, calibrant);
        end

        % Value changed function: Beamline
        function Beamline_Callback(app, event)
            % --- Executes on selection change in Beamline.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Beamline (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns Beamline contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from Beamline
            [qCalData, userData, scatt, calibrant]= getData(app);
            
            index_selected = get(hObject,'Value');
            list = get(hObject,'String');
            item_selected = list{index_selected};
            
            scatt.BeamlineIndex =  index_selected;
            scatt.Beamline = item_selected;
            
            setData(app, qCalData, userData, scatt, calibrant);
        end

        % Button pushed function: ConvH5toTiff
        function ConvH5toTiff_Callback(app, event)
            % --- Executes on button press in ConvH5toTiff.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ConvH5toTiff (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            [filenames, pathname, filterindex] = uigetfile( ...
            {  '*.h5','SAXS Image File (*.dat)'; ...
               '*.*',  'All Files (*.*)'}, ...
               'Pick a file', ...
               'MultiSelect', 'on');
            % filenames=0 if cancelled
            if isnumeric(filenames)
                fprintf('No files selected!\n');
                return;
            end
            if ischar(filenames)   % only one file was selected.
                filenames={filenames};
            end
            
            
            for nfile = filenames
                tic
                %h5data=[];
                file = nfile{1,1};
            %     [~, fn9, ext9] = fileparts(file);
            %     %outputFileName=[file(1:end-3) 'dat'];
            %     outputFileName=[fn9 '.dat'];
            
                cfile = fullfile(pathname, file);
                disp(cfile);
                h5conv2tiff2(cfile)
                toc
            end
        end

        % Button pushed function: CopyFromDetQCalib
        function CopyFromDetQCalib_Callback(app, event)
            % --- Executes on button press in CopyFromDetQCalib.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to CopyFromDetQCalib (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [qCalData, userData, scatt, calibrant]= getData(app);
            
            %set(handles.edEnergy, 'String', scatt.eng);
            app.edEnergy.Value = num2str(scatt.eng);
            setDetectorSelection(app, event, scatt.isPilatus2m);
            setDetectorInitials(app, event, scatt.isPilatus2m);
            
            app.edBeamX.Value = num2str(scatt.BeamXY(1));
            app.edBeamY.Value = num2str(scatt.BeamXY(2));
            app.edSDD.Value = num2str(scatt.SDD);
            app.edYaw.Value = num2str(scatt.yaw);
            app.CalibrantdataLabel.Text = fullfile(scatt.calibrantImagePath,scatt.calibrantImageFile) ;
            updateAll(app, event)
        end

        % Button pushed function: absIntSelectData
        function absIntSelectData_Callback(app, event)
            % --- Executes on button press in absIntSelectData.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to absIntSelectData (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [qCalData, userData, scatt, calibrant]= getData(app);
            [filename, pathname] = uigetfile( ...
                   {'*.dat;*.avg;*.txt', 'All Data Files (*.dat)'}, ...
                    'Open as');
            calibIntDataFile = [pathname, filename];
            if calibIntDataFile==0 return; end
            Io_water= 0.01632;
            
            qMin = str2num(get(handles.ed_absIntQmin, 'String'));
            qMax = str2num(get(handles.ed_absIntQmax, 'String'));
            
            calibData = readData(calibIntDataFile);
            if ~isfield(userData,'absIntCalibrantIndex')
                fprintf('Select Calibrant First!\n');
                return;
            end
            
            if userData.absIntCalibrantIndex == 1 % None
                userData.absIntCoeff = 1.0;
                set(handles.absIntCoefftext, 'String', sprintf('Coeff.: %.5f', userData.absIntCoeff));
            elseif userData.absIntCalibrantIndex == 2 % water
                %qMin = get(handles.ed_absIntQmin, 'String')
                [qIndexMin, qIndexMax] = findQrange(calibData(:,1), qMin, qMax);
                x=calibData(qIndexMin:qIndexMax,1);
                y=calibData(qIndexMin:qIndexMax,2);
                p=polyfit(x,y,3);
                fprintf('P3-P0: [%s]; P0=%f.', num2str(p), p(end));
                
                yfit=polyval(p,x);
                figure(801); semilogx(calibData(:,1),calibData(:,2),'-r', x,yfit,'-b');
                title("Water as Absolute Intensity Calibrant"); xlabel('q, 1/A'); ylabel('scattering intensity');
                userData.absIntCoeff = Io_water/p(end);
            elseif userData.absIntCalibrantIndex == 3 % Glassy Carbon
                gcData= readData(fullfile('GlassyCarbon','GlassyCarbon_Laverage.dat'));
                [qIndexMin, qIndexMax] = findQrange(gcData(:,1), qMin, qMax);
                qgcData=gcData(qIndexMin:qIndexMax,1);
                caliDataInterp = interp1(calibData(:,1), calibData(:,2), qgcData);
                userData.absIntCoeff = mean(gcData(qIndexMin:qIndexMax,2)./caliDataInterp);
                figure(802); loglog(calibData(:,1),calibData(:,2), '-m', calibData(:,1),calibData(:,2)*userData.absIntCoeff,'-r', gcData(:,1),gcData(:,2),'-b');
                title("Glassy Carbon as Absolute Intensity Calibrant"); xlabel('q, 1/A'); ylabel('scattering intensity');
            end
            set(handles.absIntStandard, 'Value', userData.absIntCalibrantIndex);
            set(handles.edAbsCoeff, 'String', userData.absIntCoeff);
            scatt.absIntCoeff = userData.absIntCoeff;
            list = get(handles.absIntStandard,'String');
            %item_selected = list{index_selected};
            scatt.absIntStandard = list{userData.absIntCalibrantIndex};
            setData(app, qCalData, userData, scatt, calibrant);
        end

        % Value changed function: absIntStandard
        function absIntStandard_Callback(app, event)
            % --- Executes on selection change in absIntStandard.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to absIntStandard (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns absIntStandard contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from absIntStandard
            [qCalData, userData, scatt, calibrant]= getData(app);
            
            index_selected = get(hObject,'Value');
            list = get(hObject,'String');
            item_selected = list{index_selected};
            
            scatt.absIntStandardIndex =  index_selected;
            scatt.absIntStandard = item_selected;
            
            setData(app, qCalData, userData, scatt, calibrant);
        end

        % Selection changed function: bgDetector
        function bgDetector_SelectionChangeFcn(app, event)
            % --- Executes when selected object is changed in bgDetector.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to the selected object in bgDetector
            % eventdata  structure with the following fields (see UIBUTTONGROUP)
            %	EventName: string 'SelectionChanged' (read only)
            %	OldValue: handle of the previously selected object or empty if none was selected
            %	NewValue: handle of the currently selected object
            % handles    structure with handles and user data (see GUIDATA)
            
            switch get(eventdata.NewValue, 'Tag')
            
                case 'rbPilatus2m'
                    setPilatus2mInitials(app, handles);
                    updateAll(app, handles);
                    %scatt=struct( 'row',1679, 'col', 1475);
            
                case 'rbPilatus300'
                    setPilatus300Initials(app, handles);
                    updateAll(app, handles);
                    %scatt=struct( 'row',619, 'col', 487);
            
                case 'rbEiger9m'
                    setEiger9mInitials(app, handles);
                    updateAll(app, handles);
                    %scatt=struct( 'row',619, 'col', 487);
            
                case 'rbPE'
                    setPEInitials(app, handles);
                    updateAll(app, handles);
                    %scatt=struct( 'row',4318, 'col', 4320);
            
                case 'rbPilatus900k'
                    %setMar300Initials(handles);
                    setPilatus900kInitials(app, handles);
                    updateAll(app, handles);
                    %scatt=struct( 'row',2048, 'col', 2048);
            
                case 'rbOther'
                    setOtherDetectorInitials(app, handles);
                    updateAll(app, handles);
                    %scatt=struct( 'row',619, 'col', 487);
            
                otherwise
                    disp('Should not be here Detector Choice!');
            end
        end

        % Button pushed function: combineMask
        function combineMask_Callback(app, event)
            % --- Executes on button press in combineMask.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to combineMask (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %
            % Note: Mouse: Read mouse clicks: on /off to clear a previous trial
            % (1) Mouse: Read Mouse click ON
            % (2) press down "Mouse cursor"
            % (3) use mouse cursor clicks to make a ROI
            % (4) right click mouse then "Toggle off" to finish select
            % (5) mouse clicks in "mousepnt.x & .y"
            
            [qCalData, userData, scatt, calibrant]= getData(app);
            
            % try
            %     mask2 = evalin('base', 'tmpmask');
            %     figure; imagesc(mask2); set(gca, 'YData', 'normal');
            %     if ~isfield(scatt, 'mask') || isempty(scatt.mask)
            %         disp('Initial Mask is not ready! ...')
            %         disp('Selected area will be calculated w/o initial mask! ...')
            %         scatt.mask = mask2;
            %     else
            %         scatt.mask = scatt.mask & mask2;
            %     end
            %
            % catch
            %     disp('Select interested area in SAXSimageviewer window ....');
            % end
            try
                figH = evalin('base', 'SAXSimageviewerhandle');
            catch
                fprintf('Start SAXSimageView first...\n');
                return;
            end
            h2 = guidata(figH);
            
            try
                pnt = evalin('base', 'mousepnt');
                x=[pnt.x pnt.x(1)]; y=[pnt.y pnt.y(1)];
                cmd1=sprintf('line(x, y,  ''parent'', h2.ImageAxes, ''Color'', ''m'', ''LineStyle'', ''-'', ''linewidth'', 1);');
                eval(cmd1);
            catch
                disp('pick the mask / region of interest first');
                return;
            end
            
            imgx = [];
            imgy = [];
            col = scatt.col; row = scatt.row;
            tic
            for kk=1:col
                imgx = [imgx ones(1,row)*kk];
                imgy = [imgy 1:row];
            end
            
            toc
            
            in = inpolygon(imgx,imgy,x,y);
            scatt.arbitraryMask = reshape(in,[row, col]);
            if scatt.arbitraryMaskExclude
                scatt.arbitraryMask = 1 - scatt.arbitraryMask;
            end
            figure(3004); imagesc(scatt.arbitraryMask); set(gca, 'YDir', 'normal');
            setData(app, qCalData, userData, scatt, calibrant);
        end

        % Callback function
        function copyFitting_Callback(app, event)
            % --- Executes on button press in copyFitting.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to copyFitting (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [qCalData, userData, scatt, calibrant]= getData(app);
            dist = 0;
            
            fitRst=getgihandle;
            set(handles.edEnergy, 'String', fitRst.xeng);
            
            userData.xeng = str2double(get(handles.edEnergy, 'string'));
            userData.wavelength = 12.398418746/userData.xeng;
            
            scatt.wavelength = userData.wavelength;
            scatt.xeng = userData.xeng;
            
            
            qAgbe = str2num(get(handles.qAgbeh, 'String'));
            theta = 2.*asin(qAgbe * scatt.wavelength / (4.*pi));
            
            if isempty(fitRst) || ~isfield(fitRst,'center')
                disp('Not Ready! Do the fitting first ....');
            
            else
                scatt.BeamXY = fitRst.center;
                dist = fitRst.px * scatt.pSize;
                scatt.SDD = dist / tan(theta);
            
                set(handles.edBeamX, 'String', scatt.BeamXY(1));
                set(handles.edBeamY, 'String', scatt.BeamXY(2));
                set(handles.edSDD, 'String', scatt.SDD);
            
                setData(app, qCalData, userData, scatt, calibrant);
            end
        end

        % Value changed function: edAbsCoeff
        function edAbsCoeff_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edAbsCoeff (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edAbsCoeff as text
            %        str2double(get(hObject,'String')) returns contents of edAbsCoeff as a double
            updateAll(app, handles);
        end

        % Value changed function: edBeamX
        function edBeamX_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edBeamX (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edBeamX as text
            %        str2double(get(hObject,'String')) returns contents of edBeamX as a double
            %beamx = str2double(get(handles.edBeamX, 'string'));
            updateAll(app, handles);
        end

        % Value changed function: edBeamY
        function edBeamY_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edBeamY (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edBeamY as text
            %        str2double(get(hObject,'String')) returns contents of edBeamY as a double
            %beamy  =str2double(get(handles.edBeamY, 'string'));
            updateAll(app, handles);
        end

        % Callback function
        function edBeamXLb_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edBeamxLb (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edBeamxLb as text
            %        str2double(get(hObject,'String')) returns contents of edBeamxLb as a double
            updateAll(app, handles);
        end

        % Callback function
        function edBeamXUb_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edBeamxUb (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edBeamxUb as text
            %        str2double(get(hObject,'String')) returns contents of edBeamxUb as a double
            updateAll(app, handles);
        end

        % Callback function
        function edBeamYLb_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edBeamyLb (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edBeamyLb as text
            %        str2double(get(hObject,'String')) returns contents of edBeamyLb as a double
            updateAll(app, handles);
        end

        % Callback function
        function edBeamYUb_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edBeamyUb (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edBeamyUb as text
            %        str2double(get(hObject,'String')) returns contents of edBeamyUb as a double
            updateAll(app, handles);
        end

        % Value changed function: edCol
        function edCol_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edCol (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edCol as text
            %        str2double(get(hObject,'String')) returns contents of edCol as a double
             [qCalData, userData, scatt, calibrant]= getData(app);
             scatt.col = floor(str2num(get(handles.edCol, 'String')));
             setData(app, qCalData, userData, scatt, calibrant);
        end

        % Value changed function: edEnergy
        function edEnergy_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edEnergy (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edEnergy as text
            %        str2double(get(hObject,'String')) returns contents of edEnergy as a double
            updateAll(app, handles);
            qCalData=evalin('base', 'qCalData');
            qCalData.calibrant.wavelength = qCalData.userData.wavelength;
            assignin('base','qCalData', qCalData);
        end

        % Value changed function: edLimitLb
        function edLimitLb_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edLimitLb (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edLimitLb as text
            %        str2double(get(hObject,'String')) returns contents of edLimitLb as a double
            updateAll(app, handles);
        end

        % Value changed function: edLimitUb
        function edLimitUb_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edLimitUb (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edLimitUb as text
            %        str2double(get(hObject,'String')) returns contents of edLimitUb as a double
            updateAll(app, handles);
        end

        % Value changed function: edOffset
        function edOffset_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edOffset (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edOffset as text
            %        str2double(get(hObject,'String')) returns contents of edOffset as a double
            %handles.offset = str2double(get(handles.edOffset,'String'));
            updateAll(app, handles);
        end

        % Value changed function: edPixelsize
        function edPixelsize_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edPixelsize (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edPixelsize as text
            %        str2double(get(hObject,'String')) returns contents of edPixelsize as a double
            updateAll(app, handles);
        end

        % Value changed function: edPtsNum
        function edPtsNum_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edPtsNum (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edPtsNum as text
            %        str2double(get(hObject,'String')) returns contents of edPtsNum as a double
            updateAll(app, handles);
        end

        % Value changed function: edQMax
        function edQMax_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edQMax (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edQMax as text
            %        str2double(get(hObject,'String')) returns contents of edQMax as a double
            updateAll(app, handles);
        end

        % Value changed function: edQMin
        function edQMin_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edQMin (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edQMin as text
            %        str2double(get(hObject,'String')) returns contents of edQMin as a double
            updateAll(app, handles);
        end

        % Value changed function: edRow
        function edRow_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edRow (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edRow as text
            %        str2double(get(hObject,'String')) returns contents of edRow as a double
             [qCalData, userData, scatt, calibrant]= getData(app);
             scatt.row = floor(str2num(get(handles.edRow, 'String')));
             setData(app, qCalData, userData, scatt, calibrant);
        end

        % Callback function
        function edSDDLb_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edSDDLb (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edSDDLb as text
            %        str2double(get(hObject,'String')) returns contents of edSDDLb as a double
            updateAll(app, handles);
        end

        % Callback function
        function edSDDUb_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edSDDUb (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edSDDUb as text
            %        str2double(get(hObject,'String')) returns contents of edSDDUb as a double
            updateAll(app, handles);
        end

        % Value changed function: edSDD
        function edSDD_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edSDD (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edSDD as text
            %        str2double(get(hObject,'String')) returns contents of edSDD as a double
            %SDD = str2double(get(handles.edSSD, 'string'));
            updateAll(app, handles);
        end

        % Callback function
        function edYawLb_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edYawLb (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edYawLb as text
            %        str2double(get(hObject,'String')) returns contents of edYawLb as a double
            updateAll(app, handles);
        end

        % Callback function
        function edYawUb_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edYawUb (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edYawUb as text
            %        str2double(get(hObject,'String')) returns contents of edYawUb as a double
            updateAll(app, handles);
        end

        % Value changed function: edYaw
        function edYaw_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edYaw (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edYaw as text
            %        str2double(get(hObject,'String')) returns contents of edYaw as a double
            updateAll(app, event);
        end

        % Button pushed function: makeMask
        function makeMask_Callback(app, event)
            % --- Executes on button press in makeMask.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to makeMask (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %maskmaker
            maskmaker_App;
        end

        % Button pushed function: pbCalExistQIndxMap
        function pbCalExistQIndxMap_Callback(app, event)
            % --- Executes on button press in pbCalExistQIndxMap.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbCalExistQIndxMap (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % updateQarray(handles)
             [qCalData, userData, scatt, calibrant]= getData(app);
            
            if isfield(scatt,'qMap') & isfield(scatt, 'qArray') & ~isempty(scatt.qMap) & ~isempty(scatt.qArray)
                scatt.qRMap = calQRMap(scatt.qMap, scatt.qArray);
                disp('Q index map done!')
            else
                disp('Not Ready! Calculate Q and Corr Maps first ....')
            end
            setData(app, qCalData, userData, scatt, calibrant);
        end

        % Button pushed function: pbCalQCorrMaps
        function pbCalQCorrMaps_Callback(app, event)
            % --- Executes on button press in pbCalQCorrMaps.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbCalQCorrMaps (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            updateAll(app, handles);
            
            [qCalData, userData, scatt, calibrant]= getData(app);
            % if ~isfield(scatt, 'wavelength')
            %     scatt.wavelength = 12.398418746/scatt.xeng
            % end
            %change 8/7/2014
            %[scatt.qMap, scatt.qCMap, qMax, qMin]=calQCorrMap2(scatt.BeamXY, scatt.SDDrel, [scatt.yaw 0 0], scatt.wavelength, [scatt.row scatt.col]);
            [scatt.qMap, scatt.qCMap, qMax, qMin]=calQCorrMap2(scatt.BeamXY-1, scatt.SDDrel, [scatt.yaw 0 0], scatt.wavelength, [scatt.row scatt.col]);
            
            setData(app, qCalData, userData, scatt, calibrant);
            
            set(handles.edQMin, 'String', num2str(qMin, '%.5f'));
            set(handles.edQMax, 'String', num2str(qMax, '%.5f'));
            updateAll(app, handles);
        end

        % Button pushed function: pbCalQIndxMap
        function pbCalQIndxMap_Callback(app, event)
            % --- Executes on button press in pbCalQIndxMap.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbCalQIndxMap (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            updateAll(app, handles);
            updateQarray(app, handles);
            [qCalData, userData, scatt, calibrant]= getData(app);
            
            if isfield(scatt,'qMap') & isfield(scatt, 'qArray') & ~isempty(scatt.qMap) & ~isempty(scatt.qArray)
                disp('Calculating Q index map ...')
                scatt.qRMap = calQRMap(scatt.qMap, scatt.qArray);
                disp('Q index map done!')
            else
                disp('Not Ready! Calculate Q and Corr Maps first ....')
            end
            setData(app, qCalData, userData, scatt, calibrant);
        end

        % Button pushed function: pbDetQCalib
        function pbDetQCalib_Callback(app, event)
            % --- Executes on button press in pbDetQCalib.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbDetQCalib (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            detQCalib;
        end

        % Callback function
        function pbFit_Callback(app, event)
            % --- Executes on button press in pbFit.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbFit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            qCalData=evalin('base','qCalData');
            userData = qCalData.userData;
            calibrant = qCalData.calibrant;
            scatt = qCalData.scatt;
            
            userData.xeng = str2double(get(handles.edEnergy, 'string'));
            userData.wavelength = 12.398418746/userData.xeng;
            calibrant.wavelength = userData.wavelength;
            
            x0 = [userData.beamx userData.beamy userData.SDD/userData.pSize userData.yaw];
            lb = [userData.beamxLb userData.beamyLb userData.SDDLb/userData.pSize userData.yawLb];
            ub = [userData.beamxUb userData.beamyUb userData.SDDUb/userData.pSize userData.yawUb];
            
            if ~isempty(calibrant.data)
                [xval, fval] = fminsearchcon(@(x) tiltcircle2(x,calibrant), x0, lb, ub);
                plotTiltCircle3(xval,calibrant);
                fprintf("Current penalty: %.5e\n", fval);
            
                beamx = xval(1);
                beamy = xval(2);
                sdd   = xval(3) * userData.pSize;
                yaw   = xval(4);
            
                set(handles.edBeamX, 'String', beamx);
                set(handles.edBeamY, 'String', beamy);
                set(handles.edSDD, 'String', sdd);
                set(handles.edYaw, 'String', yaw);
            
                userData.beamx = beamx;
                userData.beamy = beamy;
                userData.SDD = sdd;
                userData.yaw = yaw;
            
                scatt = [];
                scatt.eng = userData.xeng;
                scatt.wavelength = userData.wavelength;
                scatt.BeamXY = [beamx beamy];
                scatt.yaw = yaw;
                scatt.SDD = sdd;
                scatt.SDDrel = xval(3);
                scatt.limits = userData.limits;
                scatt.offset = userData.offset;
                qCalData.scatt = scatt;
                qCalData.userData = userData;
                assignin('base','qCalData', qCalData);
            else
                disp('Please pick the pionts first ....')
            end
        end

        % Callback function
        function pbGetptsQ_Callback(app, event)
            % --- Executes on button press in pbGetptsQ.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbGetptsQ (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            qCalData=evalin('base','qCalData');
            calibrant = qCalData.calibrant;
            try
                mousePts = evalin('base', 'mousepnt');
                %cImgName = evalin('base', 'cImgName');
                %cImg = imgUpsideDn(double(imread(cImgName)));
                cImgName2 = getgihandle;
                cImg = cImgName2.image;
                [row, col] = size(cImg);
                bnds =[col row];
                halfW = 30;
                pks=[];
                rsqVal = 0.95;
                %for kk = 1:length(mousePts(:,1))
                for kk = 1:length(mousePts.x)
                    x=round(mousePts.x(kk));
                    y=round(mousePts.y(kk));
                    %x=round(mousePts(kk,1));
                    %y=round(mousePts(kk,2));
                    cpt = [x y];
                    pkV = findVertPeak(cpt, cImg, halfW, bnds, rsqVal);
                    pkH = findHorzPeak(cpt, cImg, halfW, bnds, rsqVal);
                    newPKs = [pkV pkH];
                    if isempty(newPKs)
                        newPKs = [x;y];
                    end
                    pks = [pks newPKs];
                end
            
                cdat.xy = sortrows(pks')';
            
                %cdat.xy = [mousePts.x; mousePts.y];
            
                cq = input('Please enter the q-value for the selected ring:');
                cdat.q=cq;
            
                calibrant.data = [calibrant.data cdat]
                qCalData.calibrant = calibrant;
                assignin('base','qCalData', qCalData);
            catch
                disp('Pick calibrant pixels in SAXSimageviewer window ....');
                calibrant.data = [];
                calibrant.wavelength = 0.;
                qCalData.calibrant.wavelength = qCalData.userData.wavelength;
                assignin('base','qCalData', qCalData);
            end
        end

        % Callback function
        function pbInitilize_Callback(app, event)
            % --- Executes on button press in pbInitilize.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbInitilize (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            qCalData = evalin('base', 'qCalData');
            calibrant = qCalData.calibrant;
            calibrant.data=[];
            calibrant.wavelength=qCalData.userData.wavelength;
            qCalData.calibrant = calibrant;
            assignin('base','qCalData', qCalData);
        end

        % Button pushed function: pbLoadMaskFile
        function pbLoadMaskFile_Callback(app, event)
            % --- Executes on button press in pbLoadMaskFile.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbLoadMaskFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [qCalData, userData, scatt, calibrant]= getData(app);
            
            [file, datadir]=uigetfile({'*.*','All format(*.*)'});
            if file==0 return; end
            file = [datadir, file];
            mask = double(imread(file));
            %mask = imgUpsideDn(double(imread(file)));
            if scatt.isPilatus2m== -1
                scatt.row = floor(str2num(get(handles.edRow, 'String')));
                scatt.col = floor(str2num(get(handles.edCol, 'String')));
            end
            if size(mask)==[scatt.row scatt.col]
                scatt.mask = mask;
                assignin('base','qCalData', qCalData);
                disp('Mask is loaded!')
            else
                disp('Choose wrong mask file, dimension does not match ....')
            end
            setData(app, qCalData, userData, scatt, calibrant);
        end

        % Button pushed function: pbLoadSetup
        function pbLoadSetup_Callback(app, event)
            % --- Executes on button press in pbLoadSetup.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbLoadSetup (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % maybe need more work on combined linear array!
            
            [filename, pathname] = uigetfile( ...
                   {'*.m;*.fig;*.mat;*.mdl', 'All Setup Files (*.mat)'}, ...
                    'Open as');
            setupFile = [pathname, filename];
            if setupFile==0 return;end
            
            vars = whos('-file',setupFile);
            tmp=load(setupFile);
            
            if ismember('saxs',{vars.name})
                cScatt = tmp.saxs;
            elseif ismember('waxs',{vars.name})
                cScatt = tmp.waxs;
            elseif ismember('laxs',{vars.name})
                cScatt = tmp.laxs;
            else
                disp('Should not be here, in pbLoadSetup_Callback()!');
            end
            
            syncScatt(app, handles, cScatt);
        end

        % Callback function
        function pbPilatus300Calib_Callback(app, event)
            % --- Executes on button press in pbPilatus300Calib.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbPilatus300Calib (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            agbh_findpeaks_12IDB
        end

        % Button pushed function: pbSaveSetup
        function pbSaveSetup_Callback(app, event)
            % --- Executes on button press in pbSaveSetup.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbSaveSetup (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            qCalData = evalin('base','qCalData');
            
            if qCalData.scatt.isPilatus2m == 1   % saxs, Pilatus2m
                saxs = qCalData.scatt;
                [filename, pathname] = uiputfile('saxssetup.mat', 'Save file name(*.mat)');
                if filename==0
                    return;
                else
                    %save([pathname, filename], 'saxs');
                    save([pathname, filename], 'saxs', '-v7.3', '-nocompression');
                    disp('setup file is saved!')
                end
            elseif qCalData.scatt.isPilatus2m == 4   % saxs, Eiger 9M
                saxs = qCalData.scatt;
                [filename, pathname] = uiputfile('saxssetup.mat', 'Save file name(*.mat)');
                if filename==0
                    return;
                else
                    %save([pathname, filename], 'saxs');
                    %save([pathname, filename], 'saxs', '-v7.3', '-nocompression');
                    save([pathname, filename], 'saxs', '-v7.3');
                    disp('setup file is saved!')
                end
            elseif qCalData.scatt.isPilatus2m == 0   % waxs, Pilatus300
                waxs = qCalData.scatt;
                [filename, pathname] = uiputfile( 'waxssetup.mat', 'Save file name(*.mat)');
                if filename==0 return;
                else
                    %save([pathname, filename], 'waxs');
                    save([pathname, filename], 'waxs', '-v7.3', '-nocompression');
                    disp('setup file is saved!')
                end
            elseif qCalData.scatt.isPilatus2m == 2   % laxs, PE detector
                laxs = qCalData.scatt;
                [filename, pathname] = uiputfile( 'PEsetup.mat', 'Save file name(*.mat)');
                if filename==0 return;
                else
                    %save([pathname, filename], 'laxs');
                    %save([pathname, filename], 'laxs', '-v7.3', '-nocompression');
                    save([pathname, filename], 'laxs', '-v7.3');
                    disp('setup file is saved!')
                end
            
            elseif qCalData.scatt.isPilatus2m == 3 % for Pilatus900k detector
                waxs = qCalData.scatt;
                [filename, pathname] = uiputfile('waxssetup.mat', 'Save file name(*.mat)');
                if filename==0
                    return;
                else
                    %save([pathname, filename], 'saxs');
                    save([pathname, filename], 'waxs', '-v7.3', '-nocompression');
                    disp('setup file is saved!')
                end
            
            elseif qCalData.scatt.isPilatus2m == -1 % for other detector
                saxs = qCalData.scatt;
                [filename, pathname] = uiputfile('saxssetup.mat', 'Save file name(*.mat)');
                if filename==0
                    return;
                else
                    save([pathname, filename], 'saxs');
                    disp('setup file is saved!')
                end
            else
                disp('why I am here? please check in pbSaveSetup_Callback....')
            end
            
            %disp(pathname);
            %disp(filename);
            % save seperate setup parameters in text and mask file
            setupFolder = fullfile(pathname, 'SetupFiles');
            setupText = [filename(1:end-4) '_param.txt'];
            
            if ~exist(setupFolder, 'dir')
                mkdir(setupFolder);
            end
            myStr=evalc('disp(qCalData.scatt)');
            fid = fopen(fullfile(setupFolder, setupText), 'w');
            fprintf(fid, '%s', myStr);
            fclose(fid);
            
            if isfield(qCalData.scatt, 'mask')
                setupMask = fullfile(setupFolder, [filename(1:end-4) '_mask.bmp']);
                imwrite(logical(qCalData.scatt.mask), setupMask);
            end

            %----------------save calibration files -----------------------
            if app.SaveCalibDataCheckBox.Value 
                %folder = 'trainingSets_CURRENT_FOLDER_NAME';
                [~, cfolder] = fileparts(regexprep(pathname,'[\\/]+$','')); 
                fullfld = fullfile(pathname, sprintf('%s_%s', app.imageData.trainingFolder, cfolder));
                %fullfld = fullfile(pathname, app.imageData.trainingFolder);
                maskSet=[];
                calibSet=[]; % not calibration sets

                calibSet.imageFileName = qCalData.scatt.calibrantImageFile;
                calibSet.imageOriginalLocation = qCalData.scatt.calibrantImagePath;
                calibSet.setupFileName = setupText;
                calibSet.setupOriginalLocation = setupFolder;

                calibSet.type='SAXS';
                if contains(filename, 'WAXS', 'IgnoreCase', true)
                    calibSet.type='WAXS';
                elseif contains(filename, 'PE', 'IgnoreCase', true)
                    calibSet.type='PE';
                end

                calibSet.calibrantName = qCalData.scatt.calibrantName;
                calibSet.calibrantQValues = qCalData.scatt.calibrantQValues;
                calibSet.XrayEnergy = qCalData.scatt.eng;

                % updateMaskCalibrationDataset(maskSets, calibSets, destFolder)
                updateMaskCalibrationDataset(maskSet, calibSet, fullfld);

            end
        end

        % Button pushed function: plotSector
        function plotSector_Callback(app, event)
            % --- Executes on button press in plotSector.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to plotSector (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [qCalData, userData, scatt, calibrant]= getData(app);
            try
                figH = evalin('base', 'SAXSimageviewerhandle');
            catch
                fprintf('Start SAXSimageView first...\n');
                return;
            end
            h2 = guidata(figH);
            % ImageView handle" h2.ImageAxes
            imgH = getgihandle;
            imgHrow = imgH.imgsize(1); imgHcol = imgH.imgsize(2);
            row = scatt.row; col = scatt.col;
            if (row ~= imgHrow || col ~= imgHcol)
                disp('image size not match!');
            end
            
            beamXY = scatt.BeamXY;
            nPts = 100;
            
            [xy1, vx1]=genSectorLine(app, beamXY, imgH.imgsize, scatt.sectorA1, nPts);
            [xy2, vx2]=genSectorLine(app, beamXY, imgH.imgsize, scatt.sectorA2, nPts);
            
            cmd1=sprintf('line(xy1(:,1), xy1(:,2),  ''parent'', h2.ImageAxes, ''Color'', ''m'', ''LineStyle'', ''-'', ''linewidth'', 1);');
            cmd2=sprintf('line(xy2(:,1), xy2(:,2),  ''parent'', h2.ImageAxes, ''Color'', ''m'', ''LineStyle'', ''-'', ''linewidth'', 1);');
            eval(cmd1); eval(cmd2);
            setData(app, qCalData, userData, scatt, calibrant);
        end

        % Button pushed function: processCurrentImg
        function processCurrentImg_Callback(app, event)
            % --- Executes on button press in processCurrentImg.
            % need work on hdf5 files 1/23/2022
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to processCurrentImg (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [qCalData, userData, scatt, calibrant]= getData(app);
            phd = 0;
            cImg = getgihandle;
            datadir = cImg.dir;
            file = cImg.imagename;
            
            % if file(end-3)~='.'
            %     file = [file '.tif'];
            % end
            
            
            %[file, datadir]=uigetfile({'*.tif';'*.ccd'});
            if file==0 return; end
            
            % outputFileName=[file(1:end-3) 'dat'];
            
            cfile = fullfile(datadir, file);
            [fpath9, fn9, ext9] = fileparts(file);
            
            ctmp = strsplit(datadir, filesep);
            tmpParentDir = fullfile(ctmp{1:end-1});
            logPath = fullfile(tmpParentDir,'Log',filesep);
            %logPath = fullfile(datadir(1:end-5),'Log',filesep);
            logFileName = ['L' fn9(2:end) '.meta'];
            fullLogFile = fullfile(logPath, logFileName);
            
            %[phd, Io, eng] = parseMetafile(fullLogFile);
            [phd, Io, eng] = parseMetafile2(fullLogFile);
            
            
            % apply sector mask
            if scatt.sectorMaskOn && ~isempty(scatt.sectorMask)
                mask = scatt.mask & scatt.sectorMask;
                if scatt.arbitraryMaskOn && ~isempty(scatt.arbitraryMask)
                    mask = mask & scatt.arbitraryMask;
                end
                figure(3003); imagesc(mask); set(gca, 'YDir','normal');
            else
                mask = scatt.mask;
                if scatt.arbitraryMaskOn && ~isempty(scatt.arbitraryMask)
                    mask = mask & scatt.arbitraryMask;
                end
                figure(3003); imagesc(mask); set(gca, 'YDir','normal');
            end
            
            %outputFileName=[file(1:end-3) 'dat'];
            outputFileName=[fn9 '.dat'];
            
            cfile = fullfile(datadir, file);
            disp(cfile);
            
            %if strcmpi(ext9, '.tif')      % tif file
            if strncmpi(ext9, '.tif', 4)      % tif file
                sImg = imgUpsideDn(double(imread(cfile)));
            
            elseif strcmpi(ext9, '.h5')   % hdf5 data
                if contains(fn9, 'master')
                    fprintf('%s is a master file, will not be proecssed!\n',cfile);
                else
                    if isfield(scatt, 'Beamline')
                        beamline = scatt.Beamline;
                    else
                        beamline = '12-ID-B';
                    end
                    h5data = readHdf5(cfile, beamline);
                    %h5data = readHdf5(cfile);
                    sImg = imgUpsideDn(double(h5data.data'));
                end
            else
                fprintf('%s is not supported and proecssed!\n',cfile);
            end
            
            figure('NumberTitle','off','Name',cfile);imagesc(sImg, [0 500]);
            
            %sImg = imgUpsideDn(double(imread(cfile)));
            %figure('NumberTitle','off','Name',cfile);imagesc(sImg);
            
            isReady = checkReadiness(app);
            
            if isReady
                data = circavgnew2c(sImg, mask, scatt.qCMap, scatt.qRMap, scatt.qArray, scatt.offset, scatt.limits);
                fprintf("circ v2c\n");
                %data = circavgnew2(sImg, scatt.mask, scatt.qCMap, scatt.qRMap, scatt.qArray, scatt.offset, scatt.limits);
                figure('NumberTitle','off','Name',['1d data for: ' cfile]); loglog(data(:,1),data(:,2), '-r');
            
                %[filename, pathname] = uiputfile('test.dat', 'Save file name(*.dat)');
                [filename, pathname] = uiputfile(outputFileName, 'Save file name(*.dat)');
                if filename==0
                    return;
                else
                    outputFile = [pathname filename];
                    % [data(:,1) data(:,2)*absIntCoeff/phd data(:,3)./sqrt(data(:,5))*absIntCoeff/phd
                    %outputData = [data(:,1)*eng/scatt.eng data(:,2)*absIntCoeff/phd data(:,3)*absIntCoeff/phd data(:,5)]
            
                    outputData = [data(:,1) data(:,2)/phd*1e-6 data(:,3)/phd*1e-6];
            %         if phd > 0
            %             outputData = [data(:,1) data(:,2)/phd data(:,3)/phd];
            %         else
            %             outputData = data(:,1:3);
            %         end
                    dlmwrite(outputFile,outputData,'delimiter','\t','precision','%.6f');
                end
            
            end
        end

        % Button pushed function: processMultipleImages
        function processMultipleImages_Callback(app, event)
            % --- Executes on button press in processMultipleImages.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Process Multiple Image Function
            %
            % hObject    handle to processMultipleImages (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            [qCalData, userData, scatt, calibrant]= getData(app);
            
            
            [filenames, pathname, filterindex] = uigetfile( ...
            {  '*.tif;*.h5','SAXS Image File (*.dat)'; ...
               '*.*',  'All Files (*.*)'}, ...
               'Pick a file', ...
               'MultiSelect', 'on');
            
            
            % filenames=0 if cancelled
            if isnumeric(filenames)
                fprintf('No files selected!\n');
                return;
            end
            if ischar(filenames)   % only one file was selected.
                filenames={filenames};
            end
            
            % % sector mask
            % if scatt.sectorMaskOn && ~isempty(scatt.sectorMask)
            %     mask = scatt.mask & scatt.sectorMask;
            %     figure(3003); imagesc(mask); set(gca, 'YDir','normal');
            % else
            %     mask = scatt.mask;
            % end
            
            % apply sector/arbitary mask
            if isfield(scatt, 'sectorMaskOn') && scatt.sectorMaskOn && ~isempty(scatt.sectorMask)
                mask = scatt.mask & scatt.sectorMask;
                if scatt.arbitraryMaskOn && ~isempty(scatt.arbitraryMask)
                    mask = mask & scatt.arbitraryMask;
                end
                figure(3003); imagesc(mask); set(gca, 'YDir','normal');
            else
                mask = scatt.mask;
                if isfield(scatt, 'arbitraryMaskOn') && scatt.arbitraryMaskOn && ~isempty(scatt.arbitraryMask)
                    mask = mask & scatt.arbitraryMask;
                end
                figure(3003); imagesc(mask); set(gca, 'YDir','normal');
            end
            
            for nfile = filenames
                tic
                h5data=[];
                file = nfile{1,1};
                [~, fn9, ext9] = fileparts(file);
                %outputFileName=[file(1:end-3) 'dat'];
                outputFileName=[fn9 '.dat'];
            
                cfile = fullfile(pathname, file);
                disp(cfile)
                if strncmpi(ext9, '.tif', 4)      % tif file
                    sImg = imgUpsideDn(double(imread(cfile)));
                elseif strcmpi(ext9, '.h5')   % hdf5 data
                    if contains(fn9, 'master')
                        fprintf('%s is a master file, will not be proecssed!\n',cfile);
                        continue;
                    else
                        %h5data = readHdf5(cfile);
                        if isfield(scatt, 'Beamline')
                            beamline = scatt.Beamline;
                        else
                            beamline = '12-ID-B';
                        end
                        h5data = readHdf5(cfile, beamline);
                        sImg = imgUpsideDn(double(h5data.data'));
                    end
                else
                    fprintf('%s is not supported and proecssed!\n',cfile);
                    continue;
                end
                %figure('NumberTitle','off','Name',cfile);imagesc(sImg);
                ctmp = strsplit(pathname, filesep);
                %tmpParentDir = fullfile(filesep, ctmp{1:end-2});
                if isempty(ctmp{1})
                    tmpParentDir = fullfile(filesep,ctmp{2:end-2});
                else
                    tmpParentDir = fullfile(ctmp{1:end-2});
                end
                if strcmp(pathname(1:2), '\\')
                    tmpParentDir = fullfile(filesep,tmpParentDir);
                end
                logPath = fullfile(tmpParentDir,'Log',filesep);
                %logPath = fullfile(pathname(1:end-5),'Log',filesep);
                if userData.avgFolderChoice < 3
                    newAvgFolder = fullfile(pathname,userData.avgFolder,filesep);
                else
                    newAvgFolder = fullfile(userData.avgFolder,filesep);
                end
            
                logFileName = ['L' fn9(2:end) '.meta'];
                fullLogFile = fullfile(logPath, logFileName);
            
                if ~exist(newAvgFolder)
                    mkfolder = ['mkdir ' newAvgFolder];
                    dos(mkfolder);
                end
            
                %[phd, Io, eng] = parseMetafile(fullLogFile);
                %[phd, Io, eng] = parseMetafile(fullLogFile, 0); % comment out 10/18/2017
                if exist(fullLogFile,'file')>0
                    [phd, Io, eng, expt, metaInfo] = parseMetafile2(fullLogFile, -1);
                    expttime = metaInfo.expttime;
                else
                    expt = -1;
                    expttime = -1;
                    eng = scatt.eng;
                    phd = -1;
                    Io = -1;
                    try
                        if ~isempty(h5data)
                            phd = h5data.It_flux; % read from h5 data
                            Io = h5data.Io_flux;
                            %phd = h5data.It_phd; % read from h5 data
                            %Io = h5data.IC1_phd;
                            expttime = h5data.Sample_Time;
                            expt=h5data.ExposureTime;
                            eng = h5data.monoE;
                        else
                            fprintf('LogFile: %s and Header Info not found,  1D data will not be normalized by It.\n', fullLogFile);
                            %expt = -1;
                            %expttime = -1;
                            %eng = scatt.eng;
                            %phd = -1;
                            %Io = -1;
                        end
                    catch
                        disp('Parameter missed!');
                    end
                end
            %     if ~exist(fullLogFile, 'file')
            %         disp(sprintf('LogFile: %s not found, 1D data will not be normalized by It.', fullLogFile));
            %     end
            
                isReady = checkReadiness(app);
                if isfield(scatt, 'absIntCoeff')
                    absIntCoeff = scatt.absIntCoeff;
                else
                    absIntCoeff = 1.0;
                end
                toc
                tic
                if isReady
                    data = circavgnew2c(sImg, mask, scatt.qCMap, scatt.qRMap, scatt.qArray, scatt.offset, scatt.limits);
                    fprintf('circ v2c\n');
                        % this is the suggested version
                        % data(1): Q;
                        % data(2): I(Q)--averaged from photons, corrected by solid angle, scaled by 1e-6
                        % data(3): standard error of the mean of I(Q) among pixels in a given Q-bin
                        % data(4): error estimated by: sqrt(I(Q)/N), corrected by solid angle, scaled by 1e-6
                        % data(5): total pixel number in a given Q-bin
                        % data(6): averaged photon number without solid angle correction , scaled by 1e-6
            
            
                    %data = circavgnew2(sImg, mask, scatt.qCMap, scatt.qRMap, scatt.qArray, scatt.offset, scatt.limits);
                    %fprintf('circ v2b\n');
                        % data(1): Q;
                        % data(2): I(Q)--averaged from photons
                        % data(3): error estimated by: sqrt(I(Q)/N) among pixels in a given Q-bin
                        % data(4): Average of I(Q)^2 values for a given Q-bin
                        % data(5): total pixel number in a given Q-bin
                        % data(6): solid angle correction value
            
                    %data = circavgnew3(sImg, mask, scatt.qCMap, scatt.qRMap, scatt.qArray, scatt.offset, scatt.limits);
                    %fprintf('circ v3\n');
                        % data(1): Q;
                        % data(2): I(Q)--averaged from photons
                        % data(3): error estimated by: sqrt(I(Q)/N) among pixels in a given Q-bin
                        % data(4): Zero
                        % data(5): total pixel number in a given Q-bin
                        % data(6): solid angle correction value
                    %data = circavgnew2(sImg, scatt.mask, scatt.qCMap, scatt.qRMap, scatt.qArray, scatt.offset, scatt.limits);
                    %figure('NumberTitle','off','Name',['1d data for: ' cfile]); loglog(data(:,1),data(:,2), '-r');
            
                    %outputFile = [pathname outputFileName];
                    outputFile = [newAvgFolder outputFileName];
                    errmsg ='';
                    [fid5, errmsg]=fopen(outputFile, 'w');
                    if fid5 > 0
                        fprintf(fid5, '# Image Filename:  %s\n', file);
                        fprintf(fid5, '# Original Folder: %s\n', pathname);
                        %fprintf(fid5, '# Data Collection Time:  %s\n', expttime);
                        fprintf(fid5, '# Data Processing Time:  %s\n', datetime());
                        fprintf(fid5, '# X-ray Energy (KeV):  %.4f\n', eng);
                        fprintf(fid5, '# X-ray Wavelength (A):  %.4f\n', scatt.wavelength);
                        fprintf(fid5, '# X-ray Detector: %s\n', scatt.detector);
                        fprintf(fid5, '# Detector Pixel Size (mm):  %.4f\n', scatt.pSize);
                        fprintf(fid5, '# Detector Q calibrant: %s\n', scatt.qStandard);
                        fprintf(fid5, '# Beam Center :  %.4f, %.4f\n', scatt.BeamXY(1), scatt.BeamXY(2) );
                        fprintf(fid5, '# Sample-to-Detector Distance (SDD) (mm):  %.3f\n', scatt.SDD);
                        fprintf(fid5, '# Photodiode Value (It):  %.4f\n', phd);
                        fprintf(fid5, '# I0 Value:  %.4f\n', Io);
                        fprintf(fid5, '# Exposure Time (s):  %.4f\n', expt);
                        fprintf(fid5, '# X-ray scattering Intensities are normallized by transmitted flux(i.e. Photodiode Value)\n');
                        fprintf(fid5, '# Absolute Intensity was calibrated using: %s.\n', scatt.absIntStandard);
                        fprintf(fid5, '# Scaled by Absolute Intensity Coefficient: %.4f\n', absIntCoeff);
                        fprintf(fid5, '# \n');
                        fprintf(fid5, '# q_(1/A)    I(q)_(cm^-1)     I(q)_SE\n');
            
                        fclose(fid5);
                        %disp(sprintf('%s was processed.\n',cfile));
                        if phd<=0
                            phd = 1.0;
                            %disp(sprintf('Negative phd value!! %s was processed, but not normalized.\n', cfile));
                            fprintf('Negative phd value!! %s was processed, but not normalized.\n', cfile);
                        else
                            %disp(sprintf('%s was processed.\n', cfile));
                            fprintf('%s was processed.\n', cfile);
                        end
                        %dlmwrite(outputFile,[data(:,1) data(:,2:3)/phd],'delimiter','\t','precision','%.6f');
                        % data(1): Q;
                        % data(2): I(Q)--averaged from photons, corrected by solid angle, scaled by 1e-6
                        % data(3): standard deviation of I(Q) among pixels in a given Q-bin
                        % data(4): total photon number solid angle corrected in a given Q-bin
                        % data(5): total pixel number in a given Q-bin
                        % data(6): averaged photon number without solid angle correction
            
                        dlmwrite(outputFile,[data(:,1)*eng/scatt.eng data(:,2)*absIntCoeff/phd*1e-6 data(:,3)*absIntCoeff/phd*1e-6 ], '-append', 'delimiter','\t','precision','%.6e');
                        %dlmwrite(outputFile,[data(:,1) data(:,2) data(:,3) data(:,4) data(:,5) data(:,6)], '-append', 'delimiter','\t','precision','%.6e');
                        %dlmwrite(outputFile,[data(:,1) data(:,2)*absIntCoeff/phd data(:,3)./sqrt(data(:,5))*absIntCoeff/phd data(:,5)], '-append', 'delimiter','\t','precision','%.6e');
            
                        fprintf('cof=%.5E.\n', absIntCoeff/phd);
                        %dlmwrite(outputFile,[data(:,1) data(:,2)*absIntCoeff/phd data(:,3)*absIntCoeff/phd data(:,5)], '-append', 'delimiter','\t','precision','%.6e');
                    else
                        fprintf('Error: file: %s cannot open! %s.\n', outputFile, errmsg);
                        %disp(errmsg);
                    end
            
                end
                toc
                disp(' ');
            end
        end

        % Button pushed function: processSingleImage
        function processSingleImage_Callback(app, event)
            % --- Executes on button press in processSingleImage.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Process individual image, also It normalization
            % hObject    handle to processSingleImage (see GCBO)
            % handles    structure with handles and user data (see GUIDATA)
            [qCalData, userData, scatt, calibrant]= getData(app);
            [file, pathname]=uigetfile({'*.tif;*.h5';'*.ccd'});
            if file==0 return; end
            
            % outputFileName=[file(1:end-3) 'dat'];
            %
            % cfile = fullfile(datadir, file);
            % sImg = imgUpsideDn(double(imread(cfile)));
            
            [~, fn9, ext9] = fileparts(file);
            %outputFileName=[file(1:end-3) 'dat'];
            outputFileName=[fn9 '.dat'];
            
            cfile = fullfile(pathname, file);
            disp(cfile);
            
            if strncmpi(ext9, '.tif', 4)      % tif file
                sImg = imgUpsideDn(double(imread(cfile)));
            elseif strcmpi(ext9, '.h5')   % hdf5 data
                if contains(fn9, 'master')
                    fprintf('%s is a master file, will not be proecssed!\n',cfile);
                else
                    if isfield(scatt, 'Beamline')
                        beamline = scatt.Beamline;
                    else
                        beamline = '12-ID-B';
                    end
                    h5data = readHdf5(cfile, beamline);
                    sImg = imgUpsideDn(double(h5data.data'));
                end
            else
                fprintf('%s is not supported and proecssed!\n',cfile);
            end
            
            figure('NumberTitle','off','Name',cfile);imagesc(sImg, [0 500]);
            
            ctmp = strsplit(pathname, filesep);
            %tmpParentDir = fullfile(filesep, ctmp{1:end-2});
            if isempty(ctmp{1})
                tmpParentDir = fullfile(filesep,ctmp{2:end-2});
            else
                tmpParentDir = fullfile(ctmp{1:end-2});
            end
            if strcmp(pathname(1:2), '\\')
                tmpParentDir = fullfile(filesep,tmpParentDir);
            end
            logPath = fullfile(tmpParentDir,'Log',filesep);
            %logPath = fullfile(pathname(1:end-5),'Log',filesep);
            newAvgFolder = fullfile(pathname,'Avg',filesep);
            logFileName = ['L' fn9(2:end) '.meta'];
            fullLogFile = fullfile(logPath, logFileName);
            
            
            [phd, Io, eng, expt, metaInfo] = parseMetafile2(fullLogFile, 0);
            if ~exist(fullLogFile, 'file')
                disp(sprintf('LogFile: %s not found, 1D data will not be normalized by It.', fullLogFile));
            end
            
            isReady = checkReadiness(app);
            if isfield(scatt, 'absIntCoeff')
                absIntCoeff = scatt.absIntCoeff;
            else
                absIntCoeff = 1.0;
            end
            
            % apply sector mask
            % if scatt.sectorMaskOn && ~isempty(scatt.sectorMask)
            %     mask = scatt.mask & scatt.sectorMask;
            %     figure(3003); imagesc(mask); set(gca, 'YDir','normal');
            % else
            %     mask = scatt.mask;
            % end
            
            % apply sector mask
            if scatt.sectorMaskOn && ~isempty(scatt.sectorMask)
                mask = scatt.mask & scatt.sectorMask;
                if scatt.arbitraryMaskOn && ~isempty(scatt.arbitraryMask)
                    mask = mask & scatt.arbitraryMask;
                end
                figure(3003); imagesc(mask); set(gca, 'YDir','normal');
            else
                mask = scatt.mask;
                if scatt.arbitraryMaskOn && ~isempty(scatt.arbitraryMask)
                    mask = mask & scatt.arbitraryMask;
                end
                figure(3003); imagesc(mask); set(gca, 'YDir','normal');
            end
            
            if isReady
                tic
                %data = circavgnew2(sImg, scatt.mask, scatt.qCMap, scatt.qRMap, scatt.qArray, scatt.offset, scatt.limits);
                data = circavgnew2(sImg, mask, scatt.qCMap, scatt.qRMap, scatt.qArray, scatt.offset, scatt.limits);
                figure('NumberTitle','off','Name',['1d data for: ' cfile]); loglog(data(:,1),data(:,2)*absIntCoeff/phd, '-r');
            
                %[filename, pathname] = uiputfile('test.dat', 'Save file name(*.dat)');
                [filename, pathname] = uiputfile(outputFileName, 'Save file name(*.dat)');
            
                if filename==0
                    return;
                else
                    outputFile = [pathname filename];
                    errmsg ='';
                    [fid5, errmsg]=fopen(outputFile, 'w');
                    if fid5 > 0
                        fprintf(fid5, '# Image Filename:  %s\n', file);
                        fprintf(fid5, '# Original Folder: %s\n', metaInfo.datadir);
                        fprintf(fid5, '# Data Collection Time:  %s\n', metaInfo.expttime);
                        fprintf(fid5, '# Data Processing Time:  %s\n', datetime());
                        fprintf(fid5, '# X-ray Energy (KeV):  %.4f\n', eng);
                        fprintf(fid5, '# Beam Center :  %.4f, %.4f\n', scatt.BeamXY(1), scatt.BeamXY(2) );
                        fprintf(fid5, '# Sample-to-Detector Distance (SDD) (mm):  %.3f\n', scatt.SDD);
                        fprintf(fid5, '# Detector Pixel Size (mm):  %.4f\n', scatt.pSize);
                        fprintf(fid5, '# X-ray scattering Intensities are normallized by transmitted flux(It)\n');
                        fprintf(fid5, '#    and scaled to absolute values if Absolute Intensity Coefficient provided.\n');
                        fprintf(fid5, '# Photodiode Value (It):  %.4f\n', phd);
                        fprintf(fid5, '# I0 Value:  %.4f\n', Io);
                        fprintf(fid5, '# Exposure Time (s):  %.4f\n', expt);
                        fprintf(fid5, '# Scaled by Absolute Intensity Coefficient: %.4f\n', absIntCoeff);
                        fprintf(fid5, '# \n');
                        fprintf(fid5, '# q_(1/A)    I(q)_(cm^-1)     I(q)_SE\n');
            
                        fclose(fid5);
                        %disp(sprintf('%s was processed.\n',cfile));
                        if phd<=0
                            phd = 1.0;
                            %disp(sprintf('Negative phd value!! %s was processed, but not normalized.\n', cfile));
                            disp(sprintf('Negative phd value!! %s was processed, but not normalized.', cfile));
                        else
                            %disp(sprintf('%s was processed.\n', cfile));
                            disp(sprintf('%s was processed.', cfile));
                        end
                        %dlmwrite(outputFile,[data(:,1) data(:,2:3)/phd],'delimiter','\t','precision','%.6f');
                        dlmwrite(outputFile,[data(:,1) data(:,2)*absIntCoeff/phd data(:,3)./sqrt(data(:,5))*absIntCoeff/phd data(:,4:6)], '-append', 'delimiter','\t','precision','%.6e');
                        %dlmwrite(outputFile,[data(:,1) data(:,2:3)*absIntCoeff/phd data(:,4:6)], '-append', 'delimiter','\t','precision','%.6e');
                    else
                        disp(sprintf('Error: file: %s cannot open! %s.\n', outputFile, errmsg));
                        %disp(errmsg);
                    end
                end
            
            
            %     if filename==0
            %         return;
            %     else
            %         outputFile = [pathname filename];
            %         dlmwrite(outputFile,[data(:,1) data(:,2:3)*absIntCoeff/phd],'delimiter','\t','precision','%.6f');
            %     end
                toc
            end
        end

        % Button pushed function: pushbutton16
        function pushbutton16_Callback(app, event)
            % --- Executes on button press in pushbutton16.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton16 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %disp('here')
            [qCalData, userData, scatt, calibrant]= getData(app);
            
            if ~isempty(scatt.qMap)
                figure('NumberTitle','off' ,'Name','q Map'); imagesc(scatt.qMap);
            end
            
            if ~isempty(scatt.qCMap)
                figure('NumberTitle','off' ,'Name','Correction Map'); imagesc(scatt.qCMap);
            end
            
            if ~isempty(scatt.qRMap)
                figure('NumberTitle','off' ,'Name','q-index Map'); imagesc(scatt.qRMap);
            end
            
            if ~isempty(scatt.mask)
                figure('NumberTitle','off' ,'Name','mask'); imagesc(scatt.mask);
            end
        end

        % Value changed function: qStandard
        function qStandard_Callback(app, event)
            % --- Executes on selection change in qStandard.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to qStandard (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns qStandard contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from qStandard
            [qCalData, userData, scatt, calibrant]= getData(app);
            
            index_selected = get(hObject,'Value');
            list = get(hObject,'String');
            item_selected = list{index_selected};
            
            scatt.qStandardIndex =  index_selected;
            scatt.qStandard = item_selected;
            
            setData(app, qCalData, userData, scatt, calibrant);
        end

        % Value changed function: sectorA1
        function sectorA1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sectorA1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of sectorA1 as text
            %        str2double(get(hObject,'String')) returns contents of sectorA1 as a double
            [qCalData, userData, scatt, calibrant]= getData(app);
            userData.sectorA1 = str2num(get(handles.sectorA1,'String'));
            scatt.sectorA1 = userData.sectorA1;
            setData(app, qCalData, userData, scatt, calibrant);
        end

        % Value changed function: sectorA2
        function sectorA2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sectorA2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of sectorA2 as text
            %        str2double(get(hObject,'String')) returns contents of sectorA2 as a double
            [qCalData, userData, scatt, calibrant]= getData(app);
            userData.sectorA2 = str2num(get(handles.sectorA2,'String'));
            scatt.sectorA2 = userData.sectorA2;
            setData(app, qCalData, userData, scatt, calibrant);
        end

        % Selection changed function: uipanel13
        function uipanel13_SelectionChangeFcn(app, event)
            % --- Executes when selected object is changed in uipanel13.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to the selected object in uipanel13
            % eventdata  structure with the following fields (see UIBUTTONGROUP)
            %	EventName: string 'SelectionChanged' (read only)
            %	OldValue: handle of the previously selected object or empty if none was selected
            %	NewValue: handle of the currently selected object
            % handles    structure with handles and user data (see GUIDATA)
            %qCalData=evalin('base', 'qCalData');
            
            [qCalData, userData, scatt, calibrant]= getData(app);
            %setData(qCalData, userData, scatt, calibrant);
            qChoice = 2;
            switch get(eventdata.NewValue, 'Tag')
            
                case 'rbPixelBasedQ'
                    row = scatt.row;
                    col = scatt.col;
            
                    qChoice = 1;
                    qNum = pixelBaseQ(row, col, scatt.BeamXY);
            
                    scatt.qMin = 0;
                    userData.qMin = 0;
                    set(handles.edQMin, 'String', 0);
                    set(handles.edPtsNum, 'String', qNum);
            
            
                case 'rbLinearQ'
                    qChoice = 2;
            
                case 'rbCombLinearQ'
                    qChoice = 3;
                    scatt.qArray = updateComLinearQ(app, handles);
            %         qMin = userData.qMin;
            %         qMax = userData.qMax;
            %
            %         if isfield(scatt, 'qArray')
            %             scatt.qArray = [];
            %         end
            %
            %         dq1 = userData.combQs(1);
            %         q2 = userData.combQs(2);
            %         dq2 = userData.combQs(3);
            %         q3 = userData.combQs(4);
            %         dq3 = userData.combQs(5);
            %         qA1=[];
            %         qA2=[];
            %         qA3=[];
            %         qArray = [];
            %         if dq1>0
            %             if q2 < 0
            %                 qArray = qMin:dq1:qMax;
            %                 qNum = length(qArray);
            %                 set(handles.edPtsNum, 'String', qNum);
            %             elseif q2 > qMin
            %                 qA1=qMin:dq1:q2;
            %
            %                 if dq2 > 0
            %                     if q3 < 0
            %                        qA2=q2+dq2:dq2:qMax;
            %                        qArray = [qA1 qA2];
            %                        qNum = length(qArray);
            %                        set(handles.edPtsNum, 'String', qNum);
            %                     elseif q3 > q2
            %                         qA2=q2+dq2:dq2:q3;
            %
            %                         if dq3 > 0
            %                             if q3 < qMax
            %                                 qA3 = q3+dq3 : dq3:qMax;
            %                                 qArray = [qA1 qA2 qA3];
            %                                 qNum = length(qArray);
            %                                 set(handles.edPtsNum, 'String', qNum);
            %                             else
            %                                 disp('Setting of Q3 is wrong! Please check!')
            %                             end
            %                         else
            %                             disp('Setting of dQ3 is wrong! Please check!')
            %                         end
            %                     else
            %                         disp('Setting of Q3 is wrong! Please check!')
            %                     end
            %                 else
            %                     disp('Setting of dQ2 is wrong! Please check!')
            %                 end
            %             else
            %                 disp('Setting of Q2 is wrong! Please check!')
            %             end
            %         else
            %             disp('Setting of dQ1 is wrong! Please check!')
            %         end
            %
            %         scatt.qArray = qArray;
            %
                case 'rbLogQ'
                    qChoice = 4;
            
                case 'combLogLinear'
                    qChoice = 5;
                    scatt.qArray = updateComLogLinearQ(app, handles);
            end
            
            userData.qChoice = qChoice;
            setData(app, qCalData, userData, scatt, calibrant);
            updateAll(app, handles);
        end

        % Value changed function: useSectorMask
        function useSectorMaskValueChanged(app, event)
            [qCalData, userData, scatt, calibrant]= getData(app);
            %value = app.useSectorMask.Value;
            
            userData.sectorMaskOn = app.useSectorMask.Value;
            scatt.sectorMaskOn = userData.sectorMaskOn;
            %imgH = getgihandle;
            setData(app, qCalData, userData, scatt, calibrant);
            
            genSectorMask(app, event);
        end

        % Value changed function: excludeSectorMask
        function excludeSectorMaskValueChanged(app, event)
            %value = app.excludeSectorMask.Value;
            [qCalData, userData, scatt, calibrant]= getData(app);
            userData.sectorMaskExclude = app.excludeSectorMask.Value;
            scatt.sectorMaskExclude = userData.sectorMaskExclude;
            setData(app, qCalData, userData, scatt, calibrant);
            
            genSectorMask(app, event);
        end

        % Value changed function: arbitraryMaskOn
        function arbitraryMaskOnValueChanged(app, event)
            %value = app.arbitraryMaskOn.Value;
            [qCalData, userData, scatt, calibrant]= getData(app);
            userData.arbitraryMaskOn = app.arbitraryMaskOn.Value;
            scatt.arbitraryMaskOn = userData.arbitraryMaskOn;
            setData(app, qCalData, userData, scatt, calibrant);
        end

        % Value changed function: arbitraryMaskExclude
        function arbitraryMaskExcludeValueChanged(app, event)
            %value = app.arbitraryMaskExclude.Value;
            [qCalData, userData, scatt, calibrant]= getData(app);
            userData.arbitraryMaskExclude = app.arbitraryMaskExclude.Value;
            scatt.arbitraryMaskExclude = userData.arbirtaryMaskExclude;
            setData(app, qCalData, userData, scatt, calibrant);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'qCalibration';
            app.figure1.Tag = 'figure1';
            app.uipanel7 = uipanel(app.figure1);
            app.uipanel7.Title = 'Detector';
            app.uipanel7.Tag = 'uipanel7';
            app.bgDetector = uibuttongroup(app.uipanel7);
            app.bgDetector.SelectionChangedFcn = createCallbackFcn(app, @bgDetector_SelectionChangeFcn, true);
            app.bgDetector.Title = 'Select detector';
            app.bgDetector.Tag = 'bgDetector';
            app.rbPilatus2m = uiradiobutton(app.bgDetector);
            app.rbPilatus2m.Tag = 'rbPilatus2m';
            app.rbPilatus2m.Text = 'Pilatus 2M';
            app.rbPilatus2m.Value = true;
            app.rbPilatus300 = uiradiobutton(app.bgDetector);
            app.rbPilatus300.Tag = 'rbPilatus300';
            app.rbPilatus300.Text = 'Pilatus 300K';
            app.edRow = uieditfield(app.bgDetector, 'text');
            app.edRow.ValueChangedFcn = createCallbackFcn(app, @edRow_Callback, true);
            app.edRow.Tag = 'edRow';
            app.edRow.Value = '1024';
            app.text550 = uilabel(app.bgDetector);
            app.text550.Tag = 'text550';
            app.text550.Text = 'Col_X';
            app.edCol = uieditfield(app.bgDetector, 'text');
            app.edCol.ValueChangedFcn = createCallbackFcn(app, @edCol_Callback, true);
            app.edCol.Tag = 'edCol';
            app.edCol.Value = '1024';
            app.text56 = uilabel(app.bgDetector);
            app.text56.Tag = 'text56';
            app.text56.Text = 'Row_Y';
            app.rbPE = uiradiobutton(app.bgDetector);
            app.rbPE.Tag = 'rbPE';
            app.rbPE.Text = 'PerkinElmer XRpad';
            app.rbPilatus900k = uiradiobutton(app.bgDetector);
            app.rbPilatus900k.Tag = 'rbPilatus900k';
            app.rbPilatus900k.Text = 'Pilatus 900K';
            app.rbOther = uiradiobutton(app.bgDetector);
            app.rbOther.Tag = 'rbOther';
            app.rbOther.Text = 'Other';
            app.rbEiger9m = uiradiobutton(app.bgDetector);
            app.rbEiger9m.Tag = 'rbEiger9m';
            app.rbEiger9m.Text = 'Eiger 9M';
            app.txtPixelsize = uilabel(app.uipanel7);
            app.txtPixelsize.Tag = 'txtPixelsize';
            app.txtPixelsize.Text = 'Pixel size (mm)';
            app.text88 = uilabel(app.uipanel7);
            app.text88.Tag = 'text88';
            app.text88.Text = 'Beamline';
            app.Beamline = uilistbox(app.uipanel7);
            app.Beamline.Items = {'12-ID-B, APS', '12-ID-C, APS'};
            app.Beamline.ValueChangedFcn = createCallbackFcn(app, @Beamline_Callback, true);
            app.Beamline.Tag = 'Beamline';
            app.Beamline.Value = '12-ID-B, APS';
            app.edPixelsize = uieditfield(app.uipanel7, 'text');
            app.edPixelsize.ValueChangedFcn = createCallbackFcn(app, @edPixelsize_Callback, true);
            app.edPixelsize.Tag = 'edPixelsize';
            app.edPixelsize.Value = '0.172';
            app.uipanel9 = uipanel(app.figure1);
            app.uipanel9.Title = 'Setup Parameters';
            app.uipanel9.Tag = 'uipanel9';
            app.edEnergy = uieditfield(app.uipanel9, 'text');
            app.edEnergy.ValueChangedFcn = createCallbackFcn(app, @edEnergy_Callback, true);
            app.edEnergy.Tag = 'edEnergy';
            app.edEnergy.Value = '13.3';
            app.text16 = uilabel(app.uipanel9);
            app.text16.Tag = 'text16';
            app.text16.Text = 'X-ray Energy';
            app.edOffset = uieditfield(app.uipanel9, 'text');
            app.edOffset.ValueChangedFcn = createCallbackFcn(app, @edOffset_Callback, true);
            app.edOffset.Tag = 'edOffset';
            app.edOffset.Value = '0';
            app.text34 = uilabel(app.uipanel9);
            app.text34.Tag = 'text34';
            app.text34.Text = 'Offset';
            app.edLimitUb = uieditfield(app.uipanel9, 'text');
            app.edLimitUb.ValueChangedFcn = createCallbackFcn(app, @edLimitUb_Callback, true);
            app.edLimitUb.Tag = 'edLimitUb';
            app.edLimitUb.Value = '1000000';
            app.text35 = uilabel(app.uipanel9);
            app.text35.Tag = 'text35';
            app.text35.Text = 'Counts Limits';
            app.edLimitLb = uieditfield(app.uipanel9, 'text');
            app.edLimitLb.ValueChangedFcn = createCallbackFcn(app, @edLimitLb_Callback, true);
            app.edLimitLb.Tag = 'edLimitLb';
            app.edLimitLb.Value = '-1';
            app.edAbsCoeff = uieditfield(app.uipanel9, 'text');
            app.edAbsCoeff.ValueChangedFcn = createCallbackFcn(app, @edAbsCoeff_Callback, true);
            app.edAbsCoeff.Tag = 'edAbsCoeff';
            app.edAbsCoeff.Value = '1.0';
            app.text85 = uilabel(app.uipanel9);
            app.text85.Tag = 'text85';
            app.text85.Text = 'Abs Int Coeff.';
            app.text86 = uilabel(app.uipanel9);
            app.text86.Tag = 'text86';
            app.text86.Text = 'AbsIntStandard';
            app.absIntStandard = uilistbox(app.uipanel9);
            app.absIntStandard.Items = {'None', 'Water', 'Glassy Carbon'};
            app.absIntStandard.ValueChangedFcn = createCallbackFcn(app, @absIntStandard_Callback, true);
            app.absIntStandard.Tag = 'absIntStandard';
            app.absIntStandard.Value = 'None';
            app.text87 = uilabel(app.uipanel9);
            app.text87.Tag = 'text87';
            app.text87.Text = 'qStandard';
            app.qStandard = uilistbox(app.uipanel9);
            app.qStandard.Items = {'None', 'Silver Behenate', 'Ce2O'};
            app.qStandard.ValueChangedFcn = createCallbackFcn(app, @qStandard_Callback, true);
            app.qStandard.Tag = 'qStandard';
            app.qStandard.Value = 'None';
            app.edBeamX = uieditfield(app.uipanel9, 'text');
            app.edBeamX.ValueChangedFcn = createCallbackFcn(app, @edBeamX_Callback, true);
            app.edBeamX.Tag = 'edBeamX';
            app.edBeamX.Value = '750';
            app.text26 = uilabel(app.uipanel9);
            app.text26.Tag = 'text26';
            app.text26.Text = 'Beam center X';
            app.edBeamY = uieditfield(app.uipanel9, 'text');
            app.edBeamY.ValueChangedFcn = createCallbackFcn(app, @edBeamY_Callback, true);
            app.edBeamY.Tag = 'edBeamY';
            app.edBeamY.Value = '180';
            app.text27 = uilabel(app.uipanel9);
            app.text27.Tag = 'text27';
            app.text27.Text = 'Beam center Y';
            app.edSDD = uieditfield(app.uipanel9, 'text');
            app.edSDD.ValueChangedFcn = createCallbackFcn(app, @edSDD_Callback, true);
            app.edSDD.Tag = 'edSDD';
            app.edSDD.Value = '2000';
            app.text28 = uilabel(app.uipanel9);
            app.text28.Tag = 'text28';
            app.text28.Text = 'SDD (mm)';
            app.edYaw = uieditfield(app.uipanel9, 'text');
            app.edYaw.ValueChangedFcn = createCallbackFcn(app, @edYaw_Callback, true);
            app.edYaw.Tag = 'edYaw';
            app.edYaw.Value = '0';
            app.text29 = uilabel(app.uipanel9);
            app.text29.Tag = 'text29';
            app.text29.Text = 'Yaw angle (deg)';
            app.CopyFromDetQCalib = uibutton(app.uipanel9, 'push');
            app.CopyFromDetQCalib.ButtonPushedFcn = createCallbackFcn(app, @CopyFromDetQCalib_Callback, true);
            app.CopyFromDetQCalib.Tag = 'CopyFromDetQCalib';
            app.CopyFromDetQCalib.Tooltip = 'Copy configuration parameters from detQCalib program. if parameters lost, retrieve them by pressing "Manual Update Q-Rings" in "detQCalib" program.';
            app.CopyFromDetQCalib.Text = {'Copy fitting '; 'from detQCalib'};
            app.CalibrantdataLabel = uilabel(app.uipanel9);
            app.CalibrantdataLabel.Text = 'Calibrant data:';
            app.uipanel10 = uipanel(app.figure1);
            app.uipanel10.Title = 'Map Calculations';
            app.uipanel10.Tag = 'uipanel10';
            app.uipanel13 = uibuttongroup(app.uipanel10);
            app.uipanel13.SelectionChangedFcn = createCallbackFcn(app, @uipanel13_SelectionChangeFcn, true);
            app.uipanel13.Title = 'Q Array setup';
            app.uipanel13.Tag = 'uipanel13';
            app.rbPixelBasedQ = uiradiobutton(app.uipanel13);
            app.rbPixelBasedQ.Tag = 'rbPixelBasedQ';
            app.rbPixelBasedQ.Text = 'Pixel Based';
            app.rbPixelBasedQ.Value = true;
            app.rbLinearQ = uiradiobutton(app.uipanel13);
            app.rbLinearQ.Tag = 'rbLinearQ';
            app.rbLinearQ.Text = 'Linear Q Array';
            app.rbLogQ = uiradiobutton(app.uipanel13);
            app.rbLogQ.Tag = 'rbLogQ';
            app.rbLogQ.Text = 'Log Q Array';
            app.edPtsNum = uieditfield(app.uipanel13, 'text');
            app.edPtsNum.ValueChangedFcn = createCallbackFcn(app, @edPtsNum_Callback, true);
            app.edPtsNum.Tag = 'edPtsNum';
            app.edPtsNum.Value = '600';
            app.pbCalQIndxMap = uibutton(app.uipanel13, 'push');
            app.pbCalQIndxMap.ButtonPushedFcn = createCallbackFcn(app, @pbCalQIndxMap_Callback, true);
            app.pbCalQIndxMap.Tag = 'pbCalQIndxMap';
            app.pbCalQIndxMap.Tooltip = 'Calculate Q binning map';
            app.pbCalQIndxMap.Text = 'Calculate Q Index Map';
            app.text33 = uilabel(app.uipanel13);
            app.text33.Tag = 'text33';
            app.text33.Text = 'Q pts Number';
            app.rbCombLinearQ = uiradiobutton(app.uipanel13);
            app.rbCombLinearQ.Tag = 'rbCombLinearQ';
            app.rbCombLinearQ.Text = 'Comb Linear Q Array';
            app.edDQ1 = uieditfield(app.uipanel13, 'text');
            app.edDQ1.Tag = 'edDQ1';
            app.edDQ1.Value = '0.00025';
            app.text49 = uilabel(app.uipanel13);
            app.text49.Tag = 'text49';
            app.text49.Text = 'dQ1';
            app.edQ2 = uieditfield(app.uipanel13, 'text');
            app.edQ2.Tag = 'edQ2';
            app.edQ2.Value = '0.1';
            app.text50 = uilabel(app.uipanel13);
            app.text50.Tag = 'text50';
            app.text50.Text = 'Q2';
            app.edDQ2 = uieditfield(app.uipanel13, 'text');
            app.edDQ2.Tag = 'edDQ2';
            app.edDQ2.Value = '0.0005';
            app.text51 = uilabel(app.uipanel13);
            app.text51.Tag = 'text51';
            app.text51.Text = 'dQ2';
            app.edQ3 = uieditfield(app.uipanel13, 'text');
            app.edQ3.Tag = 'edQ3';
            app.edQ3.Value = '0.35';
            app.text52 = uilabel(app.uipanel13);
            app.text52.Tag = 'text52';
            app.text52.Text = 'Q3';
            app.edDQ3 = uieditfield(app.uipanel13, 'text');
            app.edDQ3.Tag = 'edDQ3';
            app.edDQ3.Value = '0.001';
            app.text53 = uilabel(app.uipanel13);
            app.text53.Tag = 'text53';
            app.text53.Text = 'dQ3';
            app.pbCalExistQIndxMap = uibutton(app.uipanel13, 'push');
            app.pbCalExistQIndxMap.ButtonPushedFcn = createCallbackFcn(app, @pbCalExistQIndxMap_Callback, true);
            app.pbCalExistQIndxMap.Tag = 'pbCalExistQIndxMap';
            app.pbCalExistQIndxMap.Text = 'Calculate Q Index Map w/ Pre-defined Q Array';
            app.text58 = uilabel(app.uipanel13);
            app.text58.Tag = 'text58';
            app.text58.Text = 'CombLinear Note: Set values first; Set Q2 or Q3 <0 for ignoring rest settings';
            app.combLogLinear = uiradiobutton(app.uipanel13);
            app.combLogLinear.Tag = 'combLogLinear';
            app.combLogLinear.Text = 'Comb Log Linear Q';
            app.cLogQ2 = uieditfield(app.uipanel13, 'text');
            app.cLogQ2.Tag = 'cLogQ2';
            app.cLogQ2.Value = '0.1';
            app.cLogPts = uieditfield(app.uipanel13, 'text');
            app.cLogPts.Tag = 'cLogPts';
            app.cLogPts.Value = '100';
            app.cLogDQ2 = uieditfield(app.uipanel13, 'text');
            app.cLogDQ2.Tag = 'cLogDQ2';
            app.cLogDQ2.Value = '0.002';
            app.cLogQ3 = uieditfield(app.uipanel13, 'text');
            app.cLogQ3.Tag = 'cLogQ3';
            app.cLogQ3.Value = '0.5';
            app.cLogDQ3 = uieditfield(app.uipanel13, 'text');
            app.cLogDQ3.Tag = 'cLogDQ3';
            app.cLogDQ3.Value = '0.004';
            app.text60 = uilabel(app.uipanel13);
            app.text60.Tag = 'text60';
            app.text60.Text = 'Log: Q2      pts#';
            app.text61 = uilabel(app.uipanel13);
            app.text61.Tag = 'text61';
            app.text61.Text = 'Linear: dQ2      Q3       dQ3';
            app.text62 = uilabel(app.uipanel13);
            app.text62.Tag = 'text62';
            app.text62.Text = {'Set Parameters '; 'before Select !'};
            app.uipanel15 = uipanel(app.uipanel10);
            app.uipanel15.Title = 'Panel';
            app.uipanel15.Tag = 'uipanel15';
            app.pbCalQCorrMaps = uibutton(app.uipanel15, 'push');
            app.pbCalQCorrMaps.ButtonPushedFcn = createCallbackFcn(app, @pbCalQCorrMaps_Callback, true);
            app.pbCalQCorrMaps.Tag = 'pbCalQCorrMaps';
            app.pbCalQCorrMaps.Tooltip = 'Calculate Q value and solid angle correction for each pixel';
            app.pbCalQCorrMaps.Text = 'Calculate Q and Corr Maps';
            app.edQMin = uieditfield(app.uipanel15, 'text');
            app.edQMin.ValueChangedFcn = createCallbackFcn(app, @edQMin_Callback, true);
            app.edQMin.Tag = 'edQMin';
            app.edQMin.Value = '0';
            app.edQMax = uieditfield(app.uipanel15, 'text');
            app.edQMax.ValueChangedFcn = createCallbackFcn(app, @edQMax_Callback, true);
            app.edQMax.Tag = 'edQMax';
            app.edQMax.Value = '1.';
            app.text36 = uilabel(app.uipanel15);
            app.text36.Tag = 'text36';
            app.text36.Text = 'detector ';
            app.text37 = uilabel(app.uipanel15);
            app.text37.Tag = 'text37';
            app.text37.Text = 'qMin          qMax';
            app.txQmin = uilabel(app.uipanel15);
            app.txQmin.Tag = 'txQmin';
            app.txQmin.Text = '0.0';
            app.text39 = uilabel(app.uipanel15);
            app.text39.Tag = 'text39';
            app.text39.Text = 'mask';
            app.txQmax = uilabel(app.uipanel15);
            app.txQmax.Tag = 'txQmax';
            app.txQmax.Text = '2.';
            app.pbLoadMaskFile = uibutton(app.uipanel15, 'push');
            app.pbLoadMaskFile.ButtonPushedFcn = createCallbackFcn(app, @pbLoadMaskFile_Callback, true);
            app.pbLoadMaskFile.Tag = 'pbLoadMaskFile';
            app.pbLoadMaskFile.Tooltip = 'Load pre-made 2D mask ';
            app.pbLoadMaskFile.Text = 'Load Mask';
            app.text41 = uilabel(app.uipanel15);
            app.text41.Tag = 'text41';
            app.text41.Text = 'q range';
            app.combineMask = uibutton(app.uipanel15, 'push');
            app.combineMask.ButtonPushedFcn = createCallbackFcn(app, @combineMask_Callback, true);
            app.combineMask.Tag = 'combineMask';
            app.combineMask.Tooltip = 'Apply additional Region of Interest(ROI): 1. select ROI from SAXSimageviewer; 2. make selections on "use?" and/or "invert?" to make desired mask; 3. push this button to make the additional arbitrary ROI mask.';
            app.combineMask.Text = 'Arbitary ROI';
            app.text93 = uilabel(app.uipanel15);
            app.text93.Tag = 'text93';
            app.text93.Tooltip = 'Apply additional sector mask on the pre-made mask';
            app.text93.Text = 'Additional sector mask';
            app.sectorA1 = uieditfield(app.uipanel15, 'text');
            app.sectorA1.ValueChangedFcn = createCallbackFcn(app, @sectorA1_Callback, true);
            app.sectorA1.Tag = 'sectorA1';
            app.sectorA1.Value = '20';
            app.sectorA2 = uieditfield(app.uipanel15, 'text');
            app.sectorA2.ValueChangedFcn = createCallbackFcn(app, @sectorA2_Callback, true);
            app.sectorA2.Tag = 'sectorA2';
            app.sectorA2.Value = '160';
            app.plotSector = uibutton(app.uipanel15, 'push');
            app.plotSector.ButtonPushedFcn = createCallbackFcn(app, @plotSector_Callback, true);
            app.plotSector.Tag = 'plotSector';
            app.plotSector.Tooltip = 'Plot the defined sector in SAXSimageviewer';
            app.plotSector.Text = 'plot';
            app.text95 = uilabel(app.uipanel15);
            app.text95.Tag = 'text95';
            app.text95.Text = 'range(deg)';
            app.arbitraryMaskOn = uicheckbox(app.uipanel15);
            app.arbitraryMaskOn.ValueChangedFcn = createCallbackFcn(app, @arbitraryMaskOnValueChanged, true);
            app.arbitraryMaskOn.Text = 'useROI?';
            app.arbitraryMaskExclude = uicheckbox(app.uipanel15);
            app.arbitraryMaskExclude.ValueChangedFcn = createCallbackFcn(app, @arbitraryMaskExcludeValueChanged, true);
            app.arbitraryMaskExclude.Text = 'invertROI?';
            app.useSectorMask = uicheckbox(app.uipanel15);
            app.useSectorMask.ValueChangedFcn = createCallbackFcn(app, @useSectorMaskValueChanged, true);
            app.useSectorMask.Text = 'use?';
            app.excludeSectorMask = uicheckbox(app.uipanel15);
            app.excludeSectorMask.ValueChangedFcn = createCallbackFcn(app, @excludeSectorMaskValueChanged, true);
            app.excludeSectorMask.Text = 'invert?';
            app.uibuttongroup1 = uibuttongroup(app.uipanel10);
            app.uibuttongroup1.Title = 'Averaged Folder';
            app.uibuttongroup1.Tag = 'uibuttongroup1';
            app.rdAve = uiradiobutton(app.uibuttongroup1);
            app.rdAve.Tag = 'rdAve';
            app.rdAve.Text = 'Ave';
            app.rdAve.Value = true;
            app.rdSelectAvgFolder = uiradiobutton(app.uibuttongroup1);
            app.rdSelectAvgFolder.Tag = 'rdSelectAvgFolder';
            app.rdSelectAvgFolder.Text = 'Selected';
            app.rdAveraged = uiradiobutton(app.uibuttongroup1);
            app.rdAveraged.Tag = 'rdAveraged';
            app.rdAveraged.Text = 'Averaged';
            app.folderStr = uilabel(app.uibuttongroup1);
            app.folderStr.Tag = 'folderStr';
            app.folderStr.Text = 'Ave';
            app.ConvH5toTiff = uibutton(app.uipanel10, 'push');
            app.ConvH5toTiff.ButtonPushedFcn = createCallbackFcn(app, @ConvH5toTiff_Callback, true);
            app.ConvH5toTiff.Tag = 'ConvH5toTiff';
            app.ConvH5toTiff.Tooltip = 'Convert multiple h5 files to tiff format. new data using same name in the same folder.';
            app.ConvH5toTiff.Text = 'Conv H5 to Tiff';
            app.SaveCalibDataCheckBox = uicheckbox(app.uipanel10);
            app.SaveCalibDataCheckBox.Text = 'Save Calib Data';
            app.SaveCalibDataCheckBox.Value = true;
            app.uipanel14 = uipanel(app.figure1);
            app.uipanel14.Title = 'AbsInt: Calibration';
            app.uipanel14.Tag = 'uipanel14';
            app.text90 = uilabel(app.uipanel14);
            app.text90.Tag = 'text90';
            app.text90.Text = 'Calibrant';
            app.AbsIntlistbox = uilistbox(app.uipanel14);
            app.AbsIntlistbox.Items = {'None', 'Water', 'Glassy Carbon'};
            app.AbsIntlistbox.ValueChangedFcn = createCallbackFcn(app, @AbsIntlistbox_Callback, true);
            app.AbsIntlistbox.Tag = 'AbsIntlistbox';
            app.AbsIntlistbox.Value = 'None';
            app.ed_absIntQmax = uieditfield(app.uipanel14, 'text');
            app.ed_absIntQmax.Tag = 'ed_absIntQmax';
            app.ed_absIntQmax.Value = '0.5';
            app.text91 = uilabel(app.uipanel14);
            app.text91.Tag = 'text91';
            app.text91.Text = 'Fitted Q range';
            app.ed_absIntQmin = uieditfield(app.uipanel14, 'text');
            app.ed_absIntQmin.Tag = 'ed_absIntQmin';
            app.ed_absIntQmin.Value = '0.01';
            app.absIntSelectData = uibutton(app.uipanel14, 'push');
            app.absIntSelectData.ButtonPushedFcn = createCallbackFcn(app, @absIntSelectData_Callback, true);
            app.absIntSelectData.Tag = 'absIntSelectData';
            app.absIntSelectData.Tooltip = 'Select collected data set of the absolute intensity standard to figure out the absolute intensity coefficient. ';
            app.absIntSelectData.Text = 'Select Data';
            app.absIntCoefftext = uilabel(app.uipanel14);
            app.absIntCoefftext.Tag = 'absIntCoefftext';
            app.absIntCoefftext.Text = 'Coeff: 1.0';
            app.pbLoadSetup = uibutton(app.figure1, 'push');
            app.pbLoadSetup.ButtonPushedFcn = createCallbackFcn(app, @pbLoadSetup_Callback, true);
            app.pbLoadSetup.Tag = 'pbLoadSetup';
            app.pbLoadSetup.Tooltip = 'Load matlab setup file';
            app.pbLoadSetup.Text = 'Load Setup File';
            app.pbSaveSetup = uibutton(app.figure1, 'push');
            app.pbSaveSetup.ButtonPushedFcn = createCallbackFcn(app, @pbSaveSetup_Callback, true);
            app.pbSaveSetup.Tag = 'pbSaveSetup';
            app.pbSaveSetup.Tooltip = 'Save setup parameters in mat format';
            app.pbSaveSetup.Text = 'Save Setup File';
            app.pushbutton16 = uibutton(app.figure1, 'push');
            app.pushbutton16.ButtonPushedFcn = createCallbackFcn(app, @pushbutton16_Callback, true);
            app.pushbutton16.Tag = 'pushbutton16';
            app.pushbutton16.Tooltip = 'Show calculated maps for debugging';
            app.pushbutton16.Text = 'Show All Maps';
            app.processSingleImage = uibutton(app.figure1, 'push');
            app.processSingleImage.ButtonPushedFcn = createCallbackFcn(app, @processSingleImage_Callback, true);
            app.processSingleImage.Tag = 'processSingleImage';
            app.processSingleImage.Tooltip = 'Select and process one 2D image for a quick check; results will be displayed';
            app.processSingleImage.Text = 'Process Single';
            app.processMultipleImages = uibutton(app.figure1, 'push');
            app.processMultipleImages.ButtonPushedFcn = createCallbackFcn(app, @processMultipleImages_Callback, true);
            app.processMultipleImages.Tag = 'processMultipleImages';
            app.processMultipleImages.Text = 'Process Multiple';
            app.processCurrentImg = uibutton(app.figure1, 'push');
            app.processCurrentImg.ButtonPushedFcn = createCallbackFcn(app, @processCurrentImg_Callback, true);
            app.processCurrentImg.Tag = 'processCurrentImg';
            app.processCurrentImg.Tooltip = 'Process the current image loaded in SAXSimageviewer for a quick check, results will be displayed';
            app.processCurrentImg.Text = 'Process Current';
            app.uipanel17 = uipanel(app.figure1);
            app.uipanel17.Title = 'QCalib: AgBeh / CeO2';
            app.uipanel17.Tag = 'uipanel17';
            app.pbDetQCalib = uibutton(app.uipanel17, 'push');
            app.pbDetQCalib.ButtonPushedFcn = createCallbackFcn(app, @pbDetQCalib_Callback, true);
            app.pbDetQCalib.Tag = 'pbDetQCalib';
            app.pbDetQCalib.Tooltip = 'Call detQCalib program to perform detector Q calibration';
            app.pbDetQCalib.Text = 'Detector Q Calibriation';
            app.makeMask = uibutton(app.uipanel17, 'push');
            app.makeMask.ButtonPushedFcn = createCallbackFcn(app, @makeMask_Callback, true);
            app.makeMask.Tag = 'makeMask';
            app.makeMask.Tooltip = 'Call maskmaker program to make 2D mask';
            app.makeMask.Text = 'Make Mask';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = qCalibration(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)qCalibration2_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end